classdef SingleUnits_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ExtractionTab                   matlab.ui.container.Tab
        uibuttongroup1                  matlab.ui.container.ButtonGroup
        text4                           matlab.ui.control.Label
        text5                           matlab.ui.control.Label
        label_nMax                      matlab.ui.control.Label
        label_nMin                      matlab.ui.control.Label
        text5_2                         matlab.ui.control.Label
        label_nTot                      matlab.ui.control.Label
        uibuttongroup4                  matlab.ui.container.ButtonGroup
        checkbox_calculateSUUPDOWN      matlab.ui.control.CheckBox
        uitable_output                  matlab.ui.control.Table
        checkbox_savetimes              matlab.ui.control.CheckBox
        edit_ltpnumber                  matlab.ui.control.EditField
        text8                           matlab.ui.control.Label
        OnlyworksonchannelandtrialselectedinZebraLabel  matlab.ui.control.Label
        CopyTableButton                 matlab.ui.control.Button
        RasterplotPanel                 matlab.ui.container.Panel
        rasterPlot_bt                   matlab.ui.control.Button
        KernelbandwidthEditFieldLabel   matlab.ui.control.Label
        kernelBW_edit                   matlab.ui.control.NumericEditField
        detectionPlot_ck                matlab.ui.control.CheckBox
        calculate_SU                    matlab.ui.control.Button
        reset_SU                        matlab.ui.control.Button
        HPhisto_bt                      matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        trNumber                        matlab.ui.control.Spinner
        trNumberTxt                     matlab.ui.control.Label
        chNumber                        matlab.ui.control.Spinner
        chNumberTxt                     matlab.ui.control.Label
        BandPassLabel                   matlab.ui.control.Label
        BpSelection                     matlab.ui.control.Spinner
        text2                           matlab.ui.control.Label
        AlignmentPanel                  matlab.ui.container.Panel
        beforethepeakmsLabel            matlab.ui.control.Label
        preSEditField                   matlab.ui.control.NumericEditField
        afterthepeakmsLabel             matlab.ui.control.Label
        postSEditField                  matlab.ui.control.NumericEditField
        RefractoryperiodmsLabel         matlab.ui.control.Label
        refEditField                    matlab.ui.control.NumericEditField
        InterpolationCheckBox           matlab.ui.control.CheckBox
        FactorLabel                     matlab.ui.control.Label
        FactorEditField                 matlab.ui.control.NumericEditField
        uibuttongroup3                  matlab.ui.container.ButtonGroup
        checkbox_plotLFP                matlab.ui.control.CheckBox
        checkbox_SUmax                  matlab.ui.control.CheckBox
        checkbox_SUmin                  matlab.ui.control.CheckBox
        pushbutton_scroll_left          matlab.ui.control.Button
        pushbutton_reset                matlab.ui.control.Button
        pushbutton_scroll_right         matlab.ui.control.Button
        edit_tStart_plot                matlab.ui.control.EditField
        edit_tEnd_plot                  matlab.ui.control.EditField
        ReplotButton                    matlab.ui.control.Button
        checkbox_hp                     matlab.ui.control.CheckBox
        LFPaxes                         matlab.ui.control.UIAxes
        SUaxes                          matlab.ui.control.UIAxes
        HPaxes                          matlab.ui.control.UIAxes
        ClusteringTab                   matlab.ui.container.Tab
        ChannelsButtonGroup             matlab.ui.container.ButtonGroup
        SingleChButton                  matlab.ui.control.RadioButton
        AllChButton                     matlab.ui.control.RadioButton
        ShowChSpinnerLabel              matlab.ui.control.Label
        ShowChSpinner                   matlab.ui.control.Spinner
        ChannelorderforNNLabel          matlab.ui.control.Label
        FeatureExtractionButtonGroup    matlab.ui.container.ButtonGroup
        WTButton                        matlab.ui.control.RadioButton
        PCAButton                       matlab.ui.control.RadioButton
        WaveformButton                  matlab.ui.control.RadioButton
        LevelsEditFieldLabel            matlab.ui.control.Label
        LevelsEditField                 matlab.ui.control.NumericEditField
        ComponentsEditFieldLabel        matlab.ui.control.Label
        ComponentsEditField             matlab.ui.control.NumericEditField
        MininputsEditFieldLabel         matlab.ui.control.Label
        MininputsEditField              matlab.ui.control.NumericEditField
        MaxinputsEditFieldLabel         matlab.ui.control.Label
        MaxinputsEditField              matlab.ui.control.NumericEditField
        WaveletDropDownLabel            matlab.ui.control.Label
        WaveletDropDown                 matlab.ui.control.DropDown
        NumberofselectedfeaturesEditFieldLabel  matlab.ui.control.Label
        NumSelFeatEditField             matlab.ui.control.NumericEditField
        PCACheckBox                     matlab.ui.control.CheckBox
        ClusteringButtonGroup           matlab.ui.container.ButtonGroup
        SuperparamagneticButton         matlab.ui.control.RadioButton
        KlustaButton                    matlab.ui.control.RadioButton
        KmeansButton                    matlab.ui.control.RadioButton
        ParametersPanel                 matlab.ui.container.Panel
        kNNEditFieldLabel               matlab.ui.control.Label
        kNNEditField                    matlab.ui.control.NumericEditField
        minsizeclustersEditFieldLabel   matlab.ui.control.Label
        minsizeclustersEditField        matlab.ui.control.NumericEditField
        MaxClusterNumberEditFieldLabel  matlab.ui.control.Label
        MaxClusNumKEditField            matlab.ui.control.NumericEditField
        ShowclusternumberevaluationCheckBox  matlab.ui.control.CheckBox
        CalinskiHarabaszCheckBox        matlab.ui.control.CheckBox
        SilhouetteEvalCheckBox          matlab.ui.control.CheckBox
        TabGroup2                       matlab.ui.container.TabGroup
        SPCTab                          matlab.ui.container.Tab
        ClusterSpinnerLabel             matlab.ui.control.Label
        ClusterSpinner                  matlab.ui.control.Spinner
        ShowallCheckBox                 matlab.ui.control.CheckBox
        DetectedclustersEditFieldLabel  matlab.ui.control.Label
        DetectedclustersEditField       matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        KlustaTab                       matlab.ui.container.Tab
        KmeansTab                       matlab.ui.container.Tab
        NumberofidentifiedclustersEditFieldLabel  matlab.ui.control.Label
        NumClusEditField                matlab.ui.control.NumericEditField
        ShowclusternumberSpinnerLabel   matlab.ui.control.Label
        ShowClusNumSpinner              matlab.ui.control.Spinner
        ShowspikenumberSpinnerLabel     matlab.ui.control.Label
        ShowSpkNumSpinner               matlab.ui.control.Spinner
        ShowMeanSpkCheckBox             matlab.ui.control.CheckBox
        DimensionfortheclustersplotSwitchLabel  matlab.ui.control.Label
        DimPlotClusSwitch               matlab.ui.control.Switch
        AllClusCheckBox                 matlab.ui.control.CheckBox
        AllSpkCheckBox                  matlab.ui.control.CheckBox
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        ComputeButton                   matlab.ui.control.Button
    end

    % Comment by Didi: Notes on how to use the program.
    % 1) Single Units extraction was done according to Quiroga et al. In order
    % to stick to the recommendations in the paper, use high pass filtered data
    % of 300 Hz set in the ZebraExplore main program.
    
    % 2) Set the channel and sampling period yourself: the single unit program
    % uses the channel and sampling period as set by the user here, NOT the
    % ones set in the main ZebraExplore figure
    
    % 3) This program uses results from the EventDetection program. To use
    % this, you must have computed in EventDetection first. Note that
    % all information used here from EventDetection is the start and end time
    % of up and down states. Depending on your goal, you can therefore select
    % on which channel you want to identify up and down states by setting the
    % channel in EventDetection. It is recommended to use the channel with the clearest up states to
    % detect up and down state times in EventDetection, and then keep these times
    % for both channel calculations in the single units program
    
    % 4) The order of execution is important: always click calculate in the SU
    % box on top first, only then you can plot and/or calculate in up and down
    % states
    
    % 5) To save the timing of positive and negative single units, as well as
    % of up and downs states, click the save times button, and make sure the
    % lpt number is set correctly is you used a sum (more than 1) of recordings
    % at the ephys setup
    
    properties (Access = private)
        
    end
    
    properties (Access = public)
        parentApp % ZebraMain handle
        spikestruc % structure array (channles x trials) with fields: spikes, ipeaks, intspk, featSpk - described below
        %         minAt %indices of negative peaks
        %         maxAt
        pre % time in ms before the peak
        post % time in ms after the peak
        nus
        nds
        us_startI
        us_endI
        ds_startI
        ds_endI
        neg_su_in_us
        neg_su_in_ds
        pos_su_in_us
        pos_su_in_ds
        LFP_hp
        x % time vector from the parent app
        xidx % indices of the time vector
        nMax
        nMin
        x_max_time
        x_max_index
        x_min_time
        x_min_index
        thr % array: thr for each channels
        fireRate_smooth
        datatable
        coeff % coeffiecient extracted with Haar's WT and selected by KS statistical test
        
        % probably we should put the channel and trial as properties
    end
    
    methods (Access = private)
        function findpeaks(app)
            % function to identify number and timing of positively and negatively deflecting peaks
            
            % let's start by creating vectors contaning the time points and the high-pass filtered data
            app.LFP_hp = reshape(app.parentApp.bandPassed_LFP(app.BpSelection.Value,:,:,:), size(app.parentApp.bandPassed_LFP, [2, 3, 4])); % we now have the high pass filtered data in a 3D matrix
            
            % Now find a threshold for every channel, as proposed by Quiroga et al.
            app.thr = zeros(1,app.parentApp.nCh); %stores the calculated threshold for each channel
            for i=1:app.parentApp.nCh
                tmp = squeeze(app.LFP_hp(i,:,:)); %tmp is a 2D matrix with dimension trials and time.
                sigma = median(abs(tmp(:))/0.6745); %the median is calculated over all the channel timepoints
                app.thr(i) = 5*sigma;
            end
            
            % Teresa
            % Parameters:
            ref_ms = app.refEditField.Value; % refractory period in ms
            ref = (ref_ms/1000)*app.parentApp.acqF; % refractory period in indeces
            before = app.preSEditField.Value; % time in ms to consider before the peak
            app.pre = ceil((before/1000)*app.parentApp.acqF); % number of time-points before the peak in indeces
            after = app.postSEditField.Value; % time in ms to consider after the peak
            app.post = ceil((after/1000)*app.parentApp.acqF); % number of time-points after the peak in indeces
            
            % initialization of a structure array channles x trials with fields:
            % spikes (matrix of extracted spikes, number of spikes x data points),
            % ipeaks (array containing the time of the peak of each spike)
            % intspk (matrix of interpolated, number of spikes x data
            % points), featSpk (matrix with the extracted features),
            % clusKmeans (array vector contaning the clusters found via k-means)
            app.spikestruc = struct('spikes',cell(app.parentApp.nCh,app.parentApp.nTrials), 'ipeaks',cell(app.parentApp.nCh,app.parentApp.nTrials), ...
                'intspk',cell(app.parentApp.nCh,app.parentApp.nTrials), 'featSpk',cell(app.parentApp.nCh,app.parentApp.nTrials), ...
                'clusKmeans', cell(app.parentApp.nCh,app.parentApp.nTrials));
            
            for iCh = 1:app.parentApp.nCh             % loop on channels
                for iTr = 1:app.parentApp.nTrials     % loop on trials
                    disp(['processing trial ' num2str(iTr) ' of channel ' num2str(iCh)]) % for debug purpouse
                    logictemp = abs(app.LFP_hp(iCh,iTr,:)) >= app.thr(iCh); % logic array: 1 for suprathr abs(data)
                    temp = diff(logictemp); % array: 1 (first time >thr but one index before), 0, -1 (when it goes back)
                    % definition of the temporal window of each event
                    start = find(temp == 1) + 1;
                    stop = find(temp == -1);
                    
                    % check whether the trace begins or ends with suprathreshold points
                    if start(1) > stop(1)
                        start = [1; start];
                    elseif start(end) > stop(end)
                        stop = [stop; length(app.LFP_hp(iCh,iTr,:))];
                    end
                    
                    % single suprathreshold points elimination
                    onepoint = start == stop;
                    start(onepoint) = [];
                    stop(onepoint) = [];
                    
                    % continue the analysis only if there are some events:
                    if numel(start) > 0 && numel(stop) > 0
                        
                        if numel(start) > 1 && numel(stop) > 1
                            % merging of suprathr points that belong to the same event
                            samespk = [false; (start(2:end) - stop(1:end-1)) < ref; false]; % logic array: 1 points that belong to the same spike
                            diffsamespk = diff(samespk); % 1 when the event begins, -1 when it ends; then find the correct indeces:
                            sameEvStart = find(diffsamespk == 1);
                            sameEvStop = find(diffsamespk == -1);
                            if numel(sameEvStart) > 1 && numel(sameEvStop) > 1
                                if sameEvStart(1) > sameEvStop(1) % to adjust whether the trace begins or ends with suprathr points
                                    sameEvStart = [1 sameEvStart];
                                elseif sameEvStart(end) > sameEvStop(end)
                                    sameEvStop = [sameEvStop length(stop)];
                                end
                                
                                for i=1:length(sameEvStart) % merging loop
                                    currentStart = sameEvStart(i);
                                    currentStop = sameEvStop(i);
                                    sameEvIndices = currentStart:currentStop; % indeces of the points that must be considered as one event
                                    start(sameEvIndices(2:end)) = 0; % elimination exceeding points
                                    stop(sameEvIndices(1:end-1)) = 0;
                                end
                                start(start==0)= []; % actual elimination exceeding points
                                stop(stop==0)= [];
                            end
                        end
                        % find the peaks within each temporal window start:stop
                        peaks = zeros(1,length(start)); % array with abs values of the peaks
                        app.spikestruc(iCh,iTr).ipeaks = zeros(1,length(start)); % array containing the indeces of the peaks
                        for i = 1:length(start)
                            disp([num2str(i)]) % for debug purpouse
                            iStart = start(i);
                            iStop = stop(i);
                            [peaks(i),itemp] = max(abs(app.LFP_hp(iCh,iTr,iStart:iStop)));
                            app.spikestruc(iCh,iTr).ipeaks(i) = itemp + start(i) - 1;
                        end
                        
                        % elimination of putative peaks that are at the
                        % border of the trace and there is not enough space
                        % for pre and post windows
                        app.spikestruc(iCh,iTr).ipeaks(app.spikestruc(iCh,iTr).ipeaks <= app.pre) = [];
                        app.spikestruc(iCh,iTr).ipeaks(app.spikestruc(iCh,iTr).ipeaks >= app.post + app.parentApp.dtaLen) = [];
                        
                        % define the temporal window of spikes and save the
                        % events aligned at the peak (peak index = app.pre+1)
                        windowStart = app.spikestruc(iCh,iTr).ipeaks - app.pre -2;
                        windowStop = app.spikestruc(iCh,iTr).ipeaks + app.post +2;
                        spkwindow = app.pre + app.post + 5; % indeces for looking at waveform + 1 of the peak + 4 points for interpolation
                        app.spikestruc(iCh,iTr).spikes = zeros(length(peaks),spkwindow); % matrix containing each voltage vector of the trace identified by iCh,iTr
                        for i=1:length(peaks)
                            app.spikestruc(iCh,iTr).spikes(i,:) = app.LFP_hp(iCh,iTr,windowStart(i):windowStop(i));
                        end
                    end
                end
                disp(['Peaks identified'])
            end
            
            % interpolation
            if app.InterpolationCheckBox.Value
                interpolation(app)
            else
                for iCh = 1:app.parentApp.nCh             % loop on channels
                    for iTr = 1:app.parentApp.nTrials     % loop on trials
                        app.spikestruc(iCh,iTr).spikes(:,:) = app.spikestruc(iCh,iTr).spikes(:,3:end-3); % eliminate the 4 points added for interpolating
                    end
                end
                % app.spikestruc(:,:).spikes = app.spikestruc(:,:).spikes(:,3:end-3); % eliminate the 4 points added for interpolating
            end
        end
        
        
        function interpolation(app)
            % this function computes the interpolation of the spike waveforms
            % and finds and stores the number and timing of peaks;
            % it interpolates with cubic splines to improve alignment.
            intFactor = app.FactorEditField.Value;
            
            for iCh = 1:app.parentApp.nCh             % loop on channels
                for iTr = 1:app.parentApp.nTrials     % loop on trials
                    if size(app.spikestruc(iCh,iTr).spikes,1)>0 % control: if there are spikes
                        olds = 1:size(app.spikestruc(iCh,iTr).spikes,2); % current number of samples
                        ints = 1/intFactor : 1/intFactor : size(app.spikestruc(iCh,iTr).spikes,2); % new number of sample to be simulated with interpolation
                        intspikes = spline(olds,app.spikestruc(iCh,iTr).spikes,ints); % cubic spline interpolation. intspikes is a matrix spikes x interpol data
                        intspikes = intspikes(:,6:end-5); % removal of spurious peaks at the end of interpol trace
                        aroundpeak = ((app.pre+3)*intFactor)-intFactor : ((app.pre+3)*intFactor)+intFactor; % interval around the old peak position
                        [intpeak,itemp] = max(abs(intspikes(:,aroundpeak)),[],2);
                        intpeakidx = itemp + (app.pre+3)*intFactor -1; % index of the peak in the size(intspikes,2)
                        
                        app.spikestruc(iCh,iTr).intspk = zeros(length(intpeak),app.pre + app.post +1);
                        for i = 1:length(intpeak)
                            vecSamples = intpeakidx - (intFactor*app.pre) : intFactor : intpeakidx + (intFactor*app.post);
                            app.spikestruc(iCh,iTr).intspk(i,:) = intspikes(i,vecSamples);
                        end
                    end
                end
            end
            disp(['interpolation computed'])
        end
        
        function showSuOutput(app)
            % show the SU numbers on the appropriate GUI objects
            ch = app.chNumber.Value;
            tr = app.trNumber.Value;
            
            posSU = sum(find(app.spikestruc(ch,tr).spikes(:,app.pre+1) > 0)); % remember: app.pre+1 is the index of the peak
            negSU = sum(find(app.spikestruc(ch,tr).spikes(:,app.pre+1) < 0));
            % Show the total number of upward going and downward going peaks
            set(app.label_nMax, 'Text', num2str(posSU));
            set(app.label_nMin, 'Text', num2str(negSU));
            set(app.label_nTot, 'Text', num2str(length(app.spikestruc(ch,tr).ipeaks)));
            % Also call the plot function
            plotMasterFunction(app)
        end
        
        function Extract_SWA(app)
            % function to extract the indexes of up and down states
            
            % first obtained the start and end points of the up states obtained with
            % EventDetection. Choose in EventDetection from which channel you want to
            % identify up states.
            
            % the fromT toT values in the upstates list of evDetection is longer than
            % NUS and NDS (this happens during fusing/removal up states)..these last
            % fromT and toT values contains the last up state time and so if I would
            % include these I would count several single units several times. To
            % prevent this, I first find the correct value of number of up and down
            % states and then extract only fromT and toT of this length
            app.nus = app.parentApp.evDet.NUS;
            app.nds = app.parentApp.evDet.NDS;
            us_start = (app.parentApp.evDet.upstates.fromT(1:app.nus))';
            us_end = (app.parentApp.evDet.upstates.toT(1:app.nus))';
            ds_start = (app.parentApp.evDet.downstates.fromT(1:app.nds))';
            ds_end = (app.parentApp.evDet.downstates.toT(1:app.nds))';
            % test if each start point also has an end point, i.e. if the data are
            % correct
            if length(us_start) ~= length(us_end) || length(ds_start) ~= length(ds_end)
                error('input data from EvDet do not have correct lengths')
            end
            
            % Then transform the time points to index point
            %acqF = app.edit_acqF.Value;
            acqF = app.parentApp.acqF;
            app.us_startI = us_start.*acqF;
            app.us_endI = us_end.*acqF;
            app.ds_startI = ds_start.*acqF;
            app.ds_endI = ds_end*acqF;
        end
        
        function Extract_SUpos_SWA(app)
            % find the number of positive single units located within up states and down states
            % first set the needed values
            channel = app.chNumber.Value;
            correctmaxAt = app.maxAt{channel, app.trNumber.Value};
            nus = app.nus;
            len = length(correctmaxAt);
            su_in_us = 0; % this is going to count the number of single units in upstates
            % now we will first loop through every up state
            for us = 1:nus
                idx1 = app.us_startI(us); % and for each up state find the start..
                idx2 = app.us_endI(us); % and end point
                % then we can loop through the indexes of su
                for sui = 1:len
                    if correctmaxAt(sui) > idx1 && correctmaxAt(sui) < idx2
                        su_in_us = su_in_us + 1;
                    end
                    % add an if statement to stop when you have reached higher than the
                    % current up state, just to save time
                    if correctmaxAt(sui) > idx2
                        break
                    end
                end
            end
            
            % Now let's do the same for the down states
            nds = app.nds;
            su_in_ds = 0;
            for ds = 1:nds
                idx1 = app.ds_startI(ds);
                idx2 = app.ds_endI(ds);
                for sui = 1:len
                    if correctmaxAt(sui) > idx1 && correctmaxAt(sui) < idx2
                        su_in_ds = su_in_ds + 1;
                    end
                    if correctmaxAt(sui) > idx2
                        break
                    end
                end
            end
            
            % test if correct
            %if su_in_us + su_in_ds > app.nMax
            %    error ('single units in upstates + down is larger than total single units: something wrong in the code')
            %end
            
            % add to the handles structure and update handles structure
            app.pos_su_in_us = su_in_us;
            app.pos_su_in_ds = su_in_ds;
        end
        
        
        function Extract_SUneg_SWA(app)
            % find the number of negative single units located within up states and down states
            % works the same as the function for the positive single units
            nus = app.nus;
            channel = app.chNumber.Value;
            correctminAt = app.minAt{channel, app.trNumber.Value};
            len = length(correctminAt);
            su_in_us = 0;
            for us = 1:nus
                idx1 = app.us_startI(us);
                idx2 = app.us_endI(us);
                for sui = 1:len
                    if correctminAt(sui) > idx1 && correctminAt(sui) < idx2
                        su_in_us = su_in_us + 1;
                    end
                    if correctminAt(sui) > idx2
                        break
                    end
                end
            end
            
            nds = app.nds;
            su_in_ds = 0;
            for ds = 1:nds
                idx1 = app.ds_startI(ds);
                idx2 = app.ds_endI(ds);
                for sui = 1:len
                    if correctminAt(sui) > idx1 && correctminAt(sui) < idx2
                        su_in_ds = su_in_ds + 1;
                    end
                    if correctminAt(sui) > idx2
                        break
                    end
                end
            end
            
            %if su_in_us + su_in_ds > app.nMin
            %    error ('single units in upstates + down is larger than total single units: something wrong in the code')
            %end
            
            app.neg_su_in_us = su_in_us;
            app.neg_su_in_ds = su_in_ds;
        end
        
        function plotMasterFunction(app)
            % this is the "master" plot function. It calls specific plot functions accorting to the respective checkbox state
            
            %clear all the axes for first
            cla(app.LFPaxes);
            cla(app.SUaxes);
            cla(app.HPaxes);
            % note that LFPaxes and SUaxes 'NextPlot' property is set to 'add' by default (they always "hold on")
            
            % Find the X axes:
            % convert x axis to time
            acqF = app.parentApp.acqF; % acquisition frequency
            app.x = app.parentApp.x; % time vector
            app.xidx = 1:length(app.x); % indeces of the time vector
            if isempty(get(app.edit_tStart_plot, 'Value')) % if the user did not enter the start time, i.e. the edit text box is empty
                app.x_min_time = app.x(1); % the start time in seconds
                app.x_min_index = app.xidx(1); % index of the start time
            else
                app.x_min_time = str2double(get(app.edit_tStart_plot, 'Value')); % if the user entered the start time, find the entered number
                if app.x_min_time < app.x(1) % quality control
                    app.x_min_time = app.x(1);
                end
                app.x_min_index = app.x_min_time*acqF+1;
            end
            % the same for the end time
            if isempty(get(app.edit_tEnd_plot, 'Value'))
                app.x_max_time = app.x(end);
                app.x_max_index = app.xidx(end);
            else
                app.x_max_time = str2double(get(app.edit_tEnd_plot, 'Value'));
                if app.x_max_time > app.x(end) % quality control
                    app.x_max_time = app.x(end);
                end
                app.x_max_index = app.x_max_time*acqF+1;
            end
            
            % if end time is smaller than start time
            if app.x_max_time <= app.x_min_time
                error('end time has to be larger than start time')
            end
            
            %call the specific plot functions
            if app.checkbox_plotLFP.Value
                plotLFP(app)
            end
            if app.checkbox_SUmax.Value
                plotSUmax(app)
            end
            if app.checkbox_SUmin.Value
                plotSUmin(app)
            end
            if app.checkbox_hp.Value
                plotHP(app)
            end
        end
        
        function plotLFP(app)
            % function to plot the LFP
            
            
            % Obtain  the LFP vector:
            ch = app.chNumber.Value;
            tr = app.trNumber.Value;
            LFPreal = squeeze(app.parentApp.LFP(ch,tr,:));
            
            % Find the Y axes:
            %min and max values of the LFP
            LFPreal_min = min(LFPreal);
            LFPreal_max = max(LFPreal);
            if LFPreal_min < 0
                LFP_min_temp = ceil(10*abs(LFPreal_min));
                y_min = -LFP_min_temp/10;
            else
                LFP_min_temp = ceil(10*LFPreal_min);
                y_min = LFP_min_temp/10;
            end
            if LFPreal_max < 0
                LFP_max_temp = ceil(10*abs(LFPreal_max));
                y_max = -LFP_max_temp/10;
            else
                LFP_max_temp = ceil(10*LFPreal_max);
                y_max = LFP_max_temp/10;
            end


            % Now plot the LFP:
            plot(app.LFPaxes,app.x(app.x_min_index:app.x_max_index), LFPreal(app.x_min_index:app.x_max_index), 'k');
            xlim(app.LFPaxes,[app.x_min_time app.x_max_time]);
            ylim(app.LFPaxes,[y_min y_max]);
        end
        
        function plotHP(app)
            %t = (0:app.parentApp.dtaLen-1)*app.parentApp.sp; %time vector
            ch = app.chNumber.Value;
            tr = app.trNumber.Value;
            plot(app.HPaxes,app.x(app.x_min_index:app.x_max_index),squeeze(app.LFP_hp(ch,tr,app.x_min_index:app.x_max_index)),'k')
            plot(app.HPaxes,[app.x(app.x_min_index),app.x(app.x_max_index)],[app.thr(ch),app.thr(ch)],'Color','r')
            plot(app.HPaxes,[app.x(app.x_min_index),app.x(app.x_max_index)],[-app.thr(ch),-app.thr(ch)],'Color','b')
            app.HPaxes.XLim = [app.x(app.x_min_index),app.x(app.x_max_index)];
        end
        
        function plotSUmax(app)
            % function to plot the positive 'peaks' (single units)
            % use the same x limits as created for the LFP
            ch = app.chNumber.Value;
            tr = app.trNumber.Value;
            idxtrace = 1:app.parentApp.dtaLen;
            xlim(app.SUaxes,[app.x_min_time app.x_max_time]);
            y_su_max = ismember(idxtrace, app.spikestruc(ch,tr).ipeaks(:)); % creates a vector of the same length of idxtrace, with values 0 or 1 if the same value
            % is a member of ipeaks field, in other words if there is a spike.
            plot(app.SUaxes,app.x(app.x_min_index:app.x_max_index), y_su_max(app.x_min_index:app.x_max_index), 'r');
        end
        
        function plotSUmin(app)
            % function to plot the negative 'peaks' (single units)
            ch = app.chNumber.Value;
            tr = app.trNumber.Value;
            idxtrace = 1:app.parentApp.dtaLen;
            xlim(app.SUaxes,[app.x_min_time app.x_max_time]);
            y_su_min = ismember(idxtrace, app.spikestruc(ch,tr).ipeaks(:));
            plot(app.SUaxes,app.x(app.x_min_index:app.x_max_index), -1*y_su_min(app.x_min_index:app.x_max_index), 'b');
        end
        
        %% February 2019 Didi: all stuff below was added by Gabriele. I commented it out to make sure no changed have been made to program as I intended to use it
        
        %function w = fixedKernelBandwidth(app,fireRate_raw)
        %t = ((fireRate_raw > 0)-1)*app.parentApp.sp; %spike times
        %t = find(fireRate_raw(app.chNumber.Value,:) > 0); %spike indices
        
        %minimization function as in shimazaki 2009
        
        %toBeMin = @(omega) shimazakiFixed(app,omega,t);
        %             options = optimset('Display','Iter');
        %             w = fminsearch(toBeMin,60,options);
        %w = fminsearch(toBeMin,60);
        %end
        
        %function res = shimazakiFixed(app,w,t)
        %    N = length(t);
        %    s=0;
        %    for j=2:N
        %        for i=1:j-1
        %            s = s+exp(-(t(i)-t(j))^2/(2*w)^2)-2*sqrt(2)*exp(-(t(i)-t(j))^2/(2*w^2));
        %        end
        %    end
        %    res = N/w+2/w*s; %to be continued
        %end
        
        %function [out] = fromSUpeaks_to_LFPpeaks(app,sourceI,sourceV)
        % sourceI can be app.maxAt o  app.minAt;
        % sourceV can be app.LFP_hp or app.parentApp.LFP
        %posit = sourceI; % position of SU (expressed in indices)
        
        %y_tmp = num2cell(sourceV,3); %the cell y{i,j} stores a vector all the points from sourceV(i,j,:), so from the j-th trial of the i-th channel
        %y = cellfun(@squeeze,y_tmp,'UniformOutput',0);
        
        %fun = @(ind,x) x(ind);
        %out_tmp = cellfun(fun,posit,y,'UniformOutput',0);
        
        %out = cell(app.parentApp.nCh,1);
        %for iCh = 1:app.parentApp.nCh
        %    out{iCh,1} = vertcat(out_tmp{iCh,:});
        %end
        %end
        
        %% End of Gabriele's part
        
        function FeatureExtraction(app)
            % this function extracts features according to the selected
            % type of analysis and saves them into a matrix that will serve
            % the clustering algorithm: app.spikestruc(ch,tr).featSpk is a
            % matrix containing a spike per row and the selected
            % coefficients per column. The statistical significance of the
            % most descriptive features is assessed by the Lilliefors - KS
            % test.
            % Moreover, it also saves the column indeces (featidx) of the the three most
            % distant-from-gaussianity features that are identified by the Lilliefors
            % test  (thereby the most significant descriptive features for spike sorting
            % purpose).
            
            % future implementation: switching among the types
            % of analyses and write their code: haar, other wavelet, pca, waveform
            
            % control: how many peaks are there in the channels/trials?
            toBeAnalyzed = true(app.parentApp.nCh,app.parentApp.nTrials);
            for iCh = 1:app.parentApp.nCh             % loop on channels
                for iTr = 1:app.parentApp.nTrials     % loop on trials
                    if app.InterpolationCheckBox.Value
                        if size(app.spikestruc(iCh,iTr).intspk,1) <=1
                            toBeAnalyzed(iCh,iTr) = false;
                        end
                    else
                        if size(app.spikestruc(iCh,iTr).spikes,1) <=1
                            toBeAnalyzed(iCh,iTr) = false;
                        end
                    end
                end
            end
            
            if app.WTButton.Value
                % requirement: wavelet analysis toolbox
                par.wtType = app.WaveletDropDown.Value; % type of wavelet
                par.lvl = app.LevelsEditField.Value; % level of the transform
                par.min = app.MininputsEditField.Value;  % dimensionality reduction: min # of data points to be used for clustering
                par.max = app.MaxinputsEditField.Value; % dimensionality reduction: max # of data points to be used for clustering expressed in a fraction
                for iCh = 1:app.parentApp.nCh             % loop on channels
                    for iTr = 1:app.parentApp.nTrials     % loop on trials
                        if toBeAnalyzed(iCh,iTr)
                            if app.InterpolationCheckBox.Value
                                app.spikestruc(iCh,iTr).featSpk = featExtrWT(app.spikestruc(iCh,iTr).intspk, par);
                            else
                                app.spikestruc(iCh,iTr).featSpk = featExtrWT(app.spikestruc(iCh,iTr).spikes, par);
                            end
                        else
                            app.spikestruc(iCh,iTr).featSpk = [];
                        end
                    end
                end
            end
            
        end
        
        function Clustering(app)
            switch app.ClusteringButtonGroup.SelectedObject.Text
                case 'Superparamagnetic'
                case 'K-means' % SALVA I VALORI best_K. FARE UNA STRUTTURA SOLO CLUSTERING?
                    max_K = app.MaxClusNumKEditField.Value; % maximum number of cluster chosen by the user
                    for iCh = 1:app.parentApp.nCh             % loop on channels
                        for iTr = 1:app.parentApp.nTrials     % loop on trials
                            if ~isempty(app.spikestruc(iCh,iTr).featSpk)
                                % this line directly computes the k-means and the
                                % goodness of the clustering using the Calinski -
                                % Harabasz method or silhouette evaluation.
                                if app.CalinskiHarabaszCheckBox.Value
                                    app.SilhouetteEvalCheckBox.Enable = 'off';
                                    eva = evalclusters(app.spikestruc(iCh,iTr).featSpk,'kmeans','CalinskiHarabasz','KList',[2:max_K]);
                                    if app.ShowclusternumberevaluationCheckBox.Value
                                        figure(1)
                                        subplot(1,app.parentApp.nCh,iCh)
                                        plot(eva)
                                        title(['Channel ' num2str(iCh)])
                                    end
                                    best_K = eva.OptimalK;
                                elseif app.SilhouetteEvalCheckBox.Value
                                    app.CalinskiHarabaszCheckBox.Enable = 'off';
                                    eva = evalclusters(app.spikestruc(iCh,iTr).featSpk,'kmeans','silhouette','KList',[2:max_K]);
                                    if app.ShowclusternumberevaluationCheckBox.Value
                                        figure(2)
                                        subplot(1,app.parentApp.nCh,iCh)
                                        plot(eva)
                                        title(['Channel ' num2str(iCh)])
                                    end
                                    best_K = eva.OptimalK;
                                end
                                
                                % then we run a k-mean algorithm using the optimal
                                % number of cluster
                                app.spikestruc(iCh,iTr).clusKmeans = kmeans(app.spikestruc(iCh,iTr).featSpk,best_K);
                            else
                                app.NumClusEditField.Value = 0;
                                app.spikestruc(iCh,iTr).clusKmeans = [];
                            end
                        end
                    end
                    ShowChSpinnerValueChanged(app)
            end
        end
        
        function PlotClusters(app)
            % TRIAL is set to 1. TO BE UP-DATED.
            ch = app.ShowChSpinner.Value; % selected channel. top spinner
            
            % time axis
            if app.InterpolationCheckBox.Value
                time = (0:size(app.spikestruc(ch,1).intspk,2)-1)/app.parentApp.acqF; % time axis for interpolated spikes
                spk = app.spikestruc(ch,1).intspk;
            else
                time = (0:size(app.spikestruc(ch,1).spikes,2)-1)/app.parentApp.acqF; % time axis for spikes
                spk = app.spikestruc(ch,1).spikes;
            end
            time = time - ((app.pre+1) / app.parentApp.acqF);
            
            switch app.ClusteringButtonGroup.SelectedObject.Text
                case 'Superparamagnetic'
                case 'K-means'
                    % plot the spikes. Possible combinations:
                    % 1) plot all the clusters via (1.1) plotting just the
                    % spikes, or (1.2) only the clusters' means, or (1.3)
                    % both;
                    % 2) plot just the selected cluster and (2.1) all the
                    % spikes of that cluster or (2.2) just one spike, and
                    % also the mean (2.3).
                    % mode: Next Plot Add
                    
                    cla(app.UIAxes5)
                    colors = jet(app.NumClusEditField.Value);
                    
                    % set the combination of plots
                    if app.AllClusCheckBox.Value
                        app.ShowClusNumSpinner.Enable = 'off';
                        app.ShowSpkNumSpinner.Enable = 'off';
                        start = 1;
                        stop =  max(app.spikestruc(ch,1).clusKmeans);
                    else
                        app.ShowClusNumSpinner.Enable = 'on';
                        app.ShowSpkNumSpinner.Enable = 'on';
                        app.ShowClusNumSpinner.Limits = [1, max(app.spikestruc(ch,1).clusKmeans)];
                        start =  app.ShowClusNumSpinner.Value; % selected cluster
                        stop =  app.ShowClusNumSpinner.Value;
                        if sum(app.spikestruc(ch,1).clusKmeans ==  app.ShowClusNumSpinner.Value) == 1
                            app.ShowSpkNumSpinner.Limits = [1, inf];
                            app.ShowSpkNumSpinner.Enable = 'off';
                            app.ShowSpkNumSpinner.Value = 1;
                        else
                            app.ShowSpkNumSpinner.Limits = [1, sum(app.spikestruc(ch,1).clusKmeans ==  app.ShowClusNumSpinner.Value)];
                        end
                    end
                    
                    % plot
                    for i= start : stop
                        SpkClusNum = app.spikestruc(ch,1).clusKmeans == i; % logic array indicating the spikes of the selected cluster
                        if app.AllSpkCheckBox.Value
                            allspkplot = plot(app.UIAxes5, time, spk(SpkClusNum,:), 'Color', [colors(i,:) 0.5]);
                        elseif start == stop %  single spike
                            cla(app.UIAxes5)
                            SpkNum =  app.ShowSpkNumSpinner.Value;
                            thisClust = spk(SpkClusNum,:); % matrix which rows are the spikes of the selected cluster
                            plot(app.UIAxes5, time, thisClust(SpkNum,:), 'Color', colors(i,:));
                        end
                        
                        if app.ShowMeanSpkCheckBox.Value
                            meanspk = mean(spk(SpkClusNum,:),1);
                            plot(app.UIAxes5, time, meanspk, 'Color', colors(i,:), 'LineWidth', 2);
                        end
                    end
                    
                    % scatter plot
                    cla(app.UIAxes6)
                    scatcol = colors(app.spikestruc(ch,1).clusKmeans,:);
                    if app.DimPlotClusSwitch.Value == '2-D'
                        spkfeat1 = app.spikestruc(ch,1).featSpk(:,end);
                        spkfeat2 = app.spikestruc(ch,1).featSpk(:,end-1);
                        scatter(app.UIAxes6, spkfeat1, spkfeat2,[],scatcol, 'filled')
                        app.UIAxes6.View = [0 90];
                    else
                        spkfeat1 = app.spikestruc(ch,1).featSpk(:,end);
                        spkfeat2 = app.spikestruc(ch,1).featSpk(:,end-1);
                        spkfeat3 = app.spikestruc(ch,1).featSpk(:,end-2);
                        scatter3(app.UIAxes6, spkfeat1, spkfeat2,spkfeat3,[],scatcol, 'filled')
                        app.UIAxes6.ZLabel.String = 'Feature 3';
                    end
                    
            end
            
        end
    end
    
    methods (Access = public)
        
        
        function setSpinners(app)
            % update BP spinner
            if get(app.parentApp.NumberofBPsEditField,'Value') == 1
                app.BpSelection.Limits = [1,inf];
                app.BpSelection.Enable = 'off';
            else
                app.BpSelection.Limits(2) = app.parentApp.NumberofBPsEditField.Value;
                app.BpSelection.Enable = 'on';
            end
            % updated channel spinner
            if ~isempty(app.parentApp.nCh)
                if app.parentApp.nCh == 1
                    app.chNumber.Limits = [1,inf];
                    app.chNumber.Enable = 'off';
                    app.ShowChSpinner.Limits = [1,inf];
                    app.ShowChSpinner.Enable = 'off';
                else
                    app.chNumber.Limits = [1,app.parentApp.nCh];
                    app.chNumber.Enable = 'on';
                    app.ShowChSpinner.Limits = [1,app.parentApp.nCh];
                    app.ShowChSpinner.Enable = 'on';
                end
            end
            % updated trial spinner
            if ~isempty(app.parentApp.nTrials)
                if app.parentApp.nTrials == 1
                    app.trNumber.Limits = [1,inf];
                    app.trNumber.Enable = 'off';
                else
                    app.trNumber.Limits = [1,app.parentApp.nTrials];
                    app.trNumber.Enable = 'on';
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function singleUnits_OpeningFcn(app, parentApp)
            %             % This function has no output args, see OutputFcn.
            %             % hObject    handle to figure
            %             % eventdata  reserved - to be defined in a future version of MATLAB
            %             % handles    structure with handles and user data (see GUIDATA)
            %             % varargin   command line arguments to singleunits2 (see VARARGIN)
            %
            %             % Choose default command line output for singleunits2
            %             app.output = hObject;
            %             recover the handle to ZebraExplore
            app.parentApp = parentApp;
            
            %
            
            %             %initialize channels and trials spinners
            
            setSpinners(app)
            
        end

        % Value changed function: chNumber
        function chNumber_Callback(app, event)
            plotMasterFunction(app)
            showSuOutput(app)
        end

        % Value changed function: trNumber
        function trNumber_Callback(app, event)
            plotMasterFunction(app)
            showSuOutput(app)
        end

        % Value changed function: checkbox_SUmax, checkbox_SUmin, 
        % checkbox_hp, checkbox_plotLFP
        function checkbox_plotLFP_Callback(app, event)
            plotMasterFunction(app)
        end

        % Button pushed function: reset_SU
        function reset_SU_Callback(app, event)
            set(app.label_nMax, 'Value', '-');
            set(app.label_nMin, 'Value', '-');
        end

        % Button pushed function: HPhisto_bt
        function HPhisto_bt_Callback(app, event)
            %% This function is build by Gabriele, comment it out to prevent it from interrupting with the intended purpose of the program
            %ch = app.chNumber.Value;
            
            %hp = app.LFP_hp(ch,:,:);
            %hp = hp(:);
            %figure
            %ax = axes;
            %histogram(hp,linspace(min(hp),max(hp),21))
            %ax.YScale = 'log';
            %hold on
            %plot([-app.thr(1),-app.thr(1)],ylim,'r')
            %plot([app.thr(1),app.thr(1)],ylim,'r')
            %ax.XLabel.String = 'Voltage';
            %ax.YLabel.String = 'Conunts';
            %su = hp>app.thr(ch) | hp<-app.thr(ch);
            %counts = sum(su);
            %su_freq = counts/length(hp);
            %ax.Title.String = ['SU number: ' num2str(counts) ',  SU frequency: ' num2str(su_freq)];
            
            %%% also check if SU amplitudes correlate with LFP amplitude %%----------------------------------------------
            % use the function fromSUpeaks_to_LFPpeaks(app,sourceI,sourceV), where:
            %   -sourceI is the source of SU indices; it can be app.minAt or app.maxAt
            %   -sourceV is the source of LFP values; it can be app.LFP_hp or app.parentApp.LFP.
            
            %posHp = fromSUpeaks_to_LFPpeaks(app,app.maxAt,app.LFP_hp); %high-passed LFP values relative to positive-SU timepoints
            %negHp = fromSUpeaks_to_LFPpeaks(app,app.minAt,app.LFP_hp); %high-passed LFP values relative to negative-SU timepoints
            
            %posLfp = fromSUpeaks_to_LFPpeaks(app,app.maxAt,app.parentApp.LFP); %high-passed LFP values relative to positive-SU timepoints
            %negLfp = fromSUpeaks_to_LFPpeaks(app,app.minAt,app.parentApp.LFP); %high-passed LFP values relative to negative-SU timepoints
            
            %totHp = cellfun(@vertcat,posHp,negHp,'UniformOutput',0);
            %totLfp = cellfun(@vertcat,posLfp,negLfp,'UniformOutput',0);
            
            %figure
            %axCorr = axes;
            %             plot(axCorr,totLfp{app.chNumber.Value},totHp{app.chNumber.Value},'o')
            %             axCorr.XLabel.String = 'LFP amplitude (mV)';
            %             axCorr.YLabel.String = 'high-passed LFP amplitude (mV)';
            %plot(axCorr,abs(totLfp{app.chNumber.Value}),abs(totHp{app.chNumber.Value}),'o')
            %axCorr.XLabel.String = 'absolute LFP amplitude (mV)';
            %axCorr.YLabel.String = 'absolute high-passed LFP amplitude (mV)';
            %axCorr.Title.String = 'Correlation between LFP and hp-filtered LFP at SU peaks';
            
            %[p,S] = polyfit(abs(totLfp{app.chNumber.Value}),abs(totHp{app.chNumber.Value}),1);
            %hold(axCorr,'on')
            %plot(axCorr,abs(totLfp{app.chNumber.Value}),p(2)+abs(totLfp{app.chNumber.Value}).*p(1));
            %             [rho,pval] = corr([abs(totLfp{app.chNumber.Value}),abs(totHp{app.chNumber.Value})],Y);
            %             hold(axCorr, 'on')
            %r = corrcoef(abs(totLfp{app.chNumber.Value}),abs(totHp{app.chNumber.Value}));
            %txt = text(axCorr,0,0,0,['r_P_e_a_r_s_o_n = ' num2str(r(1,2))]);
            %txt.Position = [axCorr.XLim(2)-txt.Extent(3), axCorr.YLim(2)-txt.Extent(4), 0]; %position txt to the upper-right corner
            
            %also for all the points, not only for SU
            %figure
            %axCorr = axes;
            %             plot(axCorr,totLfp{app.chNumber.Value},totHp{app.chNumber.Value},'o')
            %             axCorr.XLabel.String = 'LFP amplitude (mV)';
            %             axCorr.YLabel.String = 'high-passed LFP amplitude (mV)';
            %X = abs(app.parentApp.LFP(app.chNumber.Value,:,:));
            %X = X(:);
            %Y = abs(app.parentApp.bandPassed_LFP(5,app.chNumber.Value,:,:));
            %Y = Y(:);
            %plot(axCorr,X,Y,'o')
            %axCorr.XLabel.String = 'absolute LFP amplitude (mV)';
            %axCorr.YLabel.String = 'absolute high-passed LFP amplitude (mV)';
            %axCorr.Title.String = 'Correlation between LFP and hp-filtered LFP (all points)';
            
            %[p,S] = polyfit(X,Y,1);
            %hold(axCorr,'on')
            %plot(axCorr,X,p(2)+Y.*p(1));
            %             [rho,pval] = corr([abs(totLfp{app.chNumber.Value}),abs(totHp{app.chNumber.Value})],Y);
            %             hold(axCorr, 'on')
            %r = corrcoef(X,Y);
            %txt = text(axCorr,0,0,0,['r_P_e_a_r_s_o_n = ' num2str(r(1,2))]);
            %txt.Position = [axCorr.XLim(2)-txt.Extent(3), axCorr.YLim(2)-txt.Extent(4), 0]; %position txt to the upper-right corner
            
        end

        % Button pushed function: calculate_SU
        function calculate_SU_Callback(app, event)
            %             this function will execute the findpeaks function and display counted positive
            %             and negative peaks
            
            findpeaks(app);
            
            showSuOutput(app); % show the output on the GUI
        end

        % Button pushed function: ReplotButton
        function replot_Callback(app, event)
            %just call the plotMasterFunction
            plotMasterFunction(app)
        end

        % Value changed function: edit_tStart_plot
        function edit_tStart_plot_Callback(app, event)
            plotMasterFunction(app)
        end

        % Value changed function: edit_tEnd_plot
        function edit_tEnd_plot_Callback(app, event)
            plotMasterFunction(app)
        end

        % Button pushed function: pushbutton_reset
        function pushbutton_reset_Callback(app, event)
            % hObject    handle to pushbutton_reset (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %function to reset the time to the entire range of the LFP recording
            %if get(hObject, 'Value')
            % set the edit boxes of the start time and end time to empty again
            set(app.edit_tStart_plot, 'Value', '');
            set(app.edit_tEnd_plot, 'Value', '');
            % then replot everything; the plot master function will automatically
            % determine the entire range
            plotMasterFunction(app);
            %end
        end

        % Button pushed function: pushbutton_scroll_left
        function pushbutton_scroll_left_Callback(app, event)
            % hObject    handle to pushbutton_scroll_left (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %function to scroll though the plots of LFP and single units
            %if get(hObject, 'Value')
            currentx_min = str2double(get(app.edit_tStart_plot, 'Value'));
            currentx_max = str2double(get(app.edit_tEnd_plot, 'Value'));
            % find the current distance between the start and end time points
            diff = currentx_max-currentx_min;
            % use it to calculate the new timepoints
            newx_min = currentx_min-diff;
            newx_max = currentx_max-diff;
            %                 if newx_min >= 0 && newx_max <= app.time(end) %test that the new time points fall within the time range of the entire recording
            if newx_min < app.time(1) %quality control
                newx_min = app.time(1);
                newx_max = app.time(1)+diff;
            end
            % if yes, you can update the tstart and tend edit boxes
            set(app.edit_tStart_plot, 'Value', num2str(newx_min));
            set(app.edit_tEnd_plot, 'Value', num2str(newx_max));
            %and then re-execute the plotting
            
            %% Below is all commented out, since it is no longer necessary with the plot master function
            %plotLFP(app);
            
            % find out which single unit plotting function to use
            %if get(app.checkbox_plotLFP, 'Value')
            %    plotSUall(app);
            %elseif get(app.checkbox_SUmin, 'Value')
            %    plotSUmin(app);
            %elseif get(app.checkbox_SUmax, 'Value')
            %    plotSUmax(app);
            %end
            %                 else
            %                     axes(app.LFPaxes); cla; % if the time range is outside of the recording range, empty the plots
            %                     axes(app.SUaxes); cla;
            %                 end
            %                 % reset the pushbutton value to 0, so that when you click again it
            %                 % changes to 1 and executes the code to scroll again, so you don't have
            %                 % to unclick and click again
            %                 set(hObject,'value',0);
            %end
            plotMasterFunction(app)
        end

        % Button pushed function: pushbutton_scroll_right
        function pushbutton_scroll_right_Callback(app, event)
            % hObject    handle to pushbutton_scroll_right (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % function to scroll right through the plots, the same as the left
            % scrolling function above
            %             if get(hObject, 'Value')
            currentx_min = str2double(get(app.edit_tStart_plot, 'Value'));
            currentx_max = str2double(get(app.edit_tEnd_plot, 'Value'));
            diff = currentx_max-currentx_min;
            newx_min = currentx_min+diff;
            newx_max = currentx_max+diff;
            if newx_max > app.time(end)
                newx_max = app.time(end);
                newx_min = app.time(end)+diff;
            end
            set(app.edit_tStart_plot, 'Value', num2str(newx_min));
            set(app.edit_tEnd_plot, 'Value', num2str(newx_max));
            
            %% Below is all commented out, since it is no longer necessary with the plot master function
            %plotLFP(app);
            
            %if get(app.checkbox_plotLFP, 'Value')
            %    plotSUall(app);
            %elseif get(app.checkbox_SUmin, 'Value')
            %    plotSUmin(app);
            %elseif get(app.checkbox_SUmax, 'Value')
            %    plotSUmax(app);
            %end
            %             else axes(app.LFPaxes); cla;
            %                 axes(app.SUaxes); cla;
            %             end
            %                 set(hObject,'value',0);
            %             end
            plotMasterFunction(app)
        end

        % Value changed function: checkbox_calculateSUUPDOWN
        function checkbox_calculateSUUPDOWN_Callback(app, event)
            
            if app.checkbox_calculateSUUPDOWN.Value
                Extract_SWA (app);
                Extract_SUpos_SWA (app);
                Extract_SUneg_SWA (app);
                % creation of a list containing all output data
                % first column: entire trace
                % second column: in up states
                % third column: in down states
                
                ch = app.chNumber.Value;
                tr = app.trNumber.Value;
                list = {1 1 1};
                % first row: number of positive peaks
                list(1 ,1) = num2cell(app.nMax(ch,tr));
                list(1, 2) = num2cell(app.pos_su_in_us);
                list(1, 3) = num2cell(app.pos_su_in_ds);
                % second row: fraction of postive peaks
                list(2, 1) = num2cell((app.nMax(ch,tr)/app.nMax(ch,tr))*100);
                list(2, 2) = num2cell((app.pos_su_in_us/app.nMax(ch,tr))*100);
                list(2, 3) = num2cell((app.pos_su_in_ds/app.nMax(ch,tr))*100);
                % third row: number of negative peaks
                list(3, 1) = num2cell(app.nMin(ch,tr));
                list(3, 2) = num2cell(app.neg_su_in_us);
                list(3, 3) = num2cell(app.neg_su_in_ds);
                % fourth row: fraction of negative peaks
                list(4, 1) = num2cell((app.nMin(ch,tr)/app.nMin(ch,tr))*100);
                list(4, 2) = num2cell((app.neg_su_in_us/app.nMin(ch,tr))*100);
                list(4, 3) = num2cell((app.neg_su_in_ds/app.nMin(ch,tr))*100);
                % fifth row: number of total peaks
                total = app.nMin(ch,tr)+app.nMax(ch,tr);
                total_in_us = app.neg_su_in_us+app.pos_su_in_us;
                total_in_ds = app.neg_su_in_ds+app.pos_su_in_ds;
                list(5 ,1) = num2cell(total);
                list(5, 2) = num2cell(total_in_us);
                list(5, 3) = num2cell(total_in_ds);
                % sixth row: fraction of total peaks
                list(6, 1) = num2cell((total/total)*100);
                list(6, 2) = num2cell((total_in_us/total)*100);
                list(6, 3) = num2cell((total_in_ds/total)*100);
                
                % finally set this list as output data in the table
                set(app.uitable_output,'Data',list);
                % save the data table
                app.datatable = list;
            else
                emptylist = {};
                set(app.uitable_output, 'Data', emptylist);
            end
        end

        % Button pushed function: CopyTableButton
        function CopyTableButtonPushed(app, event)
            % This function copies the table to the clipboard
            tablesize = size(app.datatable);
            str = '';
            for i = 1:tablesize(1)
                for j = 1:tablesize(2)
                    if j == tablesize(2)
                        str = sprintf('%s%f',str,app.datatable{i,j});
                    else
                        str = sprintf('%s%f\t',str,app.datatable{i,j});
                    end
                end
                str = sprintf('%s\n',str);
            end
            clipboard ('copy',str);
            
        end

        % Button pushed function: rasterPlot_bt
        function rasterPlot_Callback(app, event)
            %% This function is build by Gabriele, comment it out to prevent it from interrupting with the intended purpose of the program
            
            % Build the raster plot matrix
            %nCh = app.parentApp.nCh;
            %nTr = app.parentApp.nTrials;
            %len = app.parentApp.dtaLen;
            %rasterMat = zeros(nCh,nTr,len); %inizialize the matrix
            %for iCh = 1:nCh %loop channels and trials
            %    for iTr = 1:nTr
            %        nPosSU = length(app.maxAt{iCh,iTr}(:));
            %        for jPos=1:nPosSU
            %            rasterMat(iCh,iTr,app.maxAt{iCh,iTr}(jPos)) = 1; % set as 1 the timepoint of positive SUs
            %        end
            
            %        nNegSU = length(app.minAt{iCh,iTr}(:));
            %        for jMin=1:nNegSU
            %            rasterMat(iCh,iTr,app.minAt{iCh,iTr}(jMin)) = 1; % set as 1 the timepoint of positive SUs
            %        end
            %rasterMat(iCh,iTr,logical(app.maxAt{iCh,iTr}(:)) | logical(app.minAt{iCh,iTr}(:))) = 1;
            %    end
            %end
            %spikeBinary = logical(rasterMat); % matrix for the rasterPlot
            
            %fireRate_raw = squeeze(sum(rasterMat,2)); % 2D matrix, channels x time
            %figure
            %ax1 = subplot(2,1,1);
            %plot(ax1,fireRate_raw(1,:))
            %             spikeTimes = cell(nTr,1);
            %             for i = 1:nTr
            %                 spikeTimes = find(spikeBinary > 0);
            %             end
            % spikeTimes = mat2cell(find(spikeBinary > 0),ones(1:nCh),ones(1:nTr));
            %spikeTimes = num2cell(find(spikeBinary > 0),3);
            
            %plotSpikeRaster(squeeze(spikeBinary(app.chNumber.Value,:,:)),'PlotType','vertline','TimePerBin',0.002);
            %ax1.YTickLabel = num2cell(1:nTr);
            %uncorrectTimes = str2double(ax1.XTickLabel);
            %ax1.XTickLabel = num2str((uncorrectTimes)*app.parentApp.sp); %always starts from 0
            %ax1.Title.String = 'raw fire rate';
            %ax1.XLabel.String = 'Time (s)';
            
            %app.x = indices
            %gaussian kernel function with parameter w.
            %w = app.kernelBW_edit.Value; % totally random, just try
            %w = fixedKernelBandwidth(app,fireRate_raw); %vediamo cosa viene
            %disp(w)
            %s = -len/2:len/2; %kernel domain
            %ker = (sqrt(2*pi)*w)^(-1) * exp(-1*(s).^2./(2*w^2));
            
            %fireRate_smooth = conv(fireRate_raw(1,:),ker);
            %app.fireRate_smooth = conv(ker,fireRate_raw(app.chNumber.Value,:),'same');
            %ax2 = subplot(2,1,2);
            %t4plot = (0:length(app.fireRate_smooth)-1)*app.parentApp.sp;
            %plot(ax2,t4plot,app.fireRate_smooth)
            %ax2.Title.String = ['smooth fire rate (kernel BW = ' num2str(w) ')'];
            %ax2.XLabel.String = 'Time (s)';
            
            
        end

        % Value changed function: checkbox_savetimes
        function checkbox_savetimes_Callback(app, event)
            % hObject    handle to checkbox_savetimes (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if app.checkbox_savetimes.Value
                acqF = app.parentApp.acqF;
                nus = app.nus;
                nds = app.nds;
                %create the several columns first: first timing positive single units
                %states, then timing negative single units, timing up states start and end and timing
                %down states start and end
                correctmaxAt = app.maxAt{app.chNumber.Value, app.trNumber.Value};
                correctminAt = app.minAt{app.chNumber.Value, app.trNumber.Value};
                A = (correctmaxAt-1)./acqF;
                B = (correctminAt-1)./acqF;
                C = app.parentApp.evDet.upstates.fromT(1:nus);
                D = app.parentApp.evDet.upstates.toT(1:nus);
                E = app.parentApp.evDet.downstates.fromT(1:nds);
                F = app.parentApp.evDet.downstates.toT(1:nds);
                
                % then create the to be printed struct
                output = struct('TimeposSU', A, 'TimenegSU', B, 'UPstart', C, 'UPend', D, 'DOWNstart', E, 'DOWNend', F);
                
                
                %then create the text file. First find the correct name. I added the
                %possibility to add ltp number so that when several sums are recorded
                %at the ephys setup, which appear in the same folder, each of these
                %sums the data is separately stored
                name = app.edit_ltpnumber.Value;
                channel = app.chNumber.Value;
                fileout = [app.parentApp.dir_in 'SUanalysis' name 'Ch' num2str(channel) '.dat'];
                lengthstruct = length(fieldnames(output));
                fields = fieldnames(output);
                % find the field with the longest length
                ls = [app.nMax(channel, app.trNumber.Value) app.nMin(channel, app.trNumber.Value) app.nus app.nds];
                maximum = max(ls);
                
                % open and start printing
                fid = fopen(fileout, 'w');
                fprintf(fid,'%s\t', fields {:});
                fprintf(fid, '\n');
                for i =  1:maximum
                    for ff = 1:lengthstruct
                        if i > length(output.(fields{ff}))
                            fprintf(fid, '\t');
                        else
                            fprintf(fid,'%g\t', output.(fields{ff})(i));
                        end
                    end
                    fprintf(fid,'\n');
                end
                %fid = fclose(fid);
                fclose(fid);
            end
        end

        % Button pushed function: ComputeButton
        function ComputeClustering(app, event)
            % this callback firstly triggers the feature extraction
            % function; then it calls the clustering function; finally, it
            % goes to the plot function.
            FeatureExtraction(app);
            ch = app.chNumber.Value;
            tr = app.trNumber.Value;
            app.NumSelFeatEditField.Value = size((app.spikestruc(ch,tr).featSpk),2);
            Clustering(app);
            PlotClusters(app);
        end

        % Value changed function: ShowChSpinner
        function ShowChSpinnerValueChanged(app, event)
            ch = app.ShowChSpinner.Value;
            % set the spinner for the cluster and the spike to be shown
            if app.KmeansButton.Value
                if isempty(app.spikestruc(ch,1).clusKmeans)
                    app.ShowClusNumSpinner.Limits = [1, inf];
                    app.ShowClusNumSpinner.Enable = 'off';
                    app.ShowSpkNumSpinner.Limits = [1, inf];
                    app.ShowSpkNumSpinner.Enable = 'off';
                else
                    app.NumClusEditField.Value = max(app.spikestruc(ch,1).clusKmeans);
                    app.ShowClusNumSpinner.Limits = [1, max(app.spikestruc(ch,1).clusKmeans)]; % TRIAL!!!
                    if ~app.AllClusCheckBox.Value
                        app.ShowClusNumSpinner.Enable = 'on';
                    end
                    app.ShowSpkNumSpinner.Limits = [1, sum(app.spikestruc(ch,1).clusKmeans ==  app.ShowClusNumSpinner.Value)];
                    if ~app.AllSpkCheckBox.Value
                        app.ShowSpkNumSpinner.Enable = 'on';
                    end
                end
            end
            PlotClusters(app)
        end

        % Value changed function: AllClusCheckBox
        function AllClusCheckBoxValueChanged(app, event)
            value = app.AllClusCheckBox.Value;
            PlotClusters(app);
        end

        % Value changed function: AllSpkCheckBox
        function AllSpkCheckBoxValueChanged(app, event)
            value = app.AllSpkCheckBox.Value;
            PlotClusters(app);
        end

        % Value changed function: ShowMeanSpkCheckBox
        function ShowMeanSpkCheckBoxValueChanged(app, event)
            value = app.ShowMeanSpkCheckBox.Value;
            PlotClusters(app);
        end

        % Value changed function: ShowClusNumSpinner
        function ShowClusNumSpinnerValueChanged(app, event)
            value = app.ShowClusNumSpinner.Value;
            PlotClusters(app);
        end

        % Value changed function: ShowSpkNumSpinner
        function ShowSpkNumSpinnerValueChanged(app, event)
            value = app.ShowSpkNumSpinner.Value;
            PlotClusters(app);
        end

        % Value changed function: DimPlotClusSwitch
        function DimPlotClusSwitchValueChanged(app, event)
            value = app.DimPlotClusSwitch.Value;
            PlotClusters(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'singleunits2';
            app.TabGroup = uitabgroup(app.figure1);
            app.ExtractionTab = uitab(app.TabGroup);
            app.ExtractionTab.Title = 'Extraction';
            app.uibuttongroup1 = uibuttongroup(app.ExtractionTab);
            app.uibuttongroup1.Title = 'Count Number SU';
            app.text4 = uilabel(app.uibuttongroup1);
            app.text4.Text = 'Number of + peaks:';
            app.text5 = uilabel(app.uibuttongroup1);
            app.text5.Text = 'Number of - peaks:';
            app.label_nMax = uilabel(app.uibuttongroup1);
            app.label_nMax.Text = '-';
            app.label_nMin = uilabel(app.uibuttongroup1);
            app.label_nMin.Text = '-';
            app.text5_2 = uilabel(app.uibuttongroup1);
            app.text5_2.Text = 'Peaks in the whole selected channel:';
            app.label_nTot = uilabel(app.uibuttongroup1);
            app.label_nTot.Text = '-';
            app.uibuttongroup4 = uibuttongroup(app.ExtractionTab);
            app.uibuttongroup4.Title = 'SU in UP and DOWN states';
            app.checkbox_calculateSUUPDOWN = uicheckbox(app.uibuttongroup4);
            app.checkbox_calculateSUUPDOWN.ValueChangedFcn = createCallbackFcn(app, @checkbox_calculateSUUPDOWN_Callback, true);
            app.checkbox_calculateSUUPDOWN.Text = 'Calculate';
            app.uitable_output = uitable(app.uibuttongroup4);
            app.uitable_output.ColumnName = {'Entire Trace'; 'In UP states'; 'In DOWN states'};
            app.uitable_output.RowName = {'# + peaks'; '% + peaks'; '# - peaks'; '% - peaks'; '# all peaks'; '% all peaks'};
            app.uitable_output.ColumnEditable = [false false false];
            app.checkbox_savetimes = uicheckbox(app.uibuttongroup4);
            app.checkbox_savetimes.ValueChangedFcn = createCallbackFcn(app, @checkbox_savetimes_Callback, true);
            app.checkbox_savetimes.Text = 'Save Times';
            app.edit_ltpnumber = uieditfield(app.uibuttongroup4, 'text');
            app.edit_ltpnumber.Value = 'ltp001';
            app.text8 = uilabel(app.uibuttongroup4);
            app.text8.Text = {'type ltp number if'; 'more than one sum'; 'in the same folder'};
            app.OnlyworksonchannelandtrialselectedinZebraLabel = uilabel(app.uibuttongroup4);
            app.OnlyworksonchannelandtrialselectedinZebraLabel.Text = {'It only works for the channel and the trial selected'; 'in ZebraExplore main window, for which events'; 'have been extracted.'};
            app.CopyTableButton = uibutton(app.uibuttongroup4, 'push');
            app.CopyTableButton.ButtonPushedFcn = createCallbackFcn(app, @CopyTableButtonPushed, true);
            app.CopyTableButton.Text = 'Copy Table ';
            app.RasterplotPanel = uipanel(app.ExtractionTab);
            app.RasterplotPanel.Title = 'Raster plot';
            app.rasterPlot_bt = uibutton(app.RasterplotPanel, 'push');
            app.rasterPlot_bt.ButtonPushedFcn = createCallbackFcn(app, @rasterPlot_Callback, true);
            app.rasterPlot_bt.Text = 'Raster plot';
            app.KernelbandwidthEditFieldLabel = uilabel(app.RasterplotPanel);
            app.KernelbandwidthEditFieldLabel.Text = 'Kernel bandwidth';
            app.kernelBW_edit = uieditfield(app.RasterplotPanel, 'numeric');
            app.kernelBW_edit.Value = 150;
            app.detectionPlot_ck = uicheckbox(app.RasterplotPanel);
            app.detectionPlot_ck.Text = 'Show detection plot';
            app.calculate_SU = uibutton(app.ExtractionTab, 'push');
            app.calculate_SU.ButtonPushedFcn = createCallbackFcn(app, @calculate_SU_Callback, true);
            app.calculate_SU.Text = 'Calculate';
            app.reset_SU = uibutton(app.ExtractionTab, 'push');
            app.reset_SU.ButtonPushedFcn = createCallbackFcn(app, @reset_SU_Callback, true);
            app.reset_SU.Text = 'Reset';
            app.HPhisto_bt = uibutton(app.ExtractionTab, 'push');
            app.HPhisto_bt.ButtonPushedFcn = createCallbackFcn(app, @HPhisto_bt_Callback, true);
            app.HPhisto_bt.Text = 'HP histogram';
            app.Panel = uipanel(app.ExtractionTab);
            app.trNumber = uispinner(app.Panel);
            app.trNumber.Limits = [1 Inf];
            app.trNumber.ValueChangedFcn = createCallbackFcn(app, @trNumber_Callback, true);
            app.trNumber.Value = 1;
            app.trNumberTxt = uilabel(app.Panel);
            app.trNumberTxt.Text = 'Trial ';
            app.chNumber = uispinner(app.Panel);
            app.chNumber.Limits = [1 Inf];
            app.chNumber.ValueChangedFcn = createCallbackFcn(app, @chNumber_Callback, true);
            app.chNumber.Value = 1;
            app.chNumberTxt = uilabel(app.Panel);
            app.chNumberTxt.Text = 'Channel';
            app.BandPassLabel = uilabel(app.Panel);
            app.BandPassLabel.Text = 'BandPass';
            app.BpSelection = uispinner(app.Panel);
            app.BpSelection.Limits = [1 Inf];
            app.BpSelection.Value = 1;
            app.text2 = uilabel(app.ExtractionTab);
            app.text2.Text = {'Use high pass filtered'; 'data at 300 Hz'};
            app.AlignmentPanel = uipanel(app.ExtractionTab);
            app.AlignmentPanel.Title = 'Alignment';
            app.beforethepeakmsLabel = uilabel(app.AlignmentPanel);
            app.beforethepeakmsLabel.Tooltip = {'Number of time points before the peak'};
            app.beforethepeakmsLabel.Text = 'before the peak (ms)';
            app.preSEditField = uieditfield(app.AlignmentPanel, 'numeric');
            app.preSEditField.Value = 0.66;
            app.afterthepeakmsLabel = uilabel(app.AlignmentPanel);
            app.afterthepeakmsLabel.Tooltip = {'Number of time points before the peak'};
            app.afterthepeakmsLabel.Text = 'after the peak (ms)';
            app.postSEditField = uieditfield(app.AlignmentPanel, 'numeric');
            app.postSEditField.Tooltip = {'Number of samples after the peak'};
            app.postSEditField.Value = 1.46;
            app.RefractoryperiodmsLabel = uilabel(app.AlignmentPanel);
            app.RefractoryperiodmsLabel.Tooltip = {'Temporal window between two following spikes'};
            app.RefractoryperiodmsLabel.Text = 'Refractory period (ms)';
            app.refEditField = uieditfield(app.AlignmentPanel, 'numeric');
            app.refEditField.Tooltip = {'Number of samples after the peak'};
            app.refEditField.Value = 1.5;
            app.InterpolationCheckBox = uicheckbox(app.AlignmentPanel);
            app.InterpolationCheckBox.Text = 'Interpolation';
            app.InterpolationCheckBox.Value = true;
            app.FactorLabel = uilabel(app.AlignmentPanel);
            app.FactorLabel.Text = '- Factor';
            app.FactorEditField = uieditfield(app.AlignmentPanel, 'numeric');
            app.FactorEditField.Value = 5;
            app.uibuttongroup3 = uibuttongroup(app.ExtractionTab);
            app.uibuttongroup3.Title = 'Plotting';
            app.checkbox_plotLFP = uicheckbox(app.uibuttongroup3);
            app.checkbox_plotLFP.ValueChangedFcn = createCallbackFcn(app, @checkbox_plotLFP_Callback, true);
            app.checkbox_plotLFP.Text = 'Plot LFP';
            app.checkbox_plotLFP.Value = true;
            app.checkbox_SUmax = uicheckbox(app.uibuttongroup3);
            app.checkbox_SUmax.ValueChangedFcn = createCallbackFcn(app, @checkbox_plotLFP_Callback, true);
            app.checkbox_SUmax.Text = 'Plot positive SU';
            app.checkbox_SUmax.Value = true;
            app.checkbox_SUmin = uicheckbox(app.uibuttongroup3);
            app.checkbox_SUmin.ValueChangedFcn = createCallbackFcn(app, @checkbox_plotLFP_Callback, true);
            app.checkbox_SUmin.Text = 'Plot negative SU';
            app.checkbox_SUmin.Value = true;
            app.pushbutton_scroll_left = uibutton(app.uibuttongroup3, 'push');
            app.pushbutton_scroll_left.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_scroll_left_Callback, true);
            app.pushbutton_scroll_left.Text = '< scroll';
            app.pushbutton_reset = uibutton(app.uibuttongroup3, 'push');
            app.pushbutton_reset.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_reset_Callback, true);
            app.pushbutton_reset.Text = 'Reset';
            app.pushbutton_scroll_right = uibutton(app.uibuttongroup3, 'push');
            app.pushbutton_scroll_right.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_scroll_right_Callback, true);
            app.pushbutton_scroll_right.Text = 'scroll >';
            app.edit_tStart_plot = uieditfield(app.uibuttongroup3, 'text');
            app.edit_tStart_plot.ValueChangedFcn = createCallbackFcn(app, @edit_tStart_plot_Callback, true);
            app.edit_tEnd_plot = uieditfield(app.uibuttongroup3, 'text');
            app.edit_tEnd_plot.ValueChangedFcn = createCallbackFcn(app, @edit_tEnd_plot_Callback, true);
            app.ReplotButton = uibutton(app.uibuttongroup3, 'push');
            app.ReplotButton.ButtonPushedFcn = createCallbackFcn(app, @replot_Callback, true);
            app.ReplotButton.Text = 'Replot';
            app.checkbox_hp = uicheckbox(app.uibuttongroup3);
            app.checkbox_hp.ValueChangedFcn = createCallbackFcn(app, @checkbox_plotLFP_Callback, true);
            app.checkbox_hp.Text = 'Plot high-passed LFP';
            app.checkbox_hp.Value = true;
            app.LFPaxes = uiaxes(app.uibuttongroup3);
            ylabel(app.LFPaxes, 'LFP (mV)')
            app.SUaxes = uiaxes(app.uibuttongroup3);
            xlabel(app.SUaxes, 'Time (s)')
            ylabel(app.SUaxes, 'SU')
            app.SUaxes.YLim = [-1 1];
            app.HPaxes = uiaxes(app.uibuttongroup3);
            ylabel(app.HPaxes, 'hp LFP (mV)')
            app.HPaxes.XTickLabel = '';
            app.ClusteringTab = uitab(app.TabGroup);
            app.ClusteringTab.Title = 'Clustering';
            app.ChannelsButtonGroup = uibuttongroup(app.ClusteringTab);
            app.ChannelsButtonGroup.Title = 'Channels';
            app.SingleChButton = uiradiobutton(app.ChannelsButtonGroup);
            app.SingleChButton.Enable = 'off';
            app.SingleChButton.Text = 'Single Ch';
            app.AllChButton = uiradiobutton(app.ChannelsButtonGroup);
            app.AllChButton.Text = 'All Ch';
            app.AllChButton.Value = true;
            app.ShowChSpinnerLabel = uilabel(app.ChannelsButtonGroup);
            app.ShowChSpinnerLabel.Text = 'Show Ch';
            app.ShowChSpinner = uispinner(app.ChannelsButtonGroup);
            app.ShowChSpinner.ValueChangedFcn = createCallbackFcn(app, @ShowChSpinnerValueChanged, true);
            app.ShowChSpinner.Value = 1;
            app.ChannelorderforNNLabel = uilabel(app.ChannelsButtonGroup);
            app.ChannelorderforNNLabel.Text = 'Channel order for NN??';
            app.FeatureExtractionButtonGroup = uibuttongroup(app.ClusteringTab);
            app.FeatureExtractionButtonGroup.Title = 'Feature Extraction';
            app.WTButton = uiradiobutton(app.FeatureExtractionButtonGroup);
            app.WTButton.Text = 'Wavelet Transform';
            app.WTButton.Value = true;
            app.PCAButton = uiradiobutton(app.FeatureExtractionButtonGroup);
            app.PCAButton.Text = 'PCA';
            app.WaveformButton = uiradiobutton(app.FeatureExtractionButtonGroup);
            app.WaveformButton.Text = 'Waveform';
            app.LevelsEditFieldLabel = uilabel(app.FeatureExtractionButtonGroup);
            app.LevelsEditFieldLabel.Text = 'Levels';
            app.LevelsEditField = uieditfield(app.FeatureExtractionButtonGroup, 'numeric');
            app.LevelsEditField.Limits = [0 Inf];
            app.LevelsEditField.Value = 4;
            app.ComponentsEditFieldLabel = uilabel(app.FeatureExtractionButtonGroup);
            app.ComponentsEditFieldLabel.Text = 'Components';
            app.ComponentsEditField = uieditfield(app.FeatureExtractionButtonGroup, 'numeric');
            app.ComponentsEditField.Value = 3;
            app.MininputsEditFieldLabel = uilabel(app.FeatureExtractionButtonGroup);
            app.MininputsEditFieldLabel.Text = 'Min inputs';
            app.MininputsEditField = uieditfield(app.FeatureExtractionButtonGroup, 'numeric');
            app.MininputsEditField.Value = 10;
            app.MaxinputsEditFieldLabel = uilabel(app.FeatureExtractionButtonGroup);
            app.MaxinputsEditFieldLabel.Text = 'Max inputs';
            app.MaxinputsEditField = uieditfield(app.FeatureExtractionButtonGroup, 'numeric');
            app.MaxinputsEditField.Limits = [0 1];
            app.MaxinputsEditField.Tooltip = {'Fraction of the event lenght'};
            app.MaxinputsEditField.Value = 0.75;
            app.WaveletDropDownLabel = uilabel(app.FeatureExtractionButtonGroup);
            app.WaveletDropDownLabel.Text = 'Wavelet';
            app.WaveletDropDown = uidropdown(app.FeatureExtractionButtonGroup);
            app.WaveletDropDown.Items = {'haar', 'morlet', 'bump'};
            app.WaveletDropDown.Value = 'haar';
            app.NumberofselectedfeaturesEditFieldLabel = uilabel(app.FeatureExtractionButtonGroup);
            app.NumberofselectedfeaturesEditFieldLabel.Text = 'Number of selected features:';
            app.NumSelFeatEditField = uieditfield(app.FeatureExtractionButtonGroup, 'numeric');
            app.NumSelFeatEditField.Editable = 'off';
            app.PCACheckBox = uicheckbox(app.FeatureExtractionButtonGroup);
            app.PCACheckBox.Text = 'PCA';
            app.ClusteringButtonGroup = uibuttongroup(app.ClusteringTab);
            app.ClusteringButtonGroup.Tooltip = {'Minimum number of cluster by-default is 2'};
            app.ClusteringButtonGroup.Title = 'Clustering';
            app.SuperparamagneticButton = uiradiobutton(app.ClusteringButtonGroup);
            app.SuperparamagneticButton.Text = 'Superparamagnetic';
            app.SuperparamagneticButton.Value = true;
            app.KlustaButton = uiradiobutton(app.ClusteringButtonGroup);
            app.KlustaButton.Text = 'Klusta';
            app.KmeansButton = uiradiobutton(app.ClusteringButtonGroup);
            app.KmeansButton.Text = 'K-means';
            app.ParametersPanel = uipanel(app.ClusteringButtonGroup);
            app.ParametersPanel.Title = 'Parameters';
            app.kNNEditFieldLabel = uilabel(app.ParametersPanel);
            app.kNNEditFieldLabel.Text = 'k-NN';
            app.kNNEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.kNNEditField.Value = 11;
            app.minsizeclustersEditFieldLabel = uilabel(app.ParametersPanel);
            app.minsizeclustersEditFieldLabel.Text = 'min size clusters';
            app.minsizeclustersEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.minsizeclustersEditField.Value = 20;
            app.MaxClusterNumberEditFieldLabel = uilabel(app.ClusteringButtonGroup);
            app.MaxClusterNumberEditFieldLabel.Text = 'Max Cluster Number';
            app.MaxClusNumKEditField = uieditfield(app.ClusteringButtonGroup, 'numeric');
            app.MaxClusNumKEditField.Value = 10;
            app.ShowclusternumberevaluationCheckBox = uicheckbox(app.ClusteringButtonGroup);
            app.ShowclusternumberevaluationCheckBox.Text = 'Show cluster number evaluation';
            app.ShowclusternumberevaluationCheckBox.Value = true;
            app.CalinskiHarabaszCheckBox = uicheckbox(app.ClusteringButtonGroup);
            app.CalinskiHarabaszCheckBox.Text = 'Calinski-Harabasz Evaluation';
            app.SilhouetteEvalCheckBox = uicheckbox(app.ClusteringButtonGroup);
            app.SilhouetteEvalCheckBox.Text = 'Silhouette Evaluation';
            app.TabGroup2 = uitabgroup(app.ClusteringTab);
            app.SPCTab = uitab(app.TabGroup2);
            app.SPCTab.Title = 'SPC';
            app.ClusterSpinnerLabel = uilabel(app.SPCTab);
            app.ClusterSpinnerLabel.Text = 'Cluster';
            app.ClusterSpinner = uispinner(app.SPCTab);
            app.ShowallCheckBox = uicheckbox(app.SPCTab);
            app.ShowallCheckBox.Text = 'Show all';
            app.DetectedclustersEditFieldLabel = uilabel(app.SPCTab);
            app.DetectedclustersEditFieldLabel.Text = 'Detected clusters';
            app.DetectedclustersEditField = uieditfield(app.SPCTab, 'numeric');
            app.UIAxes = uiaxes(app.SPCTab);
            title(app.UIAxes, 'Spike')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.SPCTab);
            title(app.UIAxes2, 'ISI ?')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.SPCTab);
            title(app.UIAxes3, 'Projections')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.SPCTab);
            title(app.UIAxes4, 'Temperature')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.KlustaTab = uitab(app.TabGroup2);
            app.KlustaTab.Title = 'Klusta';
            app.KmeansTab = uitab(app.TabGroup2);
            app.KmeansTab.Title = 'K-means';
            app.NumberofidentifiedclustersEditFieldLabel = uilabel(app.KmeansTab);
            app.NumberofidentifiedclustersEditFieldLabel.Text = 'Number of identified clusters:';
            app.NumClusEditField = uieditfield(app.KmeansTab, 'numeric');
            app.NumClusEditField.Editable = 'off';
            app.ShowclusternumberSpinnerLabel = uilabel(app.KmeansTab);
            app.ShowclusternumberSpinnerLabel.Text = 'Show cluster number:';
            app.ShowClusNumSpinner = uispinner(app.KmeansTab);
            app.ShowClusNumSpinner.ValueChangedFcn = createCallbackFcn(app, @ShowClusNumSpinnerValueChanged, true);
            app.ShowspikenumberSpinnerLabel = uilabel(app.KmeansTab);
            app.ShowspikenumberSpinnerLabel.Text = 'Show spike number:';
            app.ShowSpkNumSpinner = uispinner(app.KmeansTab);
            app.ShowSpkNumSpinner.Limits = [0 Inf];
            app.ShowSpkNumSpinner.ValueChangedFcn = createCallbackFcn(app, @ShowSpkNumSpinnerValueChanged, true);
            app.ShowSpkNumSpinner.Tooltip = {'0 means showing all the spikes of the selected cluster together'};
            app.ShowMeanSpkCheckBox = uicheckbox(app.KmeansTab);
            app.ShowMeanSpkCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowMeanSpkCheckBoxValueChanged, true);
            app.ShowMeanSpkCheckBox.Text = 'Show the mean spike';
            app.DimensionfortheclustersplotSwitchLabel = uilabel(app.KmeansTab);
            app.DimensionfortheclustersplotSwitchLabel.Text = 'Dimension for the clusters'' plot';
            app.DimPlotClusSwitch = uiswitch(app.KmeansTab, 'slider');
            app.DimPlotClusSwitch.Items = {'2-D', '3-D'};
            app.DimPlotClusSwitch.ValueChangedFcn = createCallbackFcn(app, @DimPlotClusSwitchValueChanged, true);
            app.DimPlotClusSwitch.Value = '2-D';
            app.AllClusCheckBox = uicheckbox(app.KmeansTab);
            app.AllClusCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllClusCheckBoxValueChanged, true);
            app.AllClusCheckBox.Text = 'Plot all the clusters';
            app.AllClusCheckBox.Value = true;
            app.AllSpkCheckBox = uicheckbox(app.KmeansTab);
            app.AllSpkCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllSpkCheckBoxValueChanged, true);
            app.AllSpkCheckBox.Text = 'Plot all the spikes';
            app.UIAxes5 = uiaxes(app.KmeansTab);
            title(app.UIAxes5, 'Sorted Spikes')
            xlabel(app.UIAxes5, 'time (ms)')
            ylabel(app.UIAxes5, 'Amplitude (uV)')
            app.UIAxes6 = uiaxes(app.KmeansTab);
            title(app.UIAxes6, 'Clusters')
            xlabel(app.UIAxes6, 'Feature 1')
            ylabel(app.UIAxes6, 'Feature 2')
            app.ComputeButton = uibutton(app.ClusteringTab, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeClustering, true);
            app.ComputeButton.Text = 'Compute';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SingleUnits_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)singleUnits_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end