classdef ZebraExploreApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ZebraMainFig                  matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        PlotTab                       matlab.ui.container.Tab
        plotSlate                     matlab.ui.container.Panel
        wide_plot_HP                  matlab.ui.control.UIAxes
        tminTxt                       matlab.ui.control.NumericEditField
        spectraWide                   matlab.ui.control.UIAxes
        wide_plot_up                  matlab.ui.control.UIAxes
        ymaxTxt                       matlab.ui.control.NumericEditField
        yminTxt                       matlab.ui.control.NumericEditField
        ResetButton                   matlab.ui.control.Button
        scrollLeft                    matlab.ui.control.Button
        scrollRight                   matlab.ui.control.Button
        tResetBtn                     matlab.ui.control.Button
        tmaxTxt                       matlab.ui.control.NumericEditField
        sendToClipboard               matlab.ui.control.Button
        uipanel5                      matlab.ui.container.Panel
        saveCurrentCh                 matlab.ui.control.Button
        saveSPG                       matlab.ui.control.Button
        saveBP                        matlab.ui.control.Button
        sepochsButton                 matlab.ui.control.Button
        TabGroup2                     matlab.ui.container.TabGroup
        InputDataTab                  matlab.ui.container.Tab
        select_data                   matlab.ui.control.Button
        excludeChFlag                 matlab.ui.control.CheckBox
        internalSynchCk               matlab.ui.control.CheckBox
        uipanel15                     matlab.ui.container.ButtonGroup
        mVck                          matlab.ui.control.RadioButton
        microVck                      matlab.ui.control.RadioButton
        autoclear                     matlab.ui.control.CheckBox
        openDir                       matlab.ui.control.Button
        excludeCh                     matlab.ui.control.NumericEditField
        synchCh                       matlab.ui.control.NumericEditField
        TrialtimeoffsetsLabel         matlab.ui.control.Label
        trTimeOffset_txt              matlab.ui.control.NumericEditField
        SamplingsEditFieldLabel       matlab.ui.control.Label
        spEdit                        matlab.ui.control.NumericEditField
        NChannelsLabel                matlab.ui.control.Label
        chEdit                        matlab.ui.control.NumericEditField
        TrialsEditFieldLabel          matlab.ui.control.Label
        trialsEdit                    matlab.ui.control.NumericEditField
        trialText                     matlab.ui.control.Label
        trialSlider                   matlab.ui.control.Spinner
        ChText                        matlab.ui.control.Label
        chSlider                      matlab.ui.control.Spinner
        TrialtimesLabel               matlab.ui.control.Label
        SweepTimeEdit                 matlab.ui.control.NumericEditField
        GainEditFieldLabel            matlab.ui.control.Label
        gain                          matlab.ui.control.NumericEditField
        DataFormatLabel               matlab.ui.control.Label
        dataFormat                    matlab.ui.control.DropDown
        autofocus                     matlab.ui.control.CheckBox
        DisplayControlTab             matlab.ui.container.Tab
        computeBP                     matlab.ui.control.Button
        plotMeanFlag                  matlab.ui.control.CheckBox
        plotMultiCh                   matlab.ui.control.CheckBox
        plotTrCk                      matlab.ui.control.CheckBox
        autoOffCk                     matlab.ui.control.CheckBox
        recomputeSPG                  matlab.ui.control.Button
        autoscaleYck                  matlab.ui.control.CheckBox
        plotTemplateCk                matlab.ui.control.CheckBox
        SPGmode                       matlab.ui.container.ButtonGroup
        SPG                           matlab.ui.control.RadioButton
        gammaPower                    matlab.ui.control.RadioButton
        SPGlogCk                      matlab.ui.control.CheckBox
        EIbutton                      matlab.ui.control.RadioButton
        BP                            matlab.ui.control.RadioButton
        ChronuxCk                     matlab.ui.control.CheckBox
        displayLeakCk                 matlab.ui.control.CheckBox
        leakageCk                     matlab.ui.control.CheckBox
        ColormaplimitsLabel           matlab.ui.control.Label
        upperDB                       matlab.ui.control.NumericEditField
        lowerDB                       matlab.ui.control.NumericEditField
        MaxLabel                      matlab.ui.control.Label
        MinLabel                      matlab.ui.control.Label
        PlotScaleTab                  matlab.ui.control.Table
        BPManagerPanel                matlab.ui.container.Panel
        BPplot_drop                   matlab.ui.control.DropDown
        BPplot_ck                     matlab.ui.control.CheckBox
        BPplotColor_btn               matlab.ui.control.Button
        BPplotColorShow_btn           matlab.ui.control.Button
        DefaultColorsButton           matlab.ui.control.Button
        BPsynch_led1                  matlab.ui.control.Lamp
        trialText_2                   matlab.ui.control.Label
        trialSlider_2                 matlab.ui.control.Spinner
        ChText_2                      matlab.ui.control.Label
        chSlider_2                    matlab.ui.control.Spinner
        SettingsTab                   matlab.ui.container.Tab
        autoProcessing                matlab.ui.container.Panel
        uipanel20                     matlab.ui.container.Panel
        powerSpectraCk                matlab.ui.control.CheckBox
        autoSavePSck                  matlab.ui.control.CheckBox
        analyzePWdistributionCk       matlab.ui.control.CheckBox
        autoSavePWck                  matlab.ui.control.CheckBox
        TrRej_ck                      matlab.ui.control.CheckBox
        multiChAnalyzer               matlab.ui.control.CheckBox
        singleUnitExtraction_ck       matlab.ui.control.CheckBox
        hit_ck                        matlab.ui.control.CheckBox
        checkbox_sleepscoring         matlab.ui.control.CheckBox
        saveParams_btn                matlab.ui.control.Button
        loadParams_btn                matlab.ui.control.Button
        uipanel21                     matlab.ui.container.Panel
        concat_ck                     matlab.ui.control.CheckBox
        analyzeWhileConc_ck           matlab.ui.control.CheckBox
        concat_btn                    matlab.ui.control.Button
        clearConc_btn                 matlab.ui.control.Button
        dataProcessing                matlab.ui.container.Panel
        uipanel4                      matlab.ui.container.ButtonGroup
        text8                         matlab.ui.control.Label
        text5                         matlab.ui.control.Label
        text16                        matlab.ui.control.Label
        text17                        matlab.ui.control.Label
        doubleStimCk                  matlab.ui.control.CheckBox
        templateCk                    matlab.ui.control.CheckBox
        text29                        matlab.ui.control.Label
        skip1trialCk                  matlab.ui.control.CheckBox
        templExp_bt                   matlab.ui.control.Button
        templImp_bt                   matlab.ui.control.Button
        templRecalc_bt                matlab.ui.control.Button
        smooth_ck                     matlab.ui.control.CheckBox
        singleUnitsThr                matlab.ui.control.NumericEditField
        baselinePower                 matlab.ui.control.NumericEditField
        baselineFrom                  matlab.ui.control.NumericEditField
        baselineTo                    matlab.ui.control.NumericEditField
        respFrom                      matlab.ui.control.NumericEditField
        respTo                        matlab.ui.control.NumericEditField
        winPWleft                     matlab.ui.control.NumericEditField
        winPWright                    matlab.ui.control.NumericEditField
        templateFrom                  matlab.ui.control.NumericEditField
        templateTo                    matlab.ui.control.NumericEditField
        BPtext                        matlab.ui.control.Label
        sliderBPpower                 matlab.ui.control.Spinner
        text32                        matlab.ui.control.Label
        TriggersynchmindurationsamplesLabel  matlab.ui.control.Label
        triggerMinLength              matlab.ui.control.NumericEditField
        TriggersynchmaxdurationsamplesLabel  matlab.ui.control.Label
        triggerMaxLength              matlab.ui.control.NumericEditField
        DisplayPanel                  matlab.ui.container.ButtonGroup
        text4                         matlab.ui.control.Label
        notchFilter                   matlab.ui.control.CheckBox
        predefinedBW                  matlab.ui.container.ButtonGroup
        mouseCk                       matlab.ui.control.RadioButton
        zebraCk                       matlab.ui.control.RadioButton
        EEGck                         matlab.ui.control.RadioButton
        NewcastleBand                 matlab.ui.control.RadioButton
        envelopeChoice                matlab.ui.container.ButtonGroup
        text12                        matlab.ui.control.Label
        radiobutton6                  matlab.ui.control.RadioButton
        plotEnvelopeCk                matlab.ui.control.CheckBox
        filterBPpower                 matlab.ui.control.CheckBox
        envSPGfrom                    matlab.ui.control.NumericEditField
        envSPGto                      matlab.ui.control.NumericEditField
        uipanel17                     matlab.ui.container.Panel
        uipanel18                     matlab.ui.container.Panel
        txt                           matlab.ui.control.Label
        txtspg                        matlab.ui.control.Label
        text21                        matlab.ui.control.Label
        SPGresolution                 matlab.ui.control.NumericEditField
        SPGwindow                     matlab.ui.control.NumericEditField
        SPGoverlap                    matlab.ui.control.NumericEditField
        uipanel19                     matlab.ui.container.Panel
        ChronuxAutoConfigCk           matlab.ui.control.CheckBox
        text25                        matlab.ui.control.Label
        text26                        matlab.ui.control.Label
        ChronuxRW                     matlab.ui.control.NumericEditField
        ChronuxOvl                    matlab.ui.control.NumericEditField
        text27                        matlab.ui.control.Label
        text28                        matlab.ui.control.Label
        decimateFlag                  matlab.ui.control.CheckBox
        text30                        matlab.ui.control.Label
        SPGfromFreq                   matlab.ui.control.NumericEditField
        SPGtoFreq                     matlab.ui.control.NumericEditField
        leakageLP                     matlab.ui.control.NumericEditField
        text33                        matlab.ui.control.Label
        filtOrder                     matlab.ui.control.NumericEditField
        notchFrom                     matlab.ui.control.NumericEditField
        notchTo                       matlab.ui.control.NumericEditField
        BpTable                       matlab.ui.control.Table
        NumberofBPsEditFieldLabel     matlab.ui.control.Label
        NumberofBPsEditField          matlab.ui.control.NumericEditField
        DeleteBpBtn                   matlab.ui.control.Button
        MoveDownBpBtn                 matlab.ui.control.Button
        MoveUpBpBtn                   matlab.ui.control.Button
        computeBP_2                   matlab.ui.control.Button
        BPsynch_led2                  matlab.ui.control.Lamp
        ZebraAutoPanel                matlab.ui.container.Panel
        AutoSetContrasts_bt           matlab.ui.control.Button
        AutoGo_bt                     matlab.ui.control.Button
        autoMode_bt                   matlab.ui.control.Button
        NumberofconditionsLabel       matlab.ui.control.Label
        AutoNConds_txt                matlab.ui.control.NumericEditField
        uipanel2_2                    matlab.ui.container.Panel
        text2                         matlab.ui.control.Label
        AutoFileNameIn_edit           matlab.ui.control.EditField
        AutoOutFileName_edit          matlab.ui.control.EditField
        text3                         matlab.ui.control.Label
        AutoMainFolder_edit           matlab.ui.control.EditField
        text4_2                       matlab.ui.control.Label
        uipanel1                      matlab.ui.container.Panel
        AutoSpg_ck                    matlab.ui.control.CheckBox
        AutoTable_ck                  matlab.ui.control.CheckBox
        AutoTrace_ck                  matlab.ui.control.CheckBox
        uipanel4_2                    matlab.ui.container.Panel
        AutoSpectra_ck                matlab.ui.control.CheckBox
        AutoWinSpectra_ck             matlab.ui.control.CheckBox
        AutoWinSpectra2_ck            matlab.ui.control.CheckBox
        fromEditFieldLabel            matlab.ui.control.Label
        AutoSpectraFrom_edit          matlab.ui.control.NumericEditField
        toEditFieldLabel              matlab.ui.control.Label
        AutoSpectraTo_edit            matlab.ui.control.NumericEditField
        fromEditField_2Label          matlab.ui.control.Label
        AutoSpectraFrom2_edit         matlab.ui.control.NumericEditField
        toEditField_2Label            matlab.ui.control.Label
        AutoSpectraTo2_edit           matlab.ui.control.NumericEditField
        ChannelselectionButtonGroup   matlab.ui.container.ButtonGroup
        allCh_rb                      matlab.ui.control.RadioButton
        selCh_rb                      matlab.ui.control.RadioButton
        selCh_edit                    matlab.ui.control.NumericEditField
        sound_ck                      matlab.ui.control.CheckBox
        ZebraSaysTab                  matlab.ui.container.Tab
        outable                       matlab.ui.control.Table
        MetricsoftheentiretraceLabel  matlab.ui.control.Label
        MetricsoftheevokedresponsessingletrialsandaveragesLabel  matlab.ui.control.Label
        MetricsofasynchronouseventsburstsandseizuresLabel  matlab.ui.control.Label
        outEvochedResponses           matlab.ui.control.Table
        outEventsSummary              matlab.ui.control.Table
        CopytableButton               matlab.ui.control.Button
        CopyOutableSelection          matlab.ui.control.Button
        DeleteSelRows                 matlab.ui.control.Button
        CopyEvochedSelection          matlab.ui.control.Button
        EventDetectionTab             matlab.ui.container.Tab
        uipanel2_3                    matlab.ui.container.Panel
        text1                         matlab.ui.control.Label
        text2_2                       matlab.ui.control.Label
        text3_2                       matlab.ui.control.Label
        extractEvents                 matlab.ui.control.Button
        analyzeData                   matlab.ui.control.Button
        text4_3                       matlab.ui.control.Label
        gauss1param                   matlab.ui.control.Label
        gauss2param                   matlab.ui.control.Label
        text8_2                       matlab.ui.control.Label
        text9                         matlab.ui.control.Label
        replot                        matlab.ui.control.Button
        erodeCk                       matlab.ui.control.CheckBox
        uipanel3_2                    matlab.ui.container.ButtonGroup
        powerLogCk                    matlab.ui.control.RadioButton
        radiobutton2                  matlab.ui.control.RadioButton
        removeCk                      matlab.ui.control.CheckBox
        autoThr                       matlab.ui.control.CheckBox
        conMethod                     matlab.ui.container.ButtonGroup
        noConcCk                      matlab.ui.control.RadioButton
        joinCk                        matlab.ui.control.RadioButton
        joinOffsetChk                 matlab.ui.control.RadioButton
        traslateLeft                  matlab.ui.control.Button
        traslateRight                 matlab.ui.control.Button
        leftTime                      matlab.ui.control.NumericEditField
        rightTime                     matlab.ui.control.NumericEditField
        fromH                         matlab.ui.control.NumericEditField
        toH                           matlab.ui.control.NumericEditField
        binNumber                     matlab.ui.control.NumericEditField
        minDuration                   matlab.ui.control.NumericEditField
        baselineThr                   matlab.ui.control.NumericEditField
        maxInterruption               matlab.ui.control.NumericEditField
        eventThr                      matlab.ui.control.NumericEditField
        SelectedBPLabel               matlab.ui.control.Label
        detectionBP_dropDown          matlab.ui.control.DropDown
        BPforMultipeakWavesSpinnerLabel  matlab.ui.control.Label
        BPforMultipeakWaves_spinner   matlab.ui.control.Spinner
        uipanel1_2                    matlab.ui.container.Panel
        histoPlot                     matlab.ui.control.UIAxes
        plotStates                    matlab.ui.control.UIAxes
        statesTrack                   matlab.ui.control.UIAxes
        saveHCD                       matlab.ui.control.Button
        uipanel4_3                    matlab.ui.container.Panel
        powerSpectra                  matlab.ui.control.UIAxes
        uipanel5_2                    matlab.ui.container.Panel
        text10                        matlab.ui.control.Label
        text11                        matlab.ui.control.Label
        savePWS                       matlab.ui.control.Button
        saveSegmentedStates           matlab.ui.control.Button
        UStable                       matlab.ui.control.Table
        exportTab_btn                 matlab.ui.control.Button
        minPw                         matlab.ui.control.NumericEditField
        maxPw                         matlab.ui.control.NumericEditField
        minFreq                       matlab.ui.control.NumericEditField
        maxFreq                       matlab.ui.control.NumericEditField
        SaveStartImagingButton        matlab.ui.control.Button
        SaveDownstSpec                matlab.ui.control.Button
        CopyTableButton               matlab.ui.control.Button
        uipanel7                      matlab.ui.container.ButtonGroup
        detectionBP1                  matlab.ui.control.RadioButton
        detectionBP2                  matlab.ui.control.RadioButton
        detectionBP3                  matlab.ui.control.RadioButton
        detectionHP                   matlab.ui.control.RadioButton
        detectionSPG                  matlab.ui.control.RadioButton
    end

    properties (Access = public)
        platformType % character array that contains the operating system
        
        notch % app.notch is an array with the two param
        notchOrder % order 6 for the notch filter % MIGHT BE UNUSED
        dtaComplete % Complete path and file name
        file_in % selected file name
        dir_in % directory of selected file
        expID
        fileInNum % keep track of the number of analyzed files for the output table
        eventNum % keep track of the number of analyzed events
        cnt % counter for full trace summary table
        EPcnt % counter for the summary table of evoched responses
        insetW % width of the inset window (s)
        lp % width of the low pass filter window % MIGHT BE UNUSED
        gainAmp % total gain of the ampification of the recording
        xnow
        
        sp % sampling period in s
        acqF % sampling frequency
        nCh % number of channels
        nTrials % number of trials
        currentTrial
        currentCh
        dtaLen % total length of the data
        x % Time vector
        params % structure of parameters for calculating the spectrogram
        fileTime % the time at which the file was opened
        freqN % number of frequencies of spectrogram
        
        LFP % matrix containing the raw recording. 3-D array: channel, trial, time
        bandPassed_LFP % 4-D array: band, channel, trial, time
        workLFP
        LPfiltLeakage %lowpassed LFP for spectral leakage correction
        
        spg
        spgl
        spgt
        spgw
        spgDeleaked
        spgPlot
        spgPlotDeleaked
        meanSpgDeleaked
        meanLFP
        meanBP
        meanSpg
        meanSpgPlot
        meanPWS
        EIrat
        meanEIrat
        EPamplitude
        EIresponse
        EPoffset
        templ
        SPGlowDB
        SPGhighDB
        templateConv
        ntemp
        templatePeakSigned
        BP4power
        pwsf % Vector of frequencies for the spectra (1)
        pwsf2 % Vector of frequencies for the spectra (2)
        
        tmin
        tmax
        storedLFP
        filterOrder
        frequencyBand % stores the lower and upper bounds of the frequency bands
        frequencyBand_ck % for each band, if it gets 0 the band is skipped during the analysis
        BPpowerL % 1-D array: n elements. Each i-element contains the number of "interesting" elements to be considered in BPpower for the i-band. n is the total number of bands.
        BPpower % 5-D array: band, 1=time 2=rms power, channel, trial, time
        BPpowerSP % the same as BPpowerL but for the sampling period.
        BPcomputed % logic array that indicates if each band has been calculated.
        dtaSummary
        eventSummary
        respSummary
        dtaPWsummary
        dtaPWlocalSummary
        outtableSelectedIndices % Indices of selected data in outable table
        outEvochedSelectedIndices % Indices of selected data in outEvochedResponses table
        BpTableSelIndices % Indices of selected data in BpTable table
        
        minLFP
        maxLFP
        
        % Checks
        deleakedFlag
        autoMode 
        
        selectedBands
        lpRMS
        bp1RMS
        bp2RMS
        bp3RMS
        hpRMS
        
        timeOffset   % offset on time (seconds)
        timeOffset_i % offset on time (indices)
        
        paramsStruct %structure for saving and loading parameters automatically
        
        selection % a variable stored in ZebraMain, useful for zebraAuto 
        
        % Variables for ZebraAuto
        auto %scalar structure with all the variables for zebraAuto
%         AutoDlgIn % The cell array containing the folders to be opened by ZebraAuto
%         AutoFolders % This is the list of folders used by ZebraAuto
        
        % https://undocumentedmatlab.com/blog/property-value-change-listeners
        
        %% Variables for EventDetection
        evDet %scalar structure storing all eventDetection variables.
        
        %% Stored handles of children apps
        spectreApp %ZebraSpectre
        SUapp % SingleUnit app
        
        BP_4_plot % array with one element per band pass; if the i-th element is 1 (0), the i-th BP will (won't) be plotted
        BP_color % matrix with dimension #BPs x 3; stores color (r-g-b) for each BP
        BP_color_default % 8 default colors for BPs. Initialized in the OpeningFunction
        
        chEditListener % Listener for the property 'Value' of Ch number edit field. 
        alreadySkipped % initalized to 0; gets 1 if the first trial is discarded during the trial extraction.
                            %if so, the option "skipFirstTrial" is ignored.
    end
    
    properties (Access = private)
        
    end
    
    methods (Access = private)

        function computeBPandNotch(app)
            %%
            % March 12, 2018
            % code has been modified to able/enable the BP computations
            
            % first: check if the notch filter is selected.
            if app.notchFilter.Value
                [b,a] = butter(3,2*app.notch*app.sp,'stop');    %app.notch is an array with the two param
                for i = 1:app.nCh
                    for j= 1:app.nTrials
                        tmp (1:app.dtaLen) = app.LFP (i,j,1:app.dtaLen);    %.'
                        tmp2 = filtfilt(b,a,tmp.');
                        %app.workLFP (i,j,1:app.dtaLen) = filtfilt(b,a,tmp.');
                        %app.workLFP (i,j,1:end) = filtfilt(b,a,app.LFP.');
                        app.workLFP(i,j,1:app.dtaLen) = tmp2(1:app.dtaLen);
                    end
                end
            else
                app.workLFP = app.LFP;
            end
            % computations are performed starting from the workLFP file
            
            app.filterOrder = get(app.filtOrder,'Value');
            % compute band passed data
            % Variable number of frequency bands, ENRICO 2019/04/17, GAB 2019/05/23
            app.bandPassed_LFP = NaN(app.NumberofBPsEditField.Value,app.nCh,app.nTrials,app.dtaLen);
            app.BPcomputed = zeros(app.NumberofBPsEditField.Value,1);
            for i = 1:app.NumberofBPsEditField.Value %number of bands
                if app.frequencyBand_ck(i) == 1
                    if app.frequencyBand(i,1) == 0 % lower bound = 0 means low-pass
                        [b,a] = butter(app.filterOrder,2*app.frequencyBand(i,2)*app.sp,'low');  %Low pass filter
                    elseif app.frequencyBand(i,2) == Inf % upper bound = inf means high-pass
                        [b,a] = butter(app.filterOrder,2*app.frequencyBand(i,1)*app.sp, 'high');% high pass and single units
                    else % band-pass
                        [b,a] = butter(app.filterOrder,2*app.frequencyBand(i,:)*app.sp);
                    end
                    app.bandPassed_LFP(i,:,:,:) = computeFilteredData(app, b, a);
                    app.BPcomputed(i) = 1;
                    
                    %also compute the mean filtered lfp
                    skip1 = app.skip1trialCk.Value;
                    app.meanBP(i,:,:) = mean(app.bandPassed_LFP(i,:,1+skip1-app.alreadySkipped:app.nTrials,:),3);
                else
                    app.BPcomputed(i) = 0;
                end
            end
%                   % compute the LP data for spectral leakage correction
            if app.leakageCk.Value
                [b,a] = butter(app.filterOrder,2*app.leakageLP.Value*app.sp,'low');  %Low pass filter
                app.LPfiltLeakage = computeFilteredData(app, b, a);
            end
            % envelope computation in beta+gamma band for the automatic ID of events
            %tmp = abs(app.bandPassed_LFP(2,:)+app.bandPassed_LFP(3,:));
            %app.envelope = abs(hilbert(tmp));
            % computer rolling average
            %app.rollingAV = sgolayfilt(tmp,3,1001);
        end
        
        function filteredLFP = computeFilteredData(app, b, a)
            %% b, a are the descriptor of the filter
            filteredLFP = zeros(app.nCh,app.nTrials,app.dtaLen);
            for i = 1:app.nCh
                for j = 1:app.nTrials
                    filteredLFP(i,j,:) = filtfilt(b, a, squeeze(app.workLFP(i,j,1:app.dtaLen)));
                end
            end
        end
        
        function computePowerInTime(app)
            %% May 8, 2017
            % Here we compute the short time RMS power for the four band passed data
            % The computation is performed on a window which amplitude is given by the
            % larger of 1) the time window of the SPG computation (ChronuxRW) or
            % 2) the lower period of the bandwidth.
            % The overlap is one fifth of this.
            % Therefore each STRMS plot has its own sampling, so each array contains
            % both the x and the power
            
            chronuxWin = app.ChronuxRW.Value;
            
            % First, preallocation of the array app.BPpower
            % This array has different lengths for the different bands. The max
            % length is given by the window size determined by the value of
            % ChronuxWin.
            pntWin = floor((chronuxWin/app.sp)/2);        % Cunning strategy! See later!
            pntOvl = floor(pntWin/5);
            pntWin = 2* pntOvl * 5;                           % In this way pntWin is a multiple of 10
            centerBegin = pntWin/2+1;
            BPindexes = centerBegin:pntOvl:(app.dtaLen-pntWin/2);  % this array is defined to preallocate it and to compute
            maxDim = length(BPindexes);                               % the size of the longest array


%             % Each BP power data has its own length that is stored in the array
%             % app.BPpowerL
%             app.BPpowerL = zeros(5);
%             % indexes of BPpower: 1) 1-> time-points, 2-> power; 2) band pass; 3) channel; 4)
%             % trial; 6) time.

%             app.BPpower = cell(app.NumberofBPsEditField.Value);
%             app.BPpower = zeros(2, 5, app.nCh, app.nTrials, maxDim);

%!!!          GAB 2019-05-22; replaced the app.BPpowerL and app.BPpower
%             arrays with the STRUCTURE ARRAY app.BPpower. 
%               -Its fields are:
%                   'time'
%                   'power' (3D matrix: channel x trial x time)
%                -Its lenght is equal to the number of BPs stored in BpTable.

            
            app.BPpower = struct('power',[],'time',[]); %
            
            % Lets cycle on the filtered data
            for iCh = 1:app.nCh
                for jTr= 1:app.nTrials
                    for BPsel=1:app.NumberofBPsEditField.Value
                        if app.BPcomputed(BPsel) == 1   % perform the computation only if that BP has been computed
                            % first compute the x
                            BPindexes = 1;    % initialize the index array
                            BPindLeft = 1;
                            BPindRight = 1;
                            % compute the width of the window for power computation
                            lp = 1/app.frequencyBand(BPsel,1);      % lower limit of the bandpass
                            winT = max([lp chronuxWin]);                % for higher frequency I use the window used for SPG computation
                            pntWin = floor((winT/app.sp)/2);        % Cunning strategy! See later!
                            pntOvl = floor(pntWin/5);
                            pntWin = 2* pntOvl * 5;                     % In this way pntWin is a multiple of 10
                            pntWin2 = pntWin/2;
                            centerBegin = pntWin/2+1;
                            BPindexes = centerBegin:pntOvl:(app.dtaLen-pntWin2);  % this array contains the indexes of the
                                                                                      % BP data around which are computed the RMS powers
                            
                            xBPpowerEnd = length(BPindexes);
                            %app.BPpowerL(BPsel) = xBPpowerEnd;
                            
                            if xBPpowerEnd<1
                                xBPpowerEnd = 1;
                                % this condition can only occur when the data is too short
                                % for the specified bandwidth. This is done to prevent an
                                % ensuing error condition, but, of course, no power can be
                                % computed in this bandwidth.
                                BPindexes(1) = app.dtaLen/2;
                                % the power plot contains a single point at the middle of
                                % the trial.
                            end
                            
                            BPindLeft(1:xBPpowerEnd) = BPindexes(1:xBPpowerEnd)-pntWin2;    % left and right limits (in sampling points)
                            BPindRight(1:xBPpowerEnd) = BPindexes(1:xBPpowerEnd)+pntWin2;   % of the integration window
                            
                            if (BPindLeft(1)<1), BPindLeft(1) = 1;
                            end
                            
                            if (BPindRight(xBPpowerEnd)>app.dtaLen), BPindRight(xBPpowerEnd)=app.dtaLen;
                            end
                            
                            % compute X axis
%                             app.BPpower (1,BPsel,iCh,jTr,1:xBPpowerEnd) = app.sp * (BPindexes(1:xBPpowerEnd)-1) + app.timeOffset;  % time coordinate - GAB 2019/02/24: added time offset
                            app.BPpower(BPsel).time = app.sp * (BPindexes(1:xBPpowerEnd)-1) + app.timeOffset;
                            
                            if (xBPpowerEnd>1)         % again, more controls to protect bad bandwidths
                                app.BPpowerSP(BPsel).time = app.sp * (BPindexes(2)-BPindexes(1)) + app.timeOffset; %GAB 2019/02/24: added time offset
                            else
                                app.BPpowerSP(BPsel).time = 0;
                            end
                            
                            for timI = 1:xBPpowerEnd
                                % this loop computes the power
                %                 app.BPpower (2,BPsel,iCh,jTr,1:xBPpowerEnd) = rms...
                %                     (app.bandPassed_LFP(BPsel,iCh,jTr,BPindLeft(1:xBPpowerEnd):BPindRight(1:xBPpowerEnd)),4);
%                                 app.BPpower (2,BPsel,iCh,jTr,timI) = rms...
%                                     (app.bandPassed_LFP(BPsel,iCh,jTr,BPindLeft(timI):BPindRight(timI)),4);
                                app.BPpower(BPsel).power(iCh,jTr,timI) = rms...
                                    (app.bandPassed_LFP(BPsel,iCh,jTr,BPindLeft(timI):BPindRight(timI)),4);
                            end
                        else
                            app.BPpower(BPsel).time  = [];
                            app.BPpower(BPsel).power = [];
                        end
                    end
                end
            end
        end
                
        
        function openFile(app, selection, path_in)
            
            % this function open and process the file specified in [path_in selection]
            
            %%
            app.file_in = selection;
            app.dir_in = path_in;
            app.dtaComplete=[path_in selection];
            set(app.plotSlate,'title', app.dtaComplete);
            
            % Update the time offset (both in time and indices)
            app.timeOffset = app.trTimeOffset_txt.Value; %offset in seconds GAB 30/10/2018
            app.timeOffset_i = round(app.timeOffset/app.sp);
            % time Offset must be a multiple of the sampling period:
            app.timeOffset = app.timeOffset_i * app.sp;
            app.trTimeOffset_txt.Value = app.timeOffset;
            
            % Output to console of status and timing
            tic
            disp([num2str(toc) ' Begin to load data file'])
            % initialize all arrays
            LFPin = [];
            app.LFP = [];
            app.bandPassed_LFP = [];
            app.workLFP = [];
            app.spg = [];
            app.spgDeleaked = [];
            app.spgPlot = [];
            app.spgPlotDeleaked = [];
            app.meanLFP = [];
            app.meanBP = [];
            app.meanSpg = [];
            app.meanSpgPlot = [];
            app.meanPWS = [];
            app.EIrat = [];
            app.meanEIrat = [];
            app.EPamplitude = [];
            app.EIresponse = [];
            app.EPoffset = [];
            app.templ = [];
            
            % flags to indicate the frequency domain computations that have been
            % performed on the data set
            app.BPcomputed(1:end) = 0;
            
            app.EPcnt = 0;
            app.xnow = -100;
            exCh = get(app.excludeCh,'Value');
            sCh = get(app.synchCh,'Value');
            app.deleakedFlag = 0;       % this flag is set to 1 if SPGs have been deleaked.
            
            % Now the processing pipelines differentiate depending on the input mode.
            % 2019/05/25 Gab: modified because dataFormat is now a DropDown (it was a RadioButtonGroup previously)
            flagN   = strcmp(app.dataFormat.Value,'Default');
            flagL   = strcmp(app.dataFormat.Value,'EEG format');
            flagMV  = strcmp(app.dataFormat.Value,'Meyer VEP');
            flagSSP = strcmp(app.dataFormat.Value,'SEP');
            flagAxP = strcmp(app.dataFormat.Value,'AxoP');
            flagTRC = strcmp(app.dataFormat.Value,'TRC import');
            flagMEA = strcmp(app.dataFormat.Value,'IMM MEA');
            flagEDF = strcmp(app.dataFormat.Value,'EDF format');
            flagMat = strcmp(app.dataFormat.Value,'Matlab');
            
            flagEx  = app.excludeChFlag.Value;
            flagExSynch =  app.internalSynchCk.Value;
            flagSkipFirstTrial = app.skip1trialCk.Value;

            
            % This function opens a specified file either in ASCII or in binary format.
            % the variable nCh holds the number of channels present in the data file
            % the resulting LFP data will be a matrix of nSweeps columns.
            %%
            
            %% TRC data import
            if flagTRC
                % open the dialogue window for TRC data import
                % The TRCimport must be opened by passing the file path and it returns the structure TRC that contina
                % the data, all the associated info and the events.
                TRCoutput = cell([40 1]);
                [TRCoutput{1:40}] = importTRCfilesOptions(app.dir_in,app.file_in,app.dtaComplete);
                disp([num2str(toc) ' End of TRC import'])
                %TRCimportData = zebraguidata (TRCimportDialogue);
                % let's fill the data structure with the imported data
                set(app.microVck,'Value',1);   % select microV unit
            %         setGain (hObject, handles, 1000);
                app.expID = TRCoutput{1};
                nCh = TRCoutput{9};
                app.SPGlowDB = -80;                             % set limits for spectrogram plot
                set(app.lowerDB,'Value',app.SPGlowDB)
                app.SPGhighDB = 0;
                set(app.upperDB,'Value',app.SPGhighDB)
            
                setCh(app, nCh)
                app.dtaLen = TRCoutput{11};
                acqF = TRCoutput{12};
                setSP (app, acqF);
                app.nTrials = length(TRCoutput{26}) - 1;     % if no events nTrials = -1 !
                LFPin = zeros(app.nCh, app.dtaLen);      % preallocation of LFPin
                LFPin = TRCoutput{16};
                if (app.nTrials>1)
                    set (app.skip1trialCk,'value',0);       % no need to skip the first trial
                    stimTrigger (1:app.nTrials) = 0;        % preallocation of trigger
                    for j=1:app.nTrials
                        stimTrigger (j) = TRCoutput{26}(j+1).latency;
                        % the first trigger is skipped because it is associated to the
                        % beginning of the equiluminant grey and not to a chekerboard stimulus.
                    end
                    %Compute the inter-trial distance.
                    trialInterdistance (1:app.nTrials-1) = stimTrigger(2:app.nTrials)-stimTrigger (1:app.nTrials-1);
                    % Lets find the minimal inter trial distance
                    minLen = min(trialInterdistance);      % this will be the new app.dtaLen
                    % Let's extract the trials from the available data channels.
                    disp([num2str(toc) ' Extract trials from the data channels.'])
                    
                    % Initialize the variable size ENRICO 11/12/2018
                    temp = zeros(app.nCh, app.nTrials, minLen);
                    
                    for i=1:app.nCh
                        for j=1:app.nTrials
                            temp(i,j,1:minLen) = LFPin(i,stimTrigger(j):stimTrigger(j)+minLen-1);
                        end
                    end
                    app.LFP = [];
                    app.LFP = temp;
                    app.dtaLen = minLen;
                else
                    app.nTrials = 1;
                    app.LFP(1:app.nCh,1,1:app.dtaLen) = LFPin(1:app.nCh,1:app.dtaLen);
                end
            else
                % create an handle for the file opened in read mode
                fid=fopen(app.dtaComplete,'r');
            end
            %%
            
            %% Import NI or Axiopatch data
            if (flagN || flagL || flagAxP)
                % This is the default mode. Data from the NI or Axon board. nCh channels and only 1
                % trial. The trigger channel is included. The number of channels must
                % be defined at priori only for the NI boards
                set(app.mVck,'Value',1);
                if (flagAxP)
                    % in this modality data are generated by the Axopatch program and
                    % are saved as binary data. sp and Ch number obtained from data header
                    disp([num2str(toc) ' Begin loading of Axopatch data'])
                    [LFPin,si,h] = abfload(app.dtaComplete);
                    % si    scalar           the sampling interval in micro s
                    % h     struct           information on file (selected header parameters)
                    % number of channels
                    LFPin = LFPin';    % abfloat return the channels as columns. Traspose to set them as rows
                    setGain(app, 1000)
                    setSP(app, 1/si*1000000)
                    setCh(app, h.nADCNumChannels)
                    app.dtaLen = h.sweepLengthInPts;
                else
                    if flagL    % set some parameters specific to Laura's mode.
                        setSP(app, 512)
                        setGain(app, 5000)
                        setCh(app, 2)
                        set(app.synchCh, 'Value', 1)
                        set(app.SPGwindow,'Value',16)
                        set(app.SPGoverlap,'Value',2)
                    else
                        set(app.SPGwindow,'Value',256)
                        set(app.SPGoverlap,'Value',16)
                        app.nCh = get(app.chEdit,'Value');
                    end
                    if flagL
                        disp([num2str(toc) ' Begin loading of IN EEG data.'])
                        % this is required because these data use a ',' as separator of the
                        % decimal part. How stupid is this???
                        c = textscan(fid,'%s');
                        l = size(c{1});
                        app.dtaLen = l(1)/2-1;  % remove first row
                        LFPin = zeros(app.nCh,app.dtaLen);
                        for i=1:app.dtaLen
                            % replace the comma with the decimal point
                            for j=1:app.nCh
                                runningIndex = 2 + app.nCh * (i-1) + j;
                                LFPin (j,i) = str2double(strrep(c{1}{runningIndex},',','.'));
                            end
                        end
                    else
                        disp([num2str(toc) ' Begin loading of National Instruments EEG data.'])
                        % Create the string describing the data format
                        fmtSt='';
                        for ii=1:app.nCh
                           fmtSt = [fmtSt '%f'];
                        end
                        LFPin = fscanf(fid,fmtSt,[app.nCh,inf]);
%                         ----------
%                         faster and easier way for loading column-ordered files. Let's think about this...
%                         LFPin = readtable(app.dtaComplete); 
%                         LFPin = table2array(LFPin);
%                         ----------
                        l = size(LFPin);
                        app.dtaLen = l(2); 
                    end
                end
                %% the three submode of DEFAULT mode continue from here
                app.nTrials = 1;    % in this open mode there is only one trial unless
                % the trials are extracted later on by using the trigger channel
                app.SPGlowDB = -80;
                set(app.lowerDB,'Value',app.SPGlowDB)
                app.SPGhighDB = 0;
                set(app.upperDB,'Value',app.SPGhighDB)
                if flagEx && ~flagExSynch    % if ext synch the channel is excluded only from the output
                    if (app.nCh<=1),app.nCh=2;   % This situation occurs if the original data has 2 channels
                    end                                  % and one forgets to restore the ch number
                    % remove one channel
                    cnt = 0;
                    for i=1:app.nCh
                        if (i~= exCh), cnt = cnt+1;
                            app.LFP (cnt,1,:) = LFPin(i,:);
                        end
                    end
                    app.nCh = app.nCh-1;
                else
                    app.LFP (1:app.nCh,1,:) = LFPin(1:app.nCh,:);
                end
                if flagExSynch
%                    disp([num2str(toc) ' Extract single trials from a multitrial data set.'])
%                    maxTrigger = get(app.triggerMaxLength,'Value');
%                    indexTrials = [];
%                    trialInterdistance = [];
%                    temp = [];
%                    % first, analyze the synch channel to extract the timing of each trial
%                    % binarize the trigger channel
%                    for i=1:app.dtaLen
% %                        disp(num2str(i))
%                        if (app.LFP(sCh,1,i)<1), app.LFP (sCh,1,i) = 0;
%                        else
%                            app.LFP(sCh,1,i) = 10;
%                        end
%                    end
%                    [sortedSynch,sortedInd] = sort(app.LFP (sCh,1,:),'descend');
%                    % the indexes should be clusterized and the <t> of each cluster will
%                    % give the timing of each stimulus. What is left is easy.
%                    % Parse the sortedInd vector. A trigger event is represented by a cluster of
%                    % neighboring indexes.
%                    j = 1;   % index running on the sorted arrays
%                    flg = get(app.skip1trialCk,'Value');
%                    trialCounter = 0;
%                    sortI = sortedInd(j);
%                    oldSortI = 1;
%                    oldJ = 0;
%                    while sortedSynch(j)>1
%                        % The code handles two different trigger signals
%                        % The <yy 2018 implementation of Escher generates the trigger through
%                        % the DTR line of the RS232 serial port with levels on= +10V off= -10V
%                        % Later build wil use a digital output producing the ordinary TTL signal
%             
%                        % Legality of the trigger event
%                        % A trigger event is legal if it meets 3 conditions:
%                        % 1) it is longer than 30 sampling periods
%                        % 2) it is at least 100 sp far away from the previous trigger event
%                        % 3) it is shorter than triggerMaxLength (still in SP units)
%             
%             %           if (sum(sortedSynch(j:j+2))>29 && sortI>(oldSortI+100))
%                        if (sortI>(oldSortI+100) || trialCounter==0)
%                            % if this condition is true the new event is at least 100 sp away
%                            % from the previous event, or it is the first event
%                            % now we must measure its length
%                            len = 1;
%                            runningJ = j;
%                            while sortedInd(runningJ+1) == sortedInd(runningJ)+1
%                                % we are counting the SPs in the event
%                                len = len + 1;
%                                runningJ = runningJ+1;
%                            end
%                            % now test the event for legality
%                            if (len>2 && len<maxTrigger)
%                                % event is legal
%                                trialCounter = trialCounter + 1;
%                                indexTrials(trialCounter) = sortI;
%                                if (trialCounter>1)
%                                    if flg          % if true ignore the first trial
%                                        if (trialCounter>2)
%                                            % ignore the first trial during the computation
%                                            % of the trial duration
%                                            trialInterdistance(trialCounter-2) = sortI-indexTrials (trialCounter-1);
%                                        end
%                                    else
%                                        trialInterdistance(trialCounter-1) = sortI-indexTrials (trialCounter-1);
%                                    end
%                                end
%                                oldSortI = sortI;
%                            end
%                            j = runningJ;
%                        end
%                        j = j+1;
%                        sortI = sortedInd(j);
%                    end
%                    % Lets find the minimal inter trial distance
%                    minLen = min(trialInterdistance); % this will be the new app.dtaLen
%                    % Let's extract the trials from the available data channels.
%                    
%                    for i=1:app.nCh
%                         skipped = 0;
%                         for j=1:trialCounter
%                             if (indexTrials(j)+minLen-1)>app.dtaLen
%                                 % bad synch: the data finishes before of the end of the
%                                 % last sweep
%                                 trialCounter = trialCounter - 1;
%                             else
%                                 if (indexTrials(j)+app.timeOffset_i) > 0
%                                     temp(i,j-skipped,1:minLen) = app.LFP(i,1,indexTrials(j)+app.timeOffset_i:indexTrials(j)+minLen-1+app.timeOffset_i); %GAB 30/10/2018 added +timeOffset_i
%                                 else
%                                     skipped = skipped + 1;
%                                 end
%                             end
%                         end
%                    end
                   disp([num2str(toc) ' Extract single trials from a multitrial data set.'])
                    % first, analyze the synch channel to extract the timing of each trial
                    % binarize the trigger channel
                    for i=1:app.dtaLen
                    %   TTL protocol
                       if (app.LFP(sCh,1,i)<2), app.LFP (sCh,1,i) = 0;
                       else
                           app.LFP(sCh,1,i) = 5;
                       end
                    end
                    synchDiff = diff(app.LFP (sCh,1,:));
                    idxStart = find(synchDiff == 5);
                    idxStop  = find(synchDiff == -5);
                    ttlDuration = idxStop - idxStart ; % duration of ttl signal in samples
                    trialInterdistance = [diff(idxStart); inf]; % n of samples between two stimuli
                    % Legality of the trigger event
                       % A trigger event is legal if it meets 3 conditions:
                       % 1) it is longer than triggerMinLength (sampling periods)
                       % 2) it is at least 100 sp far away from the previous trigger event
                       % 3) it is shorter than triggerMaxLength (still in SP units)
                       % moreover: - the first trial must be entirely recorderd (offset included)
                    %              - the last trial must end before the end of the recording
                    legalTrig_logic = idxStart + app.timeOffset_i > 0 &...
                                ttlDuration > app.triggerMinLength.Value &...
                                ttlDuration < app.triggerMaxLength.Value &...
                                trialInterdistance > 100;
                    if legalTrig_logic(1)
                        alreadySkipped = 0;
                    else
                        alreadySkipped = 1; %firstTrial has been already skipped
                        % Don't store it in app now, do it later on
                    end
                    idxStart_legal = idxStart(legalTrig_logic);
                    
                    % Lets find the minimal inter trial distance
                    minLen = min(trialInterdistance(1:end-1)); % this will be the new app.dtaLen
                    
                    trialCounter = length(idxStart_legal);
                    if ((app.dtaLen - idxStart(end)) < 0) && legalTrig_logic(end)
                        %if the last stimulus is valid but the recording stops too soon,
                        % it means bad synch: the data finishes before the end of the last
                        % sweep. So, skip the last stimulus.
                        trialCounter = trialCounter - 1;
                    end
                    temp = nan(app.nCh,trialCounter,minLen);
                    for tr = 1:trialCounter
                        temp(:,tr,:) = app.LFP(:,1,idxStart_legal(tr)+app.timeOffset_i:idxStart_legal(tr)+minLen-1+app.timeOffset_i);
                    end
                    app.LFP = [];
                    app.LFP = temp;
                    app.nTrials = trialCounter;
                    app.expID = '';
                    app.dtaLen = minLen;
                end
                % Close the data file
                fclose(fid);
            end
            %%
            
            %% Import Meyer ascii VEP
            if (flagMV)
                disp([num2str(toc) ' Begin loading of Meyer ASCII data.'])
                setSP(app, 2048/get(app.SweepTimeEdit,'Value')) %GABRIELE 2018/01/27; frequency is calculated as trialPoints/trialTime
                % since the SP has been likely changed, the frequencies of the
                % bandwidht filters should be taken care of
                setGain(app, 1000)
                set(app.microVck,'Value',1)
                %setCh (hObject, handles, 3);
                numCh = app.chEdit.Value; %GABRIELE 2018/01/27; allows to open file whith any number of channels
                setCh(app, numCh)
                app.dtaLen = 2048;
                set(app.SPGwindow,'Value','32')
                set(app.SPGoverlap,'Value','2')
                c = textscan(fid,'%s');
                app.expID = [c{1}{1} ' ' c{1}{2}];
                % create an array with the indexes of all cells that contain the string
                % '2048'. The data will begin immediately after the last cell pointed by the
                % index array. The length of the array is equal to the number of trials*3.
                index = find(strcmp(c{:}, '2048'));
                l = size(index);
                app.nTrials = l(1)/numCh;
                startC = index(app.nTrials*numCh)+1;
                [endC,g] = size(c{:});
            
                % loop on all points of each trial
                for (j=1:app.dtaLen)    %GABRIELE 2018/01/27; allows to open file whith any number of channels
                    % loop on all trials
                    for i=1:app.nTrials
                        % run on the entire cell array
                        runningIndex = startC + (j-1)*app.nTrials*numCh + (i-1)*numCh;
                        % first, replace all the ',' with the decimal point
                        for k=1:numCh
                            app.LFP (k,i,j) = str2double(strrep(c{1}{runningIndex+k-1},',','.'));
                        end
                    end
                end
                % Close the data file
                fclose(fid);
            end
            %%
            
            %% Load somatosensory potentials
            if (flagSSP)
                % Better be safe...
                setSP(app, 32768);
                setGain(app, 1000);
                set (app.microVck,'Value',1);
                c = textscan(fid,'%s');
                app.expID = [c{1}{1} ' ' c{1}{2}];
                % create an array with the indexes of all cells that contain the string
                % '1639'. The data will begin immediately after the last cell pointed by the
                % index array. The length of the array is equal to the number of trials*3.
                index = find(strcmp(c{:}, '1639'));
                l = size(index);
                app.nCh = l(1);
                app.nTrials = 1;
                app.dtaLen = 1639;
            
                % startC point to the beginning of the data
                startC = index(app.nCh)+1;
                [endC,g] = size(c{:});
            
                % loop on all points of each trial
                for j=1:app.dtaLen
                    % loop on all channels
                    for i=1:app.nCh
                        % run on the entire cell array
                        runningIndex = startC + (j-1)*app.nCh + (i-1);
                        app.LFP (i,1,j) = str2double(strrep(c{1}{runningIndex},',','.'));
                    end
                end
                % Now remove the artefact that sometimes pops out at the very beginning of the record
                clsW = int32(app.dtaLen/10);
                fixIt(1:app.nCh) = app.LFP(1:app.nCh,1,clsW);
                for k=1:app.nCh
                    app.LFP(k,1,1:clsW) = fixIt(k);
                end
                app.workLFP = app.LFP;    % Copied here since there is nothing to filter in these data
                % Close the data file
                fclose(fid);
            end
            %%
            
            
            %% Import EDF eeg format
            if flagEDF
                disp([num2str(toc) ' Begin loading of EDF data.'])                
                [hdr, recIn] = edfread(app.dtaComplete);
                setSP(app, hdr.frequency(1));               
                % since the SP has been likely changed, the frequencies of the
                % bandwidht filters should be taken care of
                setGain(app, 1000)
                set(app.microVck,'Value',1)
                setCh(app, size(recIn,1));
                app.dtaLen = length(recIn);
%                set(app.SPGwindow,'Value','32')
%                set(app.SPGoverlap,'Value','2')
                app.nTrials = 1;
                for k=1:app.nCh
                    app.LFP (k,1,:) = recIn(k,:);
                end
            end
            %% end of specific loading routines
            % 
            disp([num2str(toc) ' End of data loading.'])
            
            
            %% Open IMM mea data
            if (flagMEA)
                disp([num2str(toc) ' Begin loading of IMM MEA data.'])
                setGain(app, 1000);
                % the data are contained in a structure called channelPlotData
                load(app.dtaComplete)
                app.nCh = 32;
                set (app.chEdit,'Value','32');
            
                name = fieldnames(channelPlotData);
                % extract the sampling period from channel0 data structure
                sp = channelPlotData.channel0.time(1,2);
                % NOTE: this SP is only approximate. THIS SHOULD BE FIXED!
                sp = 0.0001003394;
                setSP(app, 1/sp)
                % preallocate the input array
                fullDataSet = zeros(app.nCh,1,length(channelPlotData.(name{1}).value));
                % load all the channels
                for i=1:app.nCh
                    % run on the entire cell array
                    tmp = channelPlotData.(name{i}).value;
                    fullDataSet (i,1,:) = tmp(:);
                end
            
                if flagExSynch
                    disp([num2str(toc) ' Extract single trials from a multi-trials data set.'])
                    % extract the single trials
                    tmp = [];
                    stimOrigin = channelPlotData.Stimulation.time(1,6);
                    traceOrigin = channelPlotData.channel0.time(1,1);
                    stimTimes = channelPlotData.Stimulation.time(:,6) - traceOrigin;
                    app.nTrials = length(stimTimes);
                    intervals = stimTimes(2:app.nTrials)-stimTimes(1:app.nTrials-1);
                    app.dtaLen = floor(min(intervals)/app.sp + 1);
                    pnt = app.dtaLen;
                    % prealocate LFP
                    app.LFP = zeros(app.nCh,app.nTrials,pnt);
                    % now extract the data segments
                    for j=1:app.nTrials
                       beginIndex = floor(stimTimes(j)/app.sp + 1);
                       for i=1:app.nCh
                            app.LFP(i,j,1:pnt) = fullDataSet (i,1,beginIndex:beginIndex+pnt-1);
                            % filter the initial and final transient (trigger artifact)
                            app.LFP(i,j,1:10) = app.LFP(i,j,20:-1:11);
                            app.LFP(i,j,pnt:-1:pnt-30) = app.LFP(i,j,pnt-30:-1:pnt-60);
                       end
                    end
                else
                    app.LFP = fullDataSet;
                    app.nTrials = 1;
                    app.dtaLen = length(app.LFP);
                end
                app.expID = '';
            end
            
            %% gab&Enrico trial concatenation
            if app.concat_ck.Value
                if isempty(app.storedLFP)
                    app.storedLFP = app.LFP(:,flagSkipFirstTrial-alreadySkipped+1:end,:);
                    % store alreadySkipped in app: we have to save only the
                    % one relative to the first loaded file.
                    app.alreadySkipped = alreadySkipped;
                else
                    storeDim = size(app.storedLFP);
                    if storeDim(1) ~= app.nCh %quality control
                        error('Mismatch in the number of channels')
                    end
                    storeLen = min([storeDim(3), app.dtaLen]);
                    app.storedLFP = cat(2, app.storedLFP(:,:,1:storeLen), app.LFP(:,flagSkipFirstTrial-alreadySkipped+1:end,1:storeLen));
                    % and do not overwrite app.alreadySkipped
                end
            else
                app.storedLFP = [];
                app.alreadySkipped = alreadySkipped; % store alreadySkipped in app
            end
            
            if app.analyzeWhileConc_ck.Value
                concatenateTrials(app)
            elseif ~app.concat_ck.Value
                openFile_partII(app)
            end
            %% end of specific loading routines
            % -----------------------------------------------------------------------------------------
            disp([num2str(toc) ' End of data loading.'])
        end
        
        function concatenateTrials(app)
            app.LFP = app.storedLFP;
            app.dtaLen = size(app.LFP,3);
            app.nTrials = size(app.LFP,2);
            openFile_partII(app)
        end
    
        function openFile_partII(app)
            
            %%--flag copied from OpenFile function-----------------------------------
            exCh = app.excludeCh.Value;
            sCh = app.synchCh.Value;
            
            flagEx  = app.excludeChFlag.Value;
            flagExSynch =  app.internalSynchCk.Value;
            flagSkipFirstTrial = app.skip1trialCk.Value;
            
            % Now the processing pipelines differentiate depending on the input mode.
            % 2019/05/25 Gab: modified because dataFormat is now a DropDown (it was a RadioButtonGroup previously)
            flagN   = strcmp(app.dataFormat.Value,'Default');
            flagL   = strcmp(app.dataFormat.Value,'EEG format');
            flagMV  = strcmp(app.dataFormat.Value,'Meyer VEP');
            flagSSP = strcmp(app.dataFormat.Value,'SEP');
            flagAxP = strcmp(app.dataFormat.Value,'AxoP');
            flagTRC = strcmp(app.dataFormat.Value,'TRC import');
            flagMEA = strcmp(app.dataFormat.Value,'IMM MEA');
            flagEDF = strcmp(app.dataFormat.Value,'EDF format');
            flagMat = strcmp(app.dataFormat.Value,'Matlab');
            %%-----------------------------------------------------------------------
            
            % now set the values for the ch and trial sliders based on the values
            % defined in the preceding code. If ch and trials are = 1 the control is
            % not updated and is made not visible.
            app.trialsEdit.Value = app.nTrials;
            app.currentTrial = 1;
            %set (app.chEdit,'String',app.nCh);
            app.currentCh = 1;
            if app.nTrials>1
                app.trialSlider.Limits = [1 app.nTrials];
                app.trialSlider.Value = 1;
                app.trialSlider.Visible = 'on';
                app.trialText.Visible = 'on';
                app.trialText.Text = ['Trial ' num2str(app.currentTrial)];
            else
                app.trialSlider.Visible = 'off';
                app.trialText.Visible = 'off';
            end
            
            if app.nCh>1 % If the number of channels >1, the channel slider must be shown, together with a text that states the current channel
                app.chSlider.Limits = [1 app.nCh];
                app.chSlider.Value = 1;
                %set(app.chSlider, 'SliderStep', [1/(app.nCh-1) 1/(app.nCh-1)])
                app.chSlider.Visible = 'on';
                app.ChText.Visible = 'on';
                app.ChText.Text = ['Ch ' num2str(app.currentCh)];
                % set the slider control in the PWpower distribution figure
                if app.analyzePWdistributionCk.Value
                    app.analyzePWdata.selectCh.Max = app.nCh;
                    app.analyzePWdata.selectCh.Value = 1;
                    app.analyzePWdata.selectCh.SliderStep = [1/(app.nCh-1) 1/(app.nCh-1)];
                    app.analyzePWdata.selectCh.Visible = 'on';
                    app.analyzePWdata.selectedChTxt.Visible = 'on';
                    app.analyzePWdata.selectedChTxt.String = ['Ch ' num2str(app.currentCh)];
                end
            else
                app.chSlider.Visible = 'off';
                app.ChText.Visible = 'off';
                if app.analyzePWdistributionCk.Value
                    app.analyzePWdata.selectedChTxt.Visible = 'off';
                    app.analyzePWdata.selectCh.Visible = 'off';
                end
            end
            
            %% also set channel and trial slider in the SingleUnit App, if it is open
            if ~isempty(app.SUapp)
                app.SUapp.setSpinners();
            end
            
            app.fileInNum = app.fileInNum + 1;
            drawnow    % refresh the GUI
            
            % Apply gain
            cfactor = 1000/app.gainAmp;      % conversion factor to express LFP in mV
            app.LFP = app.LFP * cfactor;            % scale the LFP for the relative gain
                        
            % Compute the time vector called app.x
            app.x = 0+app.timeOffset:app.sp:double(app.sp*(app.dtaLen-1))+app.timeOffset; %GAB 2019/02/04: offset added
            % set t axes
            app.tmin = app.x(1); %GAB: now tmin is no more 0: let's consider the offset
            app.tmax = app.x(end); %GAB: now tmin is no more dtaLen*sp: let's consider the offset
            app.tminTxt.Value = app.tmin;
            app.tmaxTxt.Value = app.tmax;
            
            % break point here if you want to access the loaded data before computing the
            % band-passed data set.
            if ~flagSSP
                disp([num2str(toc) ' Begin computation of band passed data.'])
                computeBPandNotch(app)   % elements of handles will be modified at this call
                computePowerInTime(app)   % temporal profile of power
            end
            disp([num2str(toc) ' Compute the data spectrograms.'])
            computeSpectrogram(app)
            
            % Update the status "led" indicator to GREEN because now band passed
            % LFP and BPpower are computed according to the settings
            % that are visible in the user interface
            app.BPsynch_led1.Color = 'g';
            app.BPsynch_led2.Color = 'g';
            
            % ---------------------------------------------------------------------------------------------------------------------
            
            disp([num2str(toc) ' Passing data to the active external processor.'])
            % now verify whether the external processors are active and, if they are, call
            % them up passing them the proper data.
            
            if app.powerSpectraCk.Value
                % call the ZebraSpectre code for spectra computation
                % we are here after opening a new data. Set the proper default values in the ZebraSpectre GUI
%                 Enrico 20190410
%                 All the settings for the spectra time window calculation
%                 have been commented out, so the spectra are calculated on
%                 the default values that are in the zebraspectre figure
%                 app.spectreApp.fromTtxt.Value = 0;      % beginning of the time window for spectre computation
%                 app.spectreApp.toTtxt.Value = app.tmax; % end of the time window for spectre computation
%                     
%                 app.spectreApp.fromT2txt.Value = 0;      % beginning of the second time window for spectre computation
%                 app.spectreApp.toT2txt.Value = app.tmax; % end of the second time window for spectre computation
            
                % ZebraSpectre('computeSpectra_Callback',app.spectreData.computeSpectra,[],app.spectreData)
                %computePowerSpectra (hObject, handles);
                app.spectreApp.computeSpectra_function() % Calculate and plot the spectrum
            end
            
            if app.analyzePWdistributionCk.Value
                analyzePowerDistribution(app)
            end
            
            if app.nTrials > 1
                disp([num2str(toc) ' Compute averages on trials.'])
                % compute al relevant averages. First, mean LFP
                % compute mean band passed data
                % compute mean spectrogram and 'gamma' band power
                computeMeanOnTrials(app)
            end
            
            % Insert a breakpoint here if you want to access directly to the averaged
            % trials. app.meanLFP (nCh, 1:app.dtaLen)
            
            
            disp([num2str(toc) ' Plot data.'])            
            app.ymaxTxt.Value = inf;  % reset main axes y limits
            app.yminTxt.Value = -inf; % reset main axes y limits
            plotWideWindow(app)
            plotMultiBand(app)
            plotSpectrogram(app)
            
            disp([num2str(toc) ' Create summary table.'])
            % SUMMARY TABLE
            % compute the total RMS power in the five bands
            if ~flagSSP
                app.lpRMS = 0;
                app.bp1RMS = 0;
                app.bp2RMS = 0;
                app.bp3RMS = 0;
                app.hpRMS = 0;
                for i = 1:app.nCh
                    if ~(flagExSynch && i==sCh) % if ext synch the channel is excluded from the output
                        if ~(flagExSynch && flagEx && i==exCh) % exclude channel
                            for j = 1:app.nTrials
                                app.cnt = app.cnt + 1;
                                if app.BPcomputed(1)
                                    tmp(1:app.dtaLen) = app.bandPassed_LFP(1,i,j,1:app.dtaLen);
                                    app.lpRMS = rms(tmp);
                                end
                                if app.BPcomputed(2)
                                    tmp(1:app.dtaLen) = app.bandPassed_LFP(2,i,j,1:app.dtaLen);
                                    app.bp1RMS = rms(tmp);
                                end
                                if app.BPcomputed(3)
                                    tmp(1:app.dtaLen) = app.bandPassed_LFP(3,i,j,1:app.dtaLen);
                                    app.bp2RMS = rms(tmp);
                                end
                                if app.BPcomputed(4)
                                    tmp(1:app.dtaLen) = app.bandPassed_LFP(4,i,j,1:app.dtaLen);
                                    app.bp3RMS = rms(tmp);
                                end
                                if app.BPcomputed(5)
                                    tmp(1:app.dtaLen) = app.bandPassed_LFP(5,i,j,1:app.dtaLen);
                                    app.hpRMS = rms(tmp);
                                end
                                % now we place this data in a summary table that will be loaded with a
                                % number of fun fact about the data...
                                % data must be converted to the cell format in the following statements
            
                                app.dtaSummary(app.cnt,1) = cellstr(app.dir_in);
                                app.dtaSummary(app.cnt,2) = cellstr(app.file_in);
                                if app.autoMode
                                    app.dtaSummary(app.cnt,3) = cellstr('0');
                                else
                                    app.dtaSummary(app.cnt,3) = cellstr(app.fileTime);
                                end
                                app.dtaSummary(app.cnt,4) = num2cell(i);
                                app.dtaSummary(app.cnt,5) = num2cell(j);
                                app.dtaSummary(app.cnt,6) = num2cell(app.lpRMS);
                                app.dtaSummary(app.cnt,7) = num2cell(app.bp1RMS);
                                app.dtaSummary(app.cnt,8) = num2cell(app.bp2RMS);
                                app.dtaSummary(app.cnt,9) = num2cell(app.bp3RMS);
                                app.dtaSummary(app.cnt,10) = num2cell(app.hpRMS);
                                app.dtaSummary(app.cnt,11) = cellstr('Comments here');
                            end
                        end
                    end
                end
                app.outable.Data = app.dtaSummary;
            else
                % Put here something if you want to compute 'some' metric of the SSP
                % power...
            end
        end
        
        function outBPpowers(app, bpSel, timeFrom, timeTo, fileName)
            
            % process BP1
            i_from = floor((timeFrom-5*app.BPpowerSP(bpSel)) / app.BPpowerSP(bpSel));
            if (i_from <=1), i_from = 1;
            end
            i_to = ceil((timeTo-5*app.BPpowerSP(bpSel)) / app.BPpowerSP(bpSel));
            if (i_to > length(app.BPpower)), i_to = length(app.BPpower);
            end
            
            tmpOut = [];
            fmtSt = [];
            % the time axis is identical for all channels
            tmpOut(1,1:i_to-i_from+1) = ...
                app.BPpower (1,bpSel,1,app.currentTrial,i_from:i_to);
            % iterate on all channels
            for ii=1:app.nCh      % add 1 for the x axis.
                tmpOut(ii+1,1:i_to-i_from+1) = ...
                    app.BPpower (2,bpSel,ii,app.currentTrial,i_from:i_to);
            end
            % prepare for output
            for ii=1:app.nCh      % add 1 for the x axis.
               fmtSt=[fmtSt '%10.8f '] ;
            end
            fmtSt=[fmtSt '%10.8f\n'];       % start new line
            fileOut = [app.dir_in fileName];
            fid = fopen(fileOut,'w');
            fprintf(fid,fmtSt,tmpOut)
            fclose(fid);
        end
        
    
        function plotMultiBand(app)
            % Here we plot the multi band window according to the selected bands
            flagSSP = strcmp(app.dataFormat.Value,'SEP');
            
            if (~flagSSP)
                tmp = [];
                cla(app.wide_plot_HP)
                hold(app.wide_plot_HP, 'on')
                flagTr = app.plotTrCk.Value;
                flagMd = app.plotMeanFlag.Value;
                
                % 2019/05/26 Gab: all BPs in a for loop
                for selBP = 1:size(app.frequencyBand,1)
                    if app.BP_4_plot(selBP) && app.BPcomputed(selBP)
                        if flagTr
                            tmp(1:app.dtaLen) = app.bandPassed_LFP(selBP,app.currentCh, app.currentTrial, 1:app.dtaLen);
                            plot(app.wide_plot_HP, app.x(1:app.dtaLen),tmp(1:app.dtaLen),'Color',app.BP_color(selBP,:),'LineWidth',0.5);
                        end
                        if flagMd
                            tmp(1:app.dtaLen) = app.meanBP(selBP, app.currentCh,1:app.dtaLen);
                        plot(app.wide_plot_HP, app.x(1:app.dtaLen),tmp(1:app.dtaLen),'Color',app.BP_color(selBP,:),'LineWidth',2);
                        end
                    end
                end
                
                axis(app.wide_plot_HP, [app.tmin app.tmax -inf inf])
                app.wide_plot_HP.XTick = [];
                flagUnits = app.microVck.Value;
                if flagUnits, app.wide_plot_HP.YLabel.String = 'LFP (microV)';
                else
                    app.wide_plot_HP.YLabel.String = 'LFP (mV)';
                end
            end
        end
        
        function plotWideWindow(app)
            %% The plot function requires a column vector. If the data is a matrix, the X axis moves
            % along the row. If more than n>1 columns are present, the function will
            % plot the various data in different colours.
            
            % Find out the index of the left and right limits of the plot window
            tMin = app.tminTxt.Value;
            tMax = app.tmaxTxt.Value;
            % convert the limits to array indexes
            tMinI = floor(tMin/app.sp+1 - app.timeOffset_i); %GAB 2019/02/24: offset added
            tMaxI = floor(tMax/app.sp+1 - app.timeOffset_i); %GAB 2019/02/24: offset added
            % quality control
            if tMinI <= 1, tMinI = 1;
            end
            
            if tMaxI > app.dtaLen, tMaxI = app.dtaLen;
            end
            
            app.minLFP = min(app.LFP(app.currentCh, app.currentTrial,tMinI:tMaxI));
            app.maxLFP = max(app.LFP(app.currentCh, app.currentTrial,tMinI:tMaxI));
            traceOut = [];
            
            % axes(app.wide_plot_up)
            cla(app.wide_plot_up)
%            app.wide_plot_up.ColorOrderIndex = 0; % Not sure what this is for. Enrico 11/10/2018
            hold(app.wide_plot_up, 'on')
            
            flagMd = app.plotMeanFlag.Value;     % plot mean trace
            flagMP = app.plotMultiCh.Value;      % plot all channels
            flagTr = app.plotTrCk.Value;         % plot single trials
            flagAutoOff = app.autoOffCk.Value;
            flagTempl = app.plotTemplateCk.Value;
            
            if (flagAutoOff==0), range = 0;  % take this out if you want to auto-offset the averages
            else
                range = (app.maxLFP-app.minLFP)/3;
            end
            
            if flagMP       % plot all channels
                % if multi channel no template!
                
                % 2019/05/26 Gab: possibility to differently rescale channels in the multiplot
                scalingFactors = app.PlotScaleTab.Data(:,2);
                traceOut(1:app.nCh,1:app.dtaLen) = app.LFP(1:app.nCh,app.currentTrial,1:app.dtaLen) .* scalingFactors;
                for i=1:app.nCh      % add a vertical offset
                    traceOut(i,1:app.dtaLen) = (i-1)*range + traceOut(i,1:app.dtaLen);
                end
                % next: plot multiplot with the same colors
                app.wide_plot_up.ColorOrderIndex = 1;
                app.wide_plot_up.NextPlot = 'add';
                if flagTr   % plot the individual trials
                    % the additional properties are necessary to stop the plot to
                    % intercepts the mouse clicks
                    plot(app.wide_plot_up, app.x(tMinI:tMaxI),traceOut(:, tMinI:tMaxI), 'HitTest', 'off');
                end
                if (flagMd && app.nTrials > 1)  % plot all means with offset
                    % 2019/05/26 Gab: possibility to differently rescale channels in the multiplot
                    traceOut(1:app.nCh,1:app.dtaLen) = app.meanLFP(1:app.nCh,1:app.dtaLen) .* scalingFactors;
                    for (i=1:app.nCh)
                        traceOut(i,1:app.dtaLen) = (i-1)*range + traceOut(i,1:app.dtaLen);
                    end
                    app.wide_plot_up.ColorOrderIndex = 1;
                    plot(app.wide_plot_up, app.x(tMinI:tMaxI),traceOut(:, tMinI:tMaxI),'LineWidth',2, 'HitTest', 'off');
                end
            
                % next: plot multiplot with the same colors
                %axx = gca;
            else
                if flagTr   % plot the individual trials
                    traceOut(1:app.dtaLen) = app.LFP(app.currentCh,app.currentTrial,1:app.dtaLen);
                    plot(app.wide_plot_up, app.x(tMinI:tMaxI),traceOut(tMinI:tMaxI),'Color','k', 'HitTest', 'off');
                end
                if (flagMd && app.nTrials > 1)
                    plot(app.wide_plot_up, app.x(tMinI:tMaxI),app.meanLFP(app.currentCh,(tMinI:tMaxI)),'Color','k',...
                        'LineWidth', 2, 'HitTest', 'off');
                end
                if (flagTempl && app.nTrials>1 && flagTr)
                    % plot the template for each response with the correct scaling
                    % factor and offset
                    l = length(app.templ);
                    t0 = app.templateFrom.Value;
                    for ii= 1:app.ntemp
                        a = app.EPamplitude(app.currentCh,app.currentTrial,ii);
                        templ = a * app.templ(app.currentCh,:) + app.EPoffset(app.currentCh,app.currentTrial,ii);
                        x0 = t0 + app.dtaLen*app.sp/2 * (ii-1);
                        xend = x0 + l*app.sp;
                        xtempl = x0:app.sp:xend;
                        plot (app.wide_plot_up, xtempl(1:l), templ(1:l),'LineWidth',2, 'HitTest', 'off');
                    end
                end
            end
            flagUnits = app.microVck.Value;
            if flagUnits, app.wide_plot_up.YLabel.String = 'LFP (microV)';
            else
                app.wide_plot_up.YLabel.String = 'LFP (mV)';
            end
%             if isempty(event) %Gab: set y axis limits
%                 axis(app.wide_plot_up, [tMin tMax -inf inf]);
%                 app.ymaxTxt.Value = app.wide_plot_up.YLim(2);
%                 app.yminTxt.Value = app.wide_plot_up.YLim(1);
%             else
%                 axis(app.wide_plot_up, [tMin tMax event.yLim(1) event.yLim(2)]);
%             end
            axis(app.wide_plot_up, [tMin, tMax, app.yminTxt.Value, app.ymaxTxt.Value]);
        end
        
        
        function setCh(app, nCh)
            app.nCh = nCh;
            set (app.chEdit,'Value',app.nCh);
            app.currentCh = 1;
            chEdit_Fcn(app,event) %Gab, 2019/05/29
        end
        
        function setGain(app, gain)
            app.gainAmp = gain;
            set(app.gain,'Value', app.gainAmp);
        end
        
        function setSP(app, freq)
            % This function set the sampling period, frequency and all the relative numers in the GUI
            app.sp = 1/freq;        % sampling period in s
            app.acqF = freq;        % sampling frequency in Hz
            set (app.spEdit,'Value',app.sp);
            app.frequencyBand(5,2) = 0.5*freq;  %GAB 22/05/2018
        end
        
        function startSpectraPlot(app)
            % this function causes the refresh of the spectra plots by activating a
            % callback in the ZebraSpectre figure
            
            %GAB 11/03/2018
            %   if the condition is not true, it means that Spectre window is open but
            %   spectra have not been calculated (and plotted) already.
            if ~isempty(app.spectreApp.powerSpectra.Children)
                %ZebraSpectre('maxFreq_Callback',app.spectreData.maxFreq,[],app.spectreData)
                app.spectreApp.plotPowerSpectra()
            end
        end
        
        function templUpdateFunction(app)
            inf_i = round(app.hRoi.Vertices(1,1)/app.sp)+1 - app.timeOffset_i; %index of the roi interval left edge
            sup_i = round(app.hRoi.Vertices(3,1)/app.sp)+1 - app.timeOffset_i; %index of the roi interval right edge
            [~,p100_i] = min(app.meanLFP(app.currentCh,inf_i:sup_i));
            p100_i = p100_i +inf_i-1;
            p100_t = (p100_i-1)*app.sp + app.timeOffset;
            bef = str2double(app.p100bef.String);
            aft = str2double(app.p100after.String);
            app.templateFrom.String = num2str(p100_t-bef);
            app.templateTo.String = num2str(p100_t+aft);
        end


        %% eventDetection functions:::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::
        function plotStatesFun(app)
            len = app.dtaLen;
            spp = app.sp;
            app.evDet.tmin = app.leftTime.Value;
            app.evDet.tmax = app.rightTime.Value;
            
            %axes(app.plotStates);
            cla(app.plotStates)
            %hold on
            %time = 0:spp:spp*(len-1);
            time = app.x; %GAB 2019/02/24
            plot(app.plotStates,time,app.evDet.LFP,'black');
            
            for usi=1:app.evDet.NUS
               i1 = app.evDet.upstates.fromI(usi);
               i2 = app.evDet.upstates.toI(usi);
               plot(app.plotStates,time(i1:i2),app.evDet.LFP(i1:i2),'red');
            end
            
            for usi=1:app.evDet.NDS
               i1 = app.evDet.downstates.fromI(usi);
               i2 = app.evDet.downstates.toI(usi);
               plot(app.plotStates,time(i1:i2),app.evDet.LFP(i1:i2),'green');
            end
            app.plotStates.XLim = [app.evDet.tmin app.evDet.tmax];
            app.plotStates.YLim = [-inf inf];
            %axis ([app.evDet.tmin app.evDet.tmax -inf inf])
            
            %axes(app.statesTrack);
            set(app.statesTrack, 'XTick', []);
            cla(app.statesTrack)
            %hold on
            plot (app.statesTrack,app.evDet.timeSPG,app.evDet.SPG);
            app.statesTrack.XLim = [app.evDet.tmin app.evDet.tmax];
            app.statesTrack.YLim = [-inf inf];
            %axis ([app.evDet.tmin app.evDet.tmax -inf inf])
            %hold off
        end
        
        function refineEdges(app)    
            erodeFlag = get (app.erodeCk,'Value');
            if erodeFlag
                % now refine the edges of the states
                for usi=1:app.evDet.NUS
                    % check the edges of the US: if they are farther away from the median
                    % than 2*SD the event is eroded to 2*SD. Check first left edge.
                    edgeI = app.evDet.upstates.fromI(usi);
                    value = tempData(edgeI);
                    while ~(abs(value-app.evDet.upstates.medianV(usi))<0.5*app.evDet.upstates.SD(usi))
                        % erode to the right
                        if edgeI==app.dtaLen, break
                        end
                        edgeI = edgeI+1;
                        app.evDet.upstates.fromI(usi) = edgeI;
                        value = tempData(edgeI);
                        % check not to have passed the end of the US!
                    end
                    edgeI = app.evDet.upstates.toI(usi);
                    value = tempData(edgeI);
                    while ~(abs(value-app.evDet.upstates.medianV)<2*app.evDet.upstates.SD)
                        if edgeI>1
                            % erode to the left
                            edgeI = edgeI-1;
                            app.evDet.upstates.toI(usi) = edgeI;
                            value = tempData(edgeI);
                            % check not to have passed the beginning of the US!
                        else
                            break
                        end
                    end
                    % now update the upstate definition
                    app.evDet.upstates.toT(usi) = (app.evDet.upstates.toI(usi)-1)*spp;
                    app.evDet.upstates.fromT(usi) = (app.evDet.upstates.fromI(usi)-1)*spp;
                    app.evDet.upstates.deltaT(usi) = app.evDet.upstates.toT(usi)-app.evDet.upstates.fromT (usi);
            
                    app.evDet.upstates.medianV(usi) = median(tempData(app.evDet.upstates.fromI(usi):app.evDet.upstates.toI(usi)));
                    app.evDet.upstates.SD(usi) = std(tempData(app.evDet.upstates.fromI(usi):app.evDet.upstates.toI(usi)));
                end
            end
        end
    
        
        function DeleteRowTable(app,mytable,SelIndices)
            % Enrico
            mytable.Data(min(SelIndices(:,1)):max(SelIndices(:,1)) , : ) = [];
        end
        
        function BPsynch(app,idx)
            % 2019/05/26 Gab.
%             This function updates the variables relative to band pass windows when BPs are edited.
%                 idx: if this function is called because a group of BPs has been deleted from the
%                             delete button (DeleteBpBtn), idx represents the indices of the removed BPs
%                      if this function is called because the number of BPs has been edited (by NumberofBPsEditField),
%                             idx value takes is Inf, meaning that changes regard the last BPs
%                      if this function is called by the startupFcn or when a BP window is edited in the table,
%                             idx = 0. In this mode BP_color and BP_4_plot are not changed


        % update app variables related to BPs
            app.frequencyBand_ck = app.BpTable.Data(:,2);
            app.frequencyBand = app.BpTable.Data(:,3:4);
            
        % by default, the enabled BPs are selected for being plotted
            app.BP_4_plot = app.frequencyBand_ck;
            
        % adjust the dropDown object for BP plot selection
            oldBPs = size(app.BPplot_drop.Items,2); % the old number of BPs
            nBP = app.NumberofBPsEditField.Value; % the new number of BPs
            newItems = [ repmat('BP ',nBP,1), num2str((1:nBP)','% 2u') ];
            app.BPplot_drop.Items = cellstr(newItems);
            if nBP < oldBPs
                app.BPplot_drop.Value = app.BPplot_drop.Items{end};
            end
            
        % adjust the dropDown object for BP selection in EventDetection
            app.detectionBP_dropDown.Items = [cellstr(newItems);{'SPG envelope'}];
            if nBP < oldBPs
                app.BPplot_drop.Value = app.BPplot_drop.Items{end};
            end
            % also adjust the spinner for the Multipeak Waves analysis
            if nBP > 1
                app.BPforMultipeakWaves_spinner.Limits(2) = nBP;
                app.BPforMultipeakWaves_spinner.Enable = 1;
            else
                app.BPforMultipeakWaves_spinner.Value = 1;
                app.BPforMultipeakWaves_spinner.Enable = 0;
            end

        % update the upper limit of the slider for the selection of the
        % BP to be used for the analysis of the power during response
            app.sliderBPpower.Limits(2) = nBP;
            
        % Update the number of columns in the first ZebraSays table
            %Update column names
            oldColNames = app.outable.ColumnName; % store old names
            oldData = app.outable.Data; % store old values
            txt = repmat(' RMS',nBP,1);
            BPcolNames = [newItems, txt]; % append the string 'RMS' to the BP names
            newColNames = [oldColNames(1:5);cellstr(BPcolNames);oldColNames(end)];
            app.outable.ColumnName = newColNames;
            
            % Update the table content
            if ~isempty(app.outable.Data)
                switch idx(1)
                    case 0
                        % do nothing
                    case inf
                        % the number of BPs has been edited
                        if nBP < oldBPs
                            % we have less BPs now. "Cut" the table
                            newData = [oldData(:,1:5+nBP), oldData(:,end)];
                            app.outable.Data = newData;
                        elseif nBP > oldBPs
                            % we have more BPs now. Add their columns
                            % before the last one and also move the comments to the last one.
                            newData = [oldData, cell(size(oldData,1),nBP-oldBPs)]; % add new empty columns
                            newData(:,size(oldData,2)) = cell(size(oldData,1),1); % remove the comments from the uncorrect location
                            newData(:,end) = oldData(:,end); % copy the comments in the last column
                            app.outable.Data = newData;
                        end
                    otherwise
                        % some BPs have been removed, so remove its column
                        newData = oldData;
                        newData(:,idx) = [];
                        app.outable.Data = newData;
                end
            end
            
            
        % manage BP colors:
            switch idx(1)
                case 0
                    % do nothing
                case inf
                    % the number of BPs has been edited
                    newColors = [];
                    if nBP < oldBPs
                        % we have less BPs now. "Cut" the color and selection arrays
                        newColors = app.BP_color(1:nBP,:);
                        app.BP_color = newColors;
                        
                    elseif nBP > oldBPs
                        % new BPs have been added. Assign default colors to them
                        
                        % First, if the number of BPs used is more than the default BP colors,
                                                % add grey entries to BP_color_default
                        defCols = size(app.BP_color_default,1);
                        if nBP > defCols
                            newColors = ones(nBP,3).*0.5;
                            newColors(1:defCols,:) = app.BP_color_default;
                            app.BP_color_default = newColors;
                        end
                        
                        newColors = [app.BP_color; app.BP_color_default(oldBPs+1:nBP,:)];
                        app.BP_color = newColors;
                    end
                otherwise
                    % a BP has been removed, so remove it also from the color
                    % matrix
                    app.BP_color(idx,:)  = [];
            end
            
            % Update the status "led" indicator
            switch idx(1)
                case {0 inf}
                    % turn the led RED. You need to call computeBP to
                    % synchronize Zebra with the current changes
                    %         % Set sunchronizationLED color to red
                    app.BPsynch_led1.Color = 'r';
                    app.BPsynch_led2.Color = 'r';
                otherwise
                    % do nothing: when a BP is removed, the corresponding
                    % bandPassed_LFP and BPpower are removed too. LED remain unchanged
            end
        end
        
    end
    
    methods (Access = public)
        
        function SelTab2Clip(app,mytable,selectedIndices)
%         selectedIndices is a vector [a b c d], in which a and b are the
%         row and column number of the top-left element of the selection. c
%         and d are the row and column number of the bottom-right element.

%         To copy the data from the table of zebrasays to the clipboard
            str = '';
            for i = selectedIndices(1):selectedIndices(3)
                for j = selectedIndices(2):selectedIndices(4)
                    if j == selectedIndices(4)
                        if isa(mytable.Data{i,j}, 'char')
                            str = sprintf('%s', str, mytable.Data{i,j});
                        else
                            str = sprintf('%s%f',str,mytable.Data{i,j});
                        end
                    else
                        if isa(mytable.Data{i,j}, 'char')
                            str = sprintf('%s\t', str, mytable.Data{i,j});
                        else
                            str = sprintf('%s%f\t',str, mytable.Data{i,j});
                        end
                    end
                end
                str = sprintf('%s\n',str);
            end
            clipboard('copy',str);
        end
        
        function BP_4_plot_updateFcn(app,tab,event)
            app.BP_4_plot = event.Source.Data;
            % Find the selected BP
            selBP = find(strcmp(app.BPplot_drop.Value,app.BPplot_drop.Items));
            % Update the Selection button
            app.BPplot_ck.Value = app.BP_4_plot(selBP);
            % Update BP and Power plot
            if app.fileInNum
                plotMultiBand(app)
                if app.BP.Value
                    plotSpectrogram(app)
                end
            end
        end
        

        function chEdit_Fcn(app,event)
            app.nCh = app.chEdit.Value;
            
            if app.nCh>1
                %set(app.chSlider, 'SliderStep', [1/(app.nCh-1) 1/(app.nCh-1)])
                set(app.ChText,'Text',['Ch ' num2str(app.currentCh)])
                set(app.chSlider, 'Limits', [1 app.nCh])
                set(app.chSlider, 'Value', 1)
            else
                set(app.chSlider,'Visible','off')
                set(app.ChText,'Visible','off')
            end
            app.currentCh = 1;
            
            % Gab 2019/05/29 Update the table of chanels' gain (to be implemented soon) and the
            % table of scale factors for the multichannel plot
            if ~isempty(event)
                oldCh = event.PreviousValue; % get the old number of channels
            else
                oldCh = 0;
            end
            if app.nCh < oldCh
                % "cut" the old table
%                 app.chGainTable.Data = app.chGainTable.Data(1:app.nCh,:); % to be implemented
                app.PlotScaleTab.Data = app.PlotScaleTab.Data(1:app.nCh,:);
            else
                % add new entries. New gain values are set to 1 by default
%                 app.chGainTable.Data = [app.chGainTable.Data; [(oldCh+1:app.nCh)', ones(app.nCh-oldCh,1)] ]; % to be implemented
                app.PlotScaleTab.Data = [app.PlotScaleTab.Data; [(oldCh+1:app.nCh)', ones(app.nCh-oldCh,1)] ];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             Determine the operating system
            if ismac
                % Code to run on Mac platform
                app.platformType = 'mac';
            elseif isunix
                % Code to run on Linux platform
                app.platformType = 'lin';
            elseif ispc
                % Code to run on Windows platform
                app.platformType = 'win';
            else
                disp('Platform not supported')
            end
            
            app.sp = app.spEdit.Value; % sampling period in s
            app.acqF = 1/app.sp; % sampling frequency
            app.insetW = 5; % width of the inset window (s)
            app.lp = 0.1; % width of the low pass filter window % MIGHT BE UNUSED
            app.dir_in = '';
            app.gainAmp = app.gain.Value; % gain setting of the amplifier
            app.nCh = app.chEdit.Value; % number of channels
            app.alreadySkipped = 0;
            
            %2019/05/26 gab: build up the tables of the channels' gain values and multiplot scale factors
%             app.chGainTable.Data = [(1:app.nCh)', ones(app.nCh,1)]; % to be implemented soon
            app.PlotScaleTab.Data = [(1:app.nCh)', ones(app.nCh,1)];
            
            app.nTrials = app.trialsEdit.Value; % number of trials
            app.currentCh = app.chSlider.Value; % current channel
            
            sliderBPpower_Callback(app,[])
            
            app.filterOrder = app.filtOrder.Value; % butterworth filter order
            app.notch = [app.notchFrom.Value app.notchTo.Value]; % notch filter for 50 Hz removal
%             app.frequencyBand = [0 app.lpTo.Value; app.alphaFrom.Value app.alphaTo.Value;...
%                 app.betaFrom.Value app.betaTo.Value; app.gammaFrom.Value app.gammaTo.Value;...
%                 app.hpFrom.Value 0.5/app.sp; 0 app.leakageLP.Value];
            app.BpTable.Data = [1, 1,    0,   8;...
                                2, 1,  0.5,   4;...
                                3, 1,    9,  25;...
                                4, 1,   40, 100;...
                                5, 1,  300, Inf];
            app.NumberofBPsEditField.Value = size(app.BpTable.Data, 1); % This is the number of bands
            app.BP_color_default = [  0,      0,      0;... %black   for BP1
                                      0,      1,      1;... %cyan    for BP2
                                      1,      0,      0;... %red     for BP3
                                      0,      1,      0;... %green   for BP4
                                      0,      0,      1;... %blue    for BP5
                                      1,      0,      1;... %magenta for BP6
                                      1,      1,      0;... %yellow  for BP7
                                    0.5,    0.5,    0.5];   %grey    for BP8
            app.BP_color = app.BP_color_default(1:app.NumberofBPsEditField.Value,:);
            app.BP_4_plot = ones(app.NumberofBPsEditField.Value,1);
            BPsynch(app,0) % store the info of BpTable in app.frequencyBand and app.frequencyBand_ck

            app.notchOrder = 3; % order 6 for the notch filter % MIGHT BE UNUSED
            app.xnow = -999;
            
            app.fileInNum = 0; % keep track of the number of analyzed files for the output table
            app.eventNum = 0; % keep track of the number of analyzed events
            app.cnt = 0; % counter for full trace summary table
            app.EPcnt = 0; % counter for the summary table of evoched responses
            
            % the following structure is required for passing the proper parameters to
            % the Chronux routines.
            app.params = struct('tapers',[1 2],'Fs',0.001,'fpass',[0 10],'pad',0,'err',[]);
            
            % app.dtaSummary = struct('f1','file name','f2',1,'f3',2,'f4',3,'f5',4,'f6','Comments');
            
            % Definition of a cell array to store the output table
            % The fields are consistent with te GUIDE definition of outable provided in
            % the ZebraSays figure.
            % dtaSummary holds the complete file name time of file last modification
            % (it corresponds to the original creation time) and the RMS powers in the 5 bands
            
            app.dtaSummary = {'dir' 'file' 'time' 1 2 3 4 5 6 7 'notes'};
            
            app.eventSummary = {'dir' 'file' 1 2 3 4 5 6 7 8 9 10 11 12 13 'news'};
            % Cell array for the detected events
            % Field description:
            % 1: event number in the given file
            % 2: time at the centre of the event
            % 3: duration of the event
            % 4-6: RMS power of the baseline in the three bands
            % 7-9: RMS power of the event
            % 10-12: power normalised to the baseline
            %
            % 14: news and views (editable text)
            
            app.respSummary = {'file' 1 2 3 4 5 6 7 8 9 10 'news'};
            % Cell array for trial responses: field description
            % 1: file name
            % 2: channel number
            % 3: trial number
            % 4: repeat number
            % 5: Peak response
            % 6: time to peak
            % 7: Mean BP1 baseline power (Db)
            % 8: Mean BP1 response power (Db)
            % 9: normalized power (8-7)
            % 10: BP1 power multiplied by the scaled template
            % 11: comments
            
            app.dtaPWsummary = {'dir' 'file' 'time' 4 5 6 7 8 9 10 11 12 13};
            app.dtaPWlocalSummary = {'dir' 'file' 'time' 4 5 6 7 8 9 10 11 12 13};
            % Cell array for power distribution: field description
            % 1: file name
            % 2: channel number
            % 3: time of data file creation
            % 4: channel
            % 5: trial
            % 6: band pass index (1-4 corresponding to BP1-3 and HP
            % 7: Shannon entropy
            % 8: main mode: mean log of power
            % 9: main mode: std
            % 10: main mode: relative area
            % 11: secondary mode: mean of log of power
            % 12: secondary mode: std
            % 13: secondary mode: relative area

            % Initialize timeOffset variable
            app.timeOffset = app.trTimeOffset_txt.Value;
            % All sliders must be invisible in the beginning
            set([app.ChText app.chSlider app.trialText app.trialSlider],'Visible','off')
            
            
            %GAB
            app.autoMode = 0;
            app.storedLFP = [];
            
            %%Initialize ZebraAuto variables
            app.auto.AutoDlgIn = [];
        end

        % Callback function
        function ROI4template_bt_Callback(app, event)
            if isprop(app,'hRoi')
                delete(app.hRoi);
            end
            app.hRoi = drawrectangle(app.wide_plot_up);
            templUpdateFunction(app);
        end

        % Value changed function: SPGlogCk
        function SPGlogCk_Callback(app, event)
            plotSpectrogram(app)
        end

        % Selection changed function: SPGmode
        function SPGmode_SelectionChangeFcn(app, event)
            plotSpectrogram(app)
        end

        % Callback function
        function SweepTimeEdit_Callback(app, event)
            %GABRIELE 2018/01/27: useful for setting trial time in MeyerVEP mode
            app.sp = get(app.SweepTimeEdit.Value)/2048;
            set(app.spEdit,'Value',app.sp);
            app.acqF = 1/app.sp;
        end

        % Value changed function: TrRej_ck
        function TrRej_ck_Callback(app, event)
            if get(hObject,'Value')
                app.trialRej=trialRejection;
                app.trialRejData=zebraguidata(app.trialRej);
            else
                close(app.trialRej)
                app.trialRejData=[];
            end
        end

        % Callback function
        function alphaFrom_Callback(app, event)
            app.frequencyBand(2,1) = app.alphaFrom.Value;
        end

        % Callback function
        function alphaTo_Callback(app, event)
            app.frequencyBand(2,2) = app.alphaTo.Value;
        end

        % Value changed function: analyzePWdistributionCk
        function analyzePWdistributionCk_Callback(app, event)
            
            if app.analyzePWdistributionCk.Value
                % open the child process
                app.analyzePW = analyzePWdistribution(app);      % handle to the child figure
            else
                % close the child process
                close(app.analyzePW);
                app.analyzePW = [];
            end
        end

        % Value changed function: analyzeWhileConc_ck
        function analyzeWhileConc_ck_Callback(app, event)
            % hObject    handle to analyzeWhileConc_ck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of analyzeWhileConc_ck
            if hObject.Value
                app.concat_ck.Value = 1;
            end
        end

        % Value changed function: autoOffCk
        function autoOffCk_Callback(app, event)
            if (app.fileInNum>0), plotWideWindow(app)
            end
        end

        % Callback function
        function betaFrom_Callback(app, event)
            app.frequencyBand(3,1) = app.betaFrom.Value;
        end

        % Callback function
        function betaTo_Callback(app, event)
            app.frequencyBand(3,2) = app.betaTo.Value;
        end

        % Callback function
        function bpCheck1_Callback(app, event)
            if (app.fileInNum), plotMultiBand(app)
                plotSpectrogram(app)
            end
        end

        % Callback function
        function bpCheck2_Callback(app, event)
            if (app.fileInNum), plotMultiBand(app)
                plotSpectrogram(app)
            end
        end

        % Callback function
        function bpCheck3_Callback(app, event)
            if (app.fileInNum), plotMultiBand(app)
                plotSpectrogram(app)
            end
        end

        % Value changed function: chEdit
        function chEdit_Callback(app, event)
            app.chEdit_Fcn(event)
        end

        % Value changed function: chSlider, chSlider_2
        function chSlider_Callback(app, event)
            app.currentCh = app.chSlider.Value;
            set(app.ChText,'Text',['Ch ' num2str(app.currentCh)])
            
            plotWideWindow(app)
            plotMultiBand(app)
            plotSpectrogram(app)
            if get(app.powerSpectraCk,'Value')
                startSpectraPlot(app)
            end
        end

        % Value changed function: checkbox_sleepscoring
        function checkbox_sleepscoring_Callback(app, event)
            % Didi October 2018: open the sleep scoring program to score NREM sleep
            % episodes in the australian data, based on delta/theta ratio, and
            % determine power in delta band in these episodes
            
            if app.checkbox_sleepscoring.Value
                 % open the program
                 app.S_SC = sleepscoring(app);
                 app.S_SCData = zebraguidata(app.S_SC);
            
             else
                 close(app.S_SC)
            end
        end

        % Button pushed function: clearConc_btn
        function clearConc_btn_Callback(app, event)
            % hObject    handle to clearConc_btn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.storedLFP = [];
        end

        % Button pushed function: computeBP, computeBP_2
        function computeBP_Callback(app, event)
            %% This function computes and plots the filtered data. It must be evoked after
            % changing any filter settings to update the filtered data.
            % May 7, 2017.
            % I have added the temporal profile of the power in BP1, BP2, BP3 and HP
            
            if (app.fileInNum) % of course the following code is executed only if a data has been loaded
                computeBPandNotch(app) 
                computePowerInTime(app)  % temporal profile of power
                plotMultiBand(app)
                computeSpectrogram(app)
                plotSpectrogram(app)
                
                disp('Computation of band passed data completed.')
                
                % Update the status "led" indicator to GREEN because now band passed
                % LFP and BPpower are computed according to the settings
                % that are visible in the user interface
                app.BPsynch_led1.Color = 'g';
                app.BPsynch_led2.Color = 'g';
            end
        end

        % Button pushed function: concat_btn
        function concat_btn_Callback(app, event)
            % hObject    handle to concat_btn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            concatenateTrials(app)
        end

        % Value changed function: concat_ck
        function concat_ck_Callback(app, event)
            % hObject    handle to concat_ck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if app.concat_ck.Value
                app.concat_btn.Enable = 'on';
            else
                app.concat_btn.Enable = 'off';
            end
        end

        % Value changed function: dataFormat
        function dataFormatPanel_SelectionChangeFcn(app, event)
%              2019/05/25, Gab: Converted from a RadioButtonGroup to a DropDown
%             leak = get(app.leakageLP,'Value');

            newFmt = app.dataFormat.Value; % selected format
            if strcmpi(newFmt,'EEG format')
                setSP(app, 512);
                setCh(app, 2);
                setGain(app, 5000);
                set (app.EEGck,'Value',1);
                app.SPGlowDB = -80;
                set(app.lowerDB,'Value',app.SPGlowDB);
                app.SPGhighDB = -30;
                set(app.upperDB,'Value',app.SPGhighDB);
            
                % set the correct spectral bands
                % app.frequencyBand = [0 4; 0.5 4; 12 16; 30 80; 100 0.5/app.sp; 0 leak];
                app.BpTable.Data = [1   1   0   4;
                                    2   1   0.5 4;
                                    3   1   12  16;
                                    4   1   30  80;
                                    5   1   100 inf];
            end
            
            if strcmpi(newFmt,'Meyer VEP')
                % set all required parameters
                %setSP (hObject, handles, 4096);
                setSP(app, 2048/get(app.SweepTimeEdit,'Value')); %gab
                setCh(app, 3)
                setGain(app, 1000)
                set(app.microVck,'Value',1)
                set(app.EEGck,'Value',1)
                app.SPGlowDB = -30; %gab
                set(app.lowerDB,'Value',app.SPGlowDB)
                app.SPGhighDB = -10; %gab
                set(app.upperDB,'Value',app.SPGhighDB)
            
                % set the correct spectral bands
%                 app.frequencyBand = [0 4; 0.5 4; 12 16; 30 80; 100 0.5/app.sp; 0 leak];
                app.BpTable.Data = [1   1   0   4;
                                    2   1   0.5 4;
                                    3   1   12  16;
                                    4   1   30  80;
                                    5   1   100 inf];
                                
                %GABRIELE 2018/01/27
                set(app.spEdit,'Enable','off')
                set(app.SweepTimeEdit,'Enable','on')
            else
%                 if strcmpi(event.OldValue.Text,'Meyer VEP') %meyerVEP has been deselected
                    set(app.spEdit,'Enable','on')
                    set(app.SweepTimeEdit,'Enable','off')
%                 end
            end
            
            if strcmpi(newFmt,'SEP')
                % set all required parameters
                setSP(app, 32768)
                setCh(app, 2)
                set(app.microVck,'Value',1)
                setGain(app, 1000)
                set(app.EEGck,'Value',1)
                app.SPGlowDB = -80;
                set(app.lowerDB,'Value',app.SPGlowDB)
                app.SPGhighDB = -30;
                set(app.upperDB,'Value',app.SPGhighDB)
            
                % this mode does not perform spectral analysis
            end
            % now update app.frequancyBand vector
            BPsynch(app)
        end

        % Value changed function: displayLeakCk
        function displayLeakCk_Callback(app, event)
            plotSpectrogram(app)
        end

        % Value changed function: gain
        function gain_Callback(app, event)
            app.gainAmp = app.gain.Value;
        end

        % Callback function
        function gammaFrom_Callback(app, event)
            app.frequencyBand(4,1) = app.gammaFrom.Value;
        end

        % Callback function
        function gammaTo_Callback(app, event)
            app.frequencyBand(4,2) = app.gammaTo.Value;
        end

        % Value changed function: hit_ck
        function hit_ck_Callback(app, event)
            % Hint: get(hObject,'Value') returns toggle state of hit_ck
            if get(hObject,'Value')
                app.responsometer = Responsometer(app);
                %app.responsometerData = zebraguidata(app.responsometer);
            else
                % close the child process
                close(app.responsometer);
                %app.responsometerData = [];
                app.responsometer = [];
            end
        end

        % Callback function
        function hpCheck_Callback(app, event)
            if (app.fileInNum), plotMultiBand(app)
                plotSpectrogram(app)
            end
        end

        % Callback function
        function hpFrom_Callback(app, event)
            app.frequencyBand(5,1) = app.hpFrom.Value;
        end

        % Value changed function: leakageLP
        function leakageLP_Callback(app, event)
            app.frequencyBand(6,2) = get(hObject,'Value');
        end

        % Button pushed function: loadParams_btn
        function loadParams_btn_Callback(app, event)
            paramsStruct = [];
            uiopen('*.mat');
            if isempty('paramsStruct')
                return
            end
            app.paramsStruct = paramsStruct;
            for i = 1 : height(paramsStruct.mainParamsTable)
%                 i
%                 if i==24
%                     disp('eccoci')
%                 end
                app.(paramsStruct.mainParamsTable.Name(i)).Value = paramsStruct.mainParamsTable.Value(i);
                if ~isempty(app.(paramsStruct.mainParamsTable.Name(i)).ValueChangedFcn)
                    try
                        app.(paramsStruct.mainParamsTable.Name(i)).ValueChangedFcn(app.(paramsStruct.mainParamsTable.Name(i)),[])
                    catch ME
                        warning('off','backtrace')
                        warning(strcat("Error while executing ", paramsStruct.mainParamsTable.Name(i), "'s Callback"))
                        warning(ME.message)
                        disp(newline)
                    end
                end
            end
            
            if isfield(paramsStruct, 'spectreParamsTable')
                disp(['spectre debugging' newline])
                app.spectreApp.loadParamsSpectre()
                disp([newline newline newline])
            
            end
            
            warning('on','backtrace')
            % load handel
            % sound(y,Fs)
            disp('params loaded!')
        end

        % Value changed function: lowerDB
        function lowerDB_Callback(app, event)
            app.SPGlowDB = app.lowerDB.Value;
            if (app.fileInNum>0), plotSpectrogram(app)
            end
        end

        % Callback function
        function lpTo_Callback(app, event)
            app.frequencyBand(1,2) = app.lpTo.Value;
        end

        % Callback function
        function lpcheck_Callback(app, event)
            if (app.fileInNum), plotMultiBand(app)
                plotSpectrogram(app)
            end
        end

        % Value changed function: multiChAnalyzer
        function multiChAnalyzer_Callback(app, event)
            
            if get(hObject,'Value')
                % open the child process
                app.xCorr = multiChannelAnalyzer(app, []);  % handle to the multi channel analyzer
                app.xCorrData = zebraguidata(app.xCorr);
            else
                close(app.xCorr);
            end
        end

        % Value changed function: notchFilter
        function notchFilter_Callback(app, event)
            computeBP_Callback(app, event)
            if app.powerSpectraCk.Value
                computeSpectra_function(app.spectreApp)
            end    
        end

        % Value changed function: notchFrom
        function notchFrom_Callback(app, event)
            app.notch(1) = app.notchFrom.Value;
        end

        % Value changed function: notchTo
        function notchTo_Callback(app, event)
            app.notch(2) = app.notchTo.Value;
        end

        % Button pushed function: openDir
        function openDir_Callback(app, event)
            flagN = get(app.normalDataRB,'Value');
            if (flagN), fmtSt = '*.dat';
            end
            dirArray = {'name' 'date' 'bytes' 'isdir' 'datenum'};
            fileInfo = {'name' 'date' 'bytes' 'isdir' 'datenum'};
            selection = uigetdir('C:\Users\Gix\Documents\Work\Science\')
            if selection ~= 0
                fileName = '\ltp001.dat';
                dirArray = dir([selection '\Dir_*']);
                ndir = length(dirArray);
                for i=1:ndir
                    path_in = [selection '\' dirArray(i).name];
                    fileInfo = dir([path_in fileName]);
                    timeNum = datestr(fileInfo.datenum);
                    app.fileTime = timeNum (13:20);
                    openFile(app,fileName,path_in)
                end
            end
        end

        % Value changed function: plotEnvelopeCk
        function plotEnvelopeCk_Callback(app, event)
            if (app.fileInNum), plotMultiBand(app)
            end
        end

        % Value changed function: plotMeanFlag
        function plotMeanFlag_Callback(app, event)
            if app.fileInNum>0
                if app.plotMeanFlag.Value
                    computeMeanOnTrials(app) %Gab 2019/05/26
                end
                plotWideWindow(app)
                plotMultiBand(app)
                plotSpectrogram(app)
                if get(app.powerSpectraCk, 'Value')
                    app.spectreApp.plotPowerSpectra()
                end
            end
        end

        % Value changed function: plotMultiCh
        function plotMultiCh_Callback(app, event)
            if (app.fileInNum>0)
                plotWideWindow(app)
                plotSpectrogram(app)
                if get(app.powerSpectraCk, 'Value')
                    startSpectraPlot(app)
                end
            end
        end

        % Value changed function: plotTrCk
        function plotTrCk_Callback(app, event)
            if app.fileInNum>0 
                plotWideWindow(app)
                plotMultiBand(app)
                plotSpectrogram(app)
                if get(app.powerSpectraCk, 'Value')
                    app.spectreApp.plotPowerSpectra()
                end
            end
        end

        % Value changed function: powerSpectraCk
        function powerSpectraCk_Callback(app, event)
            if app.powerSpectraCk.Value
                % open the child process
                app.spectreApp = ZebraSpectreApp(app);  % handle to the power spectra window
            else
                app.spectreApp.delete
                app.spectreApp = [];
            end
        end

        % Selection changed function: predefinedBW
        function predefinedBW_SelectionChangeFcn(app, event)
            % select default values for bandpass filters
            y = get(hObject,'Tag');     % obtain the tag to the selected button
            leak = get(app.leakageLP,'Value');
            
            if strcmp(y, 'zebraCk')
                app.frequencyBand = [0 8; 0.4 4; 4 8; 8 12; 100 0.5/app.sp; 0 leak];
                % now update the edit fields
            end
            
            if strcmp(y, 'EEGck')
                app.frequencyBand = [0 4; 0.5 4; 6 12; 12 30; 100 0.5/app.sp; 0 leak];
            end
            
            if strcmp(y, 'mouseCk')
                app.frequencyBand = [0 8; 0.5 4;6 12; 30 80; 100 0.5/app.sp; 0 leak];
            end
            
            if strcmp(y, 'NewcastleBand')
                app.frequencyBand = [0 8; 0.5 4; 20 60; 70 300; 300 0.5/app.sp; 0 leak];
            end
            
            % update the edit fields
            set(app.lpTo,'Value',app.frequencyBand (1,2))
            set(app.alphaFrom,'Value',app.frequencyBand (2,1))
            set(app.alphaTo,'Value',app.frequencyBand (2,2))
            set(app.betaFrom,'Value',app.frequencyBand (3,1))
            set(app.betaTo,'Value',app.frequencyBand (3,2))
            set(app.gammaFrom,'Value',app.frequencyBand (4,1))
            set(app.gammaTo,'Value',app.frequencyBand (4,2))
            set(app.hpFrom,'Value',app.frequencyBand (5,1))
        end

        % Button pushed function: recomputeSPG
        function recomputeSPG_Callback(app, event)
            computeSpectrogram(app)
            if (app.nTrials>1), computeMeanOnTrials (app);
            end
            % warning: if trial >1 the means have not been recomputed!
            plotSpectrogram(app)
        end

        % Button pushed function: saveBP
        function saveBP_Callback(app, event)
            % Save as an ascii file the current view of the wide window
            % If multi plot is on, all channels are saved as separate columns,
            % otherwise it is saved only the current channel.
            % This can be used to save only a small portion of the record depending on
            % the X limits of the visualisation.
            
            % Data are saved with the following order (GAB):
            % 1) Single trial, multi channels
            % col 1. time   ( NOT  Full data (with notch filter, if applied) )
            % col 2-5. the four band width
            % repeat for all channels if the multi channel flag is selected
            
            % 2) Multi trial, multi channels (GAB, not checked)
            % December 8, 2017
            % The button saves only the bandpassed data that are visualized and that refer
            % to the current channel. I have implemented two different functioning modes:
            % 2.1) if Plot mean checkbox is selected, the program saves only the mean
            % 2.2) if Plot mean is checked the program saves all trials as separate
            % columns. If more than one bandwidth is specifiedthey are alla grouped
            % together.
            
            fileOut = [app.dir_in 'currentTraceBP.dat'];
            % check what data to save (single trial or mean over trials, single Ch or multi Ch)
            flagMd = get(app.plotMeanFlag,'Value');
            flagMulti = get(app.plotMultiCh,'Value');
            tmpOut = [];
            % determine the initial and final index of the visualized data
            lim = axis(app.wide_plot_up);
            ifrom = lim(1)/app.sp + 1 - app.timeOffset_i; %gab 2019/02/24: offset added
            ito = lim(2)/app.sp + 1 - app.timeOffset_i; %gab 2019/02/24: offset added
            if (ito>app.dtaLen), ito = app.dtaLen;
            end
            tmpOut(1,:) = app.x (1:(ito-ifrom+1));
            cntOut = 1;
            ct = app.currentTrial;
            for i=1:app.nCh
                % plot on all available channels.
                if (app.currentCh == i || flagMulti)
                    % this channel has to be saved out
                    if (app.nTrials>1 && flagMd)
                        % save the mean data and BP data. If flagMd is false save only
                        % the current trace.
                        cntOut=cntOut+1;
                        dimMeanBP=size(app.meanBP);
                        %tmpOut (cntOut,1:(ifrom-ito+1)) = app.meanPWS (1:app.nCh,1:(ifrom-ito+1));
                        tmpOut (cntOut:cntOut+dimMeanBP(1)-1,1:(ito-ifrom+1)) = app.meanBP(:,app.currentCh,ifrom:ito); %GAB
                    else
                        % save data from currentTrial
                        cntOut=cntOut+1;
                        tmpOut (cntOut,1:(ito-ifrom+1)) = app.workLFP(i,ct,ifrom:ito);
                        % save the band passed data
                        tmpOut (cntOut+1:cntOut+4,1:(ito-ifrom+1)) = app.bandPassed_LFP(1:4,i, ct, ifrom:ito);
                        cntOut=cntOut+4;
                    end
                end
            end
            % create the format descriptor
            fmtSt='';
            for ii=1:cntOut-1
               fmtSt=[fmtSt '%10.8f '];
            end
            fmtSt=[fmtSt '%10.8f\n'];       % start new line
            
            fid = fopen(fileOut,'w');
            fprintf (fid,fmtSt,tmpOut);
            fclose(fid);
        end

        % Button pushed function: saveCurrentCh
        function saveCurrentCh_Callback(app, event)
            % Save as an ascii file the current view of the wide window
            % If multi plot is on, all channels are saved as separate columns,
            % otherwise it is saved only the current channel.
            % This can be used to save only a small portion of the record depending on
            % the X limits of the visualisation.            
            fileOut = [app.dir_in 'currentTrace.dat'];
            % check what data to save (single trial or mean over trials, single Ch or multi Ch)
            flagMd = get(app.plotMeanFlag,'Value');
            flagMulti = get(app.plotMultiCh,'Value');
            tmpOut = [];
            % determine the initial and final index of the visualized data
            lim = axis(app.wide_plot_up);
            ifrom = lim(1)/app.sp + 1 - app.timeOffset_i; %gab 2019/02/24: offset added
            ito = lim(2)/app.sp + 1 - app.timeOffset_i; %gab 2019/02/24: offset added
            if (ito>app.dtaLen), ito = app.dtaLen;
            end
            if flagMd
                % save all channels of the mean trace. Reserve one column for the
                % ordinates.
                tmpOut (2:app.nCh+1,1:(ito-ifrom+1)) = app.meanLFP (1:app.nCh,ifrom:ito);
            else
                % save the spectra of all channels of the current trial
            %    tmpOut (2:app.nCh+1,1:(ito-ifrom+1)) = app.workLFP (1:app.nCh,app.currentTrial,ifrom:ito);
                tmpOut (2:app.nCh+1,1:(ito-ifrom+1)) = app.workLFP (1:app.nCh,app.currentTrial,ifrom:ito);
            end
            tmpOut(1,:) = app.x (1:(ito-ifrom+1));
            % create the format descriptor
            fmtSt = '';
            for ii=1:app.nCh      % add 1 for the x axis.
               fmtSt = [fmtSt '%8.6f '];
            end
            fmtSt = [fmtSt '%8.6f\n'];       % start new line
            
            fid = fopen(fileOut,'w');
            fprintf (fid,fmtSt,tmpOut);
            fclose(fid);
        end

        % Button pushed function: saveParams_btn
        function saveParams_btn_Callback(app, event)
            app.paramsStruct = struct;
            
            propList = string(properties(app)); % get all the properies of the main app as string vector
            propListLogic = false(size(propList)); % initialize a logic vector, that will be used to tell apart checkboxes and edit fields from the rest
            
            for i=1:length(propList)
                if isobject(app.(propList(i))) && isprop(app.(propList(i)),'Type') % only isprop is not enough
                    if strcmp(app.(propList(i)).Type,'uinumericeditfield') || strcmp(app.(propList(i)).Type,'uicheckbox')
                        propListLogic(i) = true;
                    end
                end
            end
            
            paramsTable = table('size', [sum(propListLogic), 2],'VariableTypes', {'string', 'double'}, 'VariableNames', {'Name', 'Value'});
            paramsTable.Name = propList(propListLogic); % list of only checkboxes and editable textboxes
            
            % Extract all the names, values and callbacks of the checkboxes and editable textboxes of the main App
            for i=1:height(paramsTable)
                paramsTable.Value(i) = app.(paramsTable.Name(i)).Value;
            end
            
            app.paramsStruct.mainParamsTable = paramsTable;
            
            if app.powerSpectraCk.Value
                % if zebraspectre is open, ZebraSpectreApp saves its parameter in the table spectreParamsTable and adds it to the structure app.paramsStruct
		saveParamsSpectre(app.spectreApp)
            end
            
            paramsStruct = app.paramsStruct;
            
            [file,path] = uiputfile('*.mat','Save parameters file','Parameters.mat');
            
            save([path file],'paramsStruct')
        end

        % Button pushed function: saveSPG
        function saveSPG_Callback(app, event)
            % Save as an ascii file the current view of the SPG, envelope, RMS power and E/I index
            % If plotMean is on it saves the computed means otherwise it saves the current trace.
            % This can be used to save only a small portion of the record depending on
            % the X limits of the visualisation.
            
            % check what data to save (single trial or mean over trials, single Ch or multi Ch)
            flagMd = get(app.plotMeanFlag,'Value');
            %flagMulti = get(app.plotMultiCh,'Value'); yet unusued
            
            % determine the initial and final index of the visualized data
            lim = axis(app.wide_plot_up);
            timeFrom = lim(1);
            timeTo = lim(2);
            
            % process the RMS powers of the BP1-3, HP.
            % since each file may have different time axis they are saved as separate
            % files
            
            outBPpowers (app, 2, timeFrom, timeTo, 'BP1pw.dat');
            outBPpowers (app, 3, timeFrom, timeTo, 'BP2pw.dat');
            outBPpowers (app, 4, timeFrom, timeTo, 'BP3pw.dat');
            outBPpowers (app, 5, timeFrom, timeTo, 'HPpw.dat');
            
            % process the E/I ratio first, the envelope second
            SPGmin = app.spgt(1);
            SPGmax = app.spgt(app.spgl);
            resolutionSPG = (SPGmax-SPGmin)/(app.spgl-1);
            SPGfrom = int32(timeFrom/resolutionSPG)+1 - app.timeOffset_i; %gab 2019/02/24: offset added
            SPGto =  int32(timeTo/resolutionSPG)+1 - app.timeOffset_i; %gab 2019/02/24: offset added
            
            if (SPGto>app.spgl), SPGto = app.spgl;
            end
            tmpOut = [];
            
            if flagMd
                % save all channels of the mean E/I ratio. Reserve one column for the
                % time coordinate.
                tmpOut (2:app.nCh+1,1:(SPGto-SPGfrom+1)) = app.meanEIrat (1:app.nCh,SPGfrom:SPGto);
            else
                % save the E/I ratio of all channels of the current trial
                tmpOut (2:app.nCh+1,1:(SPGto-SPGfrom+1)) = app.EIrat (1:app.nCh,app.currentTrial,SPGfrom:SPGto);
            end
            c(1,:) = app.spgt(:);
            % create the format descriptor
            fmtSt='';
            for ii=1:app.nCh      % add 1 for the x axis.
               fmtSt= [fmtSt '%10.8f '];
            end
            fmtSt=[fmtSt '%10.8f\n'];       % start new line
            fileOut = [app.dir_in 'EIratio.dat'];
            fid = fopen(fileOut,'w');
            fprintf (fid,fmtSt,tmpOut);
            fclose(fid);
            
            % process the envelope
            if flagMd
                % save all channels of the mean E/I ratio. Reserve one column for the
                % time coordinate.
                tmpOut (2:app.nCh+1,1:(SPGto-SPGfrom+1)) = app.meanSpgPlot (1:app.nCh,SPGfrom:SPGto);
            else
                % save the E/I ratio of all channels of the current trial
                tmpOut (2:app.nCh+1,1:(SPGto-SPGfrom+1)) = app.spgPlot (1:app.nCh,app.currentTrial,SPGfrom:SPGto);
            end
            
            fileOut = [app.dir_in 'SPGplot.dat'];
            fid = fopen(fileOut,'w');
            fprintf (fid,fmtSt,tmpOut);
            fclose(fid);
        end

        % Callback function
        function screenCaptureBtn_Callback(app, event)
            flag = get(hObject,'Value');
            if flag, scButtonOn(app);
            else
                scButtonOff(app)
            end
        end

        % Button pushed function: scrollLeft
        function scrollLeft_Callback(app, event)
            
            delta = app.tmax - app.tmin;
            app.tmin = app.tmin - delta;
            if app.tmin<0, app.tmin=0;
            end
            app.tmax = app.tmin + delta;
            set(app.tminTxt,'Value',app.tmin);
            set(app.tmaxTxt,'Value',app.tmax);
            plotWideWindow(app)
            plotMultiBand(app)
            plotSpectrogram(app)
        end

        % Button pushed function: scrollRight
        function scrollRight_Callback(app, event)
            
            delta = app.tmax - app.tmin;
            app.tmin = app.tmax;
            app.tmax = app.tmax + delta;
            set(app.tminTxt,'Value',app.tmin);
            set(app.tmaxTxt,'Value',app.tmax);
            plotWideWindow(app)
            plotMultiBand(app)
            plotSpectrogram(app)
        end

        % Button pushed function: select_data
        function select_data_Callback(app, event)
            % Open new data file according to the selected data type
            
            % Check if any accessory window is selected and, if that is the case,
            % closes it.


            sel_format = get(app.dataFormat,'Value');
            switch sel_format
                case 'Default'
                    fmtSt = '*.dat';
                case 'EEG format'
                    fmtSt = '*.eeg';
                case 'Meyer VEP'
                    fmtSt = '*.asc';
                case 'SEP'
                    fmtSt = '*.asc';
                case 'AxoP'
                    fmtSt = '*.abf';
                case 'TRC import'
                    fmtSt = '*.trc';
                case 'IMM MEA'
                    fmtSt = '*.mat';
                case 'EDF format'
                    fmtSt = '*.edf';
                case 'Matlab'
                    fmtSt = '*.mat';
            end
            
            
            if app.autoMode    %:::GABRIELE::: 08/12/2017, for AutoOpening
                selection = app.auto.selection;
                path_in=[app.auto.path_in '\'];
                app.fileTime=[]; % needed by OpenFile
                openFile(app,selection,path_in)
            else
                [selection,path_in,filterI] = uigetfile ([app.dir_in fmtSt]);
                
                if isempty(selection) %return button pressed
                    return
                    figure(app.ZebraMainFig)
                end
                
                if app.autofocus.Value %2019/05/26 Gab
                    % Stay focused on ZebraExplore when opening a new file
                    figure(app.ZebraMainFig)
                end
                
                fileInfo = {'name' 'date' 'bytes' 'isdir' 'datenum'};
                if selection ~= 0
                    fileInfo = dir([path_in selection]);
                    timeNum = datestr(fileInfo.datenum);
                    app.fileTime = timeNum (13:20);
                    openFile(app,selection,path_in)
                end
            end
        end

        % Button pushed function: sendToClipboard
        function sendToClipboard_Callback(app, event)
            h = ZebraExplore;
            hgexport(h,'-clipboard');
        end

        % Value changed function: singleUnitExtraction_ck
        function singleUnitExtraction_ck_Callback(app, event)
            % hObject    handle to singleUnitExtraction_ck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Didi October 2018: open single units program to extract single units from
            % LFP
             if app.singleUnitExtraction_ck.Value
                 % open the single unit program
                 app.SUapp = SingleUnits_App(app);            
             else
                app.SUapp.delete
                app.SUapp = [];
             end
        end

        % Value changed function: sliderBPpower
        function sliderBPpower_Callback(app, event)
            app.BP4power = app.sliderBPpower.Value;
            app.BPtext.Text = ['Select BP' num2str(app.BP4power)];
        end

        % Value changed function: spEdit
        function spEdit_Callback(app, event)
            app.sp = app.spEdit.Value;
            app.acqF = 1/app.sp;
            app.frequencyBand(5,2) = 0.5*app.acqF;  %GAB 22/05/2018
        end

        % Button pushed function: tResetBtn
        function tResetBtn_Callback(app, event)
            app.tmin = app.x(1); %GAB 2019/02/24: added a time offset, so tmin is no more 0.
            set(app.tminTxt,'Value',app.tmin)
            app.tmax = app.x(end); %GAB 2019/02/24: added a time offset, so tmax is no more dtaLen*sp.
            set(app.tmaxTxt,'Value',app.tmax)
            plotWideWindow(app)
            plotMultiBand(app)
            plotSpectrogram(app)
        end

        % Button pushed function: templExp_bt
        function templExp_bt_Callback(app, event)
            % hObject    handle to templExp_bt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            templ = app.templ;
            uisave('templ','template.mat')
        end

        % Button pushed function: templImp_bt
        function templImp_bt_Callback(app, event)
            % hObject    handle to templImp_bt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            templTemp = uigetfile;
            app.templ = templTemp;
        end

        % Value changed function: tmaxTxt
        function tmaxTxt_Callback(app, event)
            app.tmax = app.tmaxTxt.Value;
            if (app.tmin < app.tmax), plotWideWindow(app)
                plotMultiBand(app)
                plotSpectrogram(app)
            end
        end

        % Value changed function: tminTxt
        function tminTxt_Callback(app, event)
            app.tmin = app.tminTxt.Value;
            if (app.tmin < app.tmax), plotWideWindow(app)
                plotMultiBand(app)
                plotSpectrogram(app)
            end
        end

        % Value changed function: trialSlider, trialSlider_2
        function trialSlider_Callback(app, event)
            if (app.fileInNum >= 1 && app.nTrials > 1)
                app.currentTrial = int16(app.trialSlider.Value);
                set(app.trialText,'Text',['Trial ' num2str(app.currentTrial)]);
                plotWideWindow(app)
                plotMultiBand(app)
                plotSpectrogram(app)
                if get(app.powerSpectraCk, 'Value')
                    startSpectraPlot(app)
                end
            end
        end

        % Value changed function: upperDB
        function upperDB_Callback(app, event)
            app.SPGhighDB = app.upperDB.Value;
            if (app.fileInNum>0), plotSpectrogram (app)
            end
        end

        % Value changed function: ymaxTxt
        function ymaxTxt_Callback(app, event)
            %        str2double(get(hObject,'Value')) returns contents of ymaxTxt as a double
            %app.ymax = (get(hObject,'Value'));
%             event = [];
%             event.yLim = [app.yminTxt.Value, app.ymaxTxt.Value];
%             plotWideWindow(app,event)
%             plotMultiBand(app)
%             plotSpectrogram(app)
            plotWideWindow(app)
        end

        % Button pushed function: autoMode_bt
        function autoMode_btButtonPushed(app, event)
            if ~app.autoMode
                app.autoMode = 1;
                app.select_data.Enable = 'off';
                app.AutoGo_bt.Enable = 'on';
                app.autoMode_bt.Text = 'Auto Mode ON';
                app.autoMode_bt.BackgroundColor = 'g';
            else
                app.autoMode = 0;
                app.select_data.Enable = 'on';
                app.AutoGo_bt.Enable = 'off';
                app.autoMode_bt.Text = 'Auto Mode OFF';
                app.autoMode_bt.BackgroundColor = 'r';
            end
        end

        % Button pushed function: AutoSetContrasts_bt
        function AutoSetContrasts_btButtonPushed(app, event)
            nCond = app.AutoNConds_txt.Value;
            
            conds = 1:nCond;
            
            % Create a cell array containing all the string to be put aside each text box
            prompt = arrayfun(@(x) ['specify folders for contrast level' num2str(x)], conds, 'un', 0);
            
            if ~isempty(app.auto.AutoDlgIn)
                % If app.AutoDlgIn is not empty, AutoDlg is used as preset values of the ZebraAuto folders
                preset = app.auto.AutoDlgIn;
            else
                % If there is nothing in app.AutoDlgIn, it is initialized as an empty cell array
                preset = cell(nCond, 1);
                preset(:) = {''};
            end
            
            title = 'specify folders for analysis (space separated)';
            nlines = 1;
            app.auto.AutoDlgIn = inputdlg(prompt, title, nlines, preset, 'on');
            if isempty(app.auto.AutoDlgIn)
                return
            end
            
            for i = 1:nCond
%                 AutoFolderTmp = strsplit(app.AutoDlgIn{i}, ' ');
%                 app.AutoFolders{i} = cellfun(@str2double, AutoFolderTmp);
                app.auto.AutoFolders{i} = strsplit(app.auto.AutoDlgIn{i}, ' ');
            end
        end

        % Button pushed function: AutoGo_bt
        function AutoGo_btButtonPushed(app, event)
            % The main path_in is taken from the path that is saved in the ZebraAuto UI
            % And the same is done for the file name
            folder_in = app.AutoMainFolder_edit.Value;
            app.auto.selection = app.AutoFileNameIn_edit.Value;
            
            nCond = app.AutoNConds_txt.Value;
            nFolds = cellfun(@length,app.auto.AutoFolders); %it is useful to know how many folders(=traces) are there for every condition
                          
            %initialize the variables.
            
            %compute the number of traces for each condition
            if app.concat_ck.Value
                % if traces from the same condition are concatenated, we end up with a single trace for each condition
                condsLen = ones(1,nCond); 
            else
                % otherwise, the number of traces for each condition is stored in nFolds
                condsLen = nFolds;
            end
            if app.AutoTable_ck.Value
%             outTab is a structure array with n elements,(n = number of conditions) 
%             and one field (Table: a cell array of length m, with 
%             m = number of traces for that condition. Every element of Table 
%             stores the zebraSays table from the corresponding trace).
                outTab = struct('Table',[]);
                for i = 1:nCond
                    % outTab(i).Table = cell(1,1,length(outTab(i)));
                    outTab(i).Table = cell(1,condsLen(i));
                end
            end
            
            if app.AutoTrace_ck.Value
%             outTraces is a structure array with n elements,(n = number of conditions) 
%             and three fields: Time, Trace and MeanTrace (fields are cell arrays of length m, with 
%             m = number of traces for that condition). MeanTrace is used
%             only when nTrials > 1.
                outTraces = struct('Time',[],'Trace',[],'MeanTrace',[]);
                for i = 1:nCond
                    outTraces(i).Time       = cell(1,condsLen(i));
                    outTraces(i).Trace      = cell(1,condsLen(i));
                    outTraces(i).MeanTrace  = cell(1,condsLen(i));
                end
            end
            
            if app.AutoSpectra_ck.Value
%             outSpec is a structure array with n elements,(n = number of conditions) 
%             and six fields: Freq, Pws and MeanPws, Freq2, Pws2, MeanPws2 (fields are cell arrays of length m, with 
%             m = number of traces for that condition). MeanPws is used
%             only when nTrials > 1. The last three fields are used
%             only when the second time window for power spectrum
%             calculation is selected.
                outSpec = struct('Freq' ,[],'Pws' ,[],'MeanPws' ,[],...
                                   'Freq2',[],'Pws2',[],'MeanPws2',[]);
                for i = 1:nCond
                    outSpec(i).Freq     = cell(1,condsLen(i));
                    outSpec(i).Pws      = cell(1,condsLen(i));
                    outSpec(i).MeanPws  = cell(1,condsLen(i));
                    outSpec(i).Freq2    = cell(1,condsLen(i));
                    outSpec(i).Pws2     = cell(1,condsLen(i));
                    outSpec(i).MeanPws2 = cell(1,condsLen(i));
                end
            end
            
            if app.AutoSpg_ck.Value
%             outSpg is a structure array with n elements,(n = number of conditions) 
%             and four fields: SpgMat, SpgT and SpgW, MeanSpgMat (fields are cell arrays of length m, with 
%             m = number of traces for that condition). MeanSpgMat is used
%             only when nTrials > 1.
                outSpg = struct('SpgMat',[],'SpgT',[],'SpgW',[],'MeanSpgMat',[]);
                for i = 1:nCond
                    outSpg(i).SpgMat        = cell(1,condsLen(i));
                    outSpg(i).SpgT          = cell(1,condsLen(i));
                    outSpg(i).SpgW          = cell(1,condsLen(i));
                    outSpg(i).MeanSpgMat    = cell(1,condsLen(i));
                end
            end
            
            % before startung, clear the stored LFP!
            if app.concat_ck.Value
                clearConc_btn_Callback(app)
            end
            
            % Start running ZebraExplore automatically
            for i_Cond=1:nCond
                currFolds = app.auto.AutoFolders{i_Cond}; %currFolds=folders of the current condition
                nTraces = length(currFolds);
                skip = 0; % Useful for "concatenate" condition. If skip = 1, skips the storage of data form a trace
                if nTraces>0
                    for i_Trace = 1:nTraces
                                    
                        iCond = i_Cond
                        iTrace = i_Trace
                        
                        if str2double(currFolds{iTrace})>9 %we need this because folder names as Dir_03 cannot be returned, i.g.: num2string(03)=3 
                            app.auto.path_in = [folder_in '\Dir_' currFolds{iTrace}];
                        else
                            app.auto.path_in = [folder_in '\Dir_0' currFolds{iTrace}];
                        end
                        
                        select_data_Callback(app,[])
%                                              
%                         if app.AutoSelCh_txt.Value ~= 1
%                             app.chSlider.Value = app.AutoSelCh_txt.Value;
%                             chSlider_Callback(app,[])
%                         end
                        
                        %2018/11/04: if "concatenation is selected", just open all the
                        %"nTraces" files and extract data only at the end of a "condition".
                        if app.concat_ck.Value
                            if iTrace < nTraces
                                skip = 1;
                            else
                                skip = 0;
                                iTrace = 1; %so that the result is stored as it was the "fist trace"
                                %concatenate trials and compute everything in ZebraMain
                                concat_btn_Callback(app, [])
                            end
                        end
                        
                        if ~skip
                            
                            %% process the recording.
                            
                            % channels is a vector containing the number of the channels that have to be considered.
                            if app.selCh_rb.Value %if a specific channel is selected
                                channels = app.selCh_edit.Value;
                            elseif app.allCh_rb.Value %all the channels have to be considered
                                channels = 1:app.nCh;
                            else
                                error('Error in the UI elements for channel selection')
                            end
                            
                            %% tables
                            if app.AutoTable_ck.Value
%                                 temp = app.outEvochedResponses.Data;
%                                 temp2 = {};
%                                 for i = 1:length(channels)
%                                     temp2 = cat(1,temp2,temp(cell2mat(temp(:,2))==channels(i),:));
%                                 end
                                %outTab(iCond).Table{iTrace} = wholeTab(cell2mat(wholeTab(:,4))==1,:);
                                if app.selCh_rb.Value
                                    temp2 = app.outEvochedResponses.Data(cell2mat(app.outEvochedResponses.Data(:,2))==channels,:);
                                else
                                    temp2 = app.outEvochedResponses.Data;
                                end
                                outTab(iCond).Table{iTrace} = temp2;
                            end
            
                            %% spectra
                            if app.AutoSpectra_ck.Value
                                %--set parameters on ZebraSpectre----------------------
                                if app.AutoWinSpectra_ck.Value
                                    app.spectreApp.fromTtxt.Value = app.AutoSpectraFrom_edit.Value;
                                    app.spectreApp.toTtxt.Value = app.AutoSpectraTo_edit.Value;
                                    app.spectreApp.windowCk.Value = 1;
                                    app.spectreApp.subMean_ck.Value = 1;
                                    if app.AutoWinSpectra2_ck.Value
                                        app.spectreApp.fromT2txt.Value = app.AutoSpectraFrom2_edit.Value;
                                        app.spectreApp.toT2txt.Value = app.AutoSpectraTo2_edit.Value;
                                        app.spectreApp.window2Ck.Value = 1;
                                    end
                                end
                                
                                % Make SpectreApp compute the spectra
                                computeSpectra_function(app.spectreApp)
                                
                                outSpec(iCond).Freq{iTrace} = app.spectreApp.pwsf;
                                outSpec(iCond).Pws{iTrace}  = app.spectreApp.powerS(channels,:,:);
                                if app.nTrials > 1
                                    outSpec(iCond).MeanPws{iTrace} = app.spectreApp.meanPWS(channels,:);
                                end
                                
                                if app.AutoWinSpectra2_ck.Value
                                    outSpec(iCond).Freq2{iTrace} = app.spectreApp.pwsf2;
                                    outSpec(iCond).Pws2{iTrace}  = app.spectreApp.powerS2(channels,:,:);
                                    if app.nTrials > 1
                                        outSpec(iCond).MeanPws2{iTrace} = app.spectreApp.meanPWS2(channels,:);
                                    end
                                end
                                
                            end
                            
                            %% spg
                            if app.AutoSpg_ck.Value
                                flagLeak = app.displayLeakCk.Value; %is deleaked_spg displayed?
                                
                                outSpg(iCond).SpgT{iTrace}   = app.spgt;
                                outSpg(iCond).SpgW{iTrace}  = app.spgw;
                                
                                %if deleaked_spg is calculated and
                                %displayed, collect it instead of the
                                %"standard" spg
                                if (app.deleakedFlag && flagLeak)
                                    outSpg(iCond).SpgMat{iTrace} = app.spgDeleaked(channels,:,:,:);
                                    if app.nTrials > 1
                                        outSpg(iCond).MeanSpgMat{iTrace} = app.meanSpgDeleaked(channels,:,:);
                                    end
                                else
                                    outSpg(iCond).SpgMat{iTrace} = app.spg(channels,:,:,:);
                                    if app.nTrials > 1
                                        outSpg(iCond).MeanSpgMat{iTrace} = app.meanSpg(channels,:,:);
                                    end
                                end
            
                                %check that time and freq are the same among traces
                                if iTrace > 1
                                    %Compare the current time and frequency vectors with the ones from the 1st trial
                                    checkT = isequal(outSpg(iCond).SpgT{iTrace},outSpg(iCond).SpgT{1});
                                    checkW = isequal(outSpg(iCond).SpgW{iTrace},outSpg(iCond).SpgW{1});
                                    if checkT * checkW == 0
                                        error('SPG: time or frequency mismatch')
                                    end
                                end
                            end
            
                            %% traces
                            if app.AutoTrace_ck.Value
                                outTraces(iCond).Trace{iTrace} = app.LFP(channels,:,:);
                                outTraces(iCond).Time{iTrace} = app.x;
                                if app.nTrials > 1
                                    outTraces(iCond).MeanTrace{iTrace} = app.meanLFP(channels,:);
                                end
                            end    
                        end
                    end
                    
                    %% this is done at the end of each "condition"   
                    if app.concat_ck.Value
                        clearConc_btn_Callback(app)
                    end
                else
                    disp(['no selected folders for dataset ' num2str(iCond)])
                end
            end
            
            %% Save
            
            %Table
            if app.AutoTable_ck.Value
                save([app.AutoOutFileName_edit.Value(1:end-4) '_tables.mat'], 'outTab');
            end
            
            %Spectra
            if app.AutoSpectra_ck.Value
                save([app.AutoOutFileName_edit.Value(1:end-4) '_spectra.mat'], 'outSpec');
            end
            
            %Spectrogram
            if app.AutoSpg_ck.Value
                save([app.AutoOutFileName_edit.Value(1:end-4) '_spg.mat'], 'outSpg');
            end
            
            %Traces
            if app.AutoTrace_ck.Value
                save([app.AutoOutFileName_edit.Value(1:end-4) '_traces.mat'],'outTraces')
            end
            
            if app.sound_ck.Value 
                load gong
                sound(y,Fs)
            end
            disp('ho finito!')          
        end

        % Button pushed function: SaveDownstSpec
        function SaveDownstSpecButtonPushed(app, event)
            SaveDownstSpectra(app,[])
        end

        % Button pushed function: analyzeData
        function analyzeData_Callback(app, event)
            % This function computes the distribution of the spectral power in the
            % 'envelope' band width. The distribution is fitted by a double gaussian
            % and the limits for the event detection are computed
                        
            app.evDet.Hcenters = [];
            app.evDet.cdist = [];
            app.evDet.cdistN = [];
            app.evDet.Hnelements = [];
            app.evDet.normNelements = [];
            
%             % first, extract the data to be analyzed
%             dataSource = 9;
%             if get(app.detectionSPG,'Value')
%                 dataSource = 0;
%             end
%             if get(app.detectionBP1,'Value')
%                 if app.BPcomputed(2), dataSource = 2;
%                 end
%             end
%             if get(app.detectionBP2,'Value')
%                 if app.BPcomputed(3), dataSource = 3;
%                 end
%             end
%             if get(app.detectionBP3,'Value')
%                 if app.BPcomputed(4), dataSource = 4;
%                 end
%             end
%             if get(app.detectionHP,'Value')
%                 if app.BPcomputed(5), dataSource = 5;
%                 end
%             end
            
            % first, extract the data to be analyzed
            dataSource = NaN;
            % Find the selected BP
            selBP = find(strcmp(app.detectionBP_dropDown.Value,app.detectionBP_dropDown.Items));
            if selBP < size(app.detectionBP_dropDown.Items,1) % it is not SPG envelope, which is always the last element
                if app.BPcomputed(selBP)
                    dataSource = selBP;
                end
            else %SPG envelop is selected
                dataSource = 0;
            end
            
            if ~isnan(dataSource)
                ct = app.currentTrial;
                cCh = app.currentCh;
                if dataSource == 0
                    sl = app.spgl;
%                 else
%                     sl = app.BPpowerL(dataSource);
                end
                logFlag = get(app.powerLogCk,'Value');
                flagLeak = get (app.displayLeakCk,'Value');
                leftH = get(app.fromH, 'Value');
                rightH = get(app.toH, 'Value');
                bins = get(app.binNumber, 'Value');
                
                temp = []; % just to be sure
%                 if (app.deleakedFlag && flagLeak)
%                     % deleaked only for the SPG.
%                     if logFlag
%                         temp (1:sl) = log10(app.spgPlotDeleaked(cCh,ct,1:sl));
%                     else
%                         temp (1:sl) = app.spgPlotDeleaked(cCh,ct,1:sl);
%                     end
%                 else
%                     if logFlag
% %                         switch dataSource
% %                             case 0      % SPG
% %                                 temp (1:sl) = log10(app.spgPlot(cCh,ct,1:sl));
% %                             case {2, 3, 4, 5}      % BP1, BP2, BP3, HP
% %                                 temp (1:sl) = log10(app.BPpower(2,dataSource,cCh,ct,1:sl));
% %                         end
%                         if ~dataSource
%                             temp (1:sl) = log10(app.spgPlot(cCh,ct,1:sl));
%                         else
%                             temp (1:sl) = log10(app.BPpower(dataSource).power(cCh,ct,:));
%                         end
%                     else
%                         switch dataSource
%                             case 0      % SPG
%                                 temp (1:sl) = app.spgPlot(cCh,ct,1:sl);
%                             case {2, 3, 4, 5}      % BP1, BP2, BP3, HP
%                                 temp (1:sl) = app.BPpower(2,dataSource,cCh,ct,1:sl);
%                         end
%                     end
%                 end

                % 2019/05/28 Gab. Rearranged and adapted to multiple BPs
                % and to the new structure of the variable BPpower
                if (app.deleakedFlag && flagLeak)
                    % deleaked only for the SPG.
                    temp (1:sl) = app.spgPlotDeleaked(cCh,ct,1:sl);
                else
                    if ~dataSource
                        temp (1:sl) = app.spgPlot(cCh,ct,1:sl);
                    else
                        temp = app.BPpower(dataSource).power(cCh,ct,:);
                    end
                end
                
                if logFlag
                    temp = log10(temp);
                end


                % check about auto scaling or not
                if leftH==0, [nelements,centers] = hist(temp,bins);
                else
                   xValues = leftH:(rightH-leftH)/bins:rightH;
                   [nelements,centers] = hist(temp,xValues);
                end
            
                % compute the 1) normalized histo, 2) cumulative distribution, 3)
                % normalized cumulative distribution
                totalN = sum (nelements);
                app.evDet.normNelements = nelements/totalN;
                for j=1:bins+1
                    app.evDet.cdist (j) = sum(nelements(1:j));
                end
                app.evDet.cdistN = app.evDet.cdist / totalN;
            
                app.evDet.Hcenters = centers;
                app.evDet.Hnelements = nelements;
            
                % plot the histogram
                % axes (app.histoPlot);
                cla(app.histoPlot)
                bar(app.histoPlot,centers,nelements);
                %axis ([leftH rightH 0 inf], 'auto y');
                app.histoPlot.XLim = [leftH rightH];
                app.histoPlot.YLim = [0 inf];
                app.histoPlot.YLimMode = 'auto';
                app.evDet.meanPW(cCh,ct) = mean(temp);
                app.evDet.stdPW(cCh,ct) = std(temp);
                nsamples = length(centers);
                nmeasures = sum(nelements);
            
                tll = app.evDet.meanPW(cCh,ct)-app.evDet.stdPW(cCh,ct)/3;
                set(app.baselineThr,'Value',tll);
                tll = app.evDet.meanPW(cCh,ct)+app.evDet.stdPW(cCh,ct)/3;
                set(app.eventThr,'Value',tll);
            
                %f = fit(centers.',nelements.','gauss2');
            
                % fit the histogram with a double gaussian. 10.0266 = 2*2*sqr(2*pi)
                param(2)= app.evDet.meanPW(cCh,ct)-app.evDet.stdPW(cCh,ct); % mean first component
                param(3)= app.evDet.stdPW(cCh,ct)/2;                      % SD first component
                amplitude(1)= nmeasures/(10.0266*param(3));                 % amplitude first component
                param(5)= app.evDet.meanPW(cCh,ct)+app.evDet.stdPW(cCh,ct); % mean second component
                param(6)= app.evDet.stdPW(cCh,ct)/2;                      % SD second component
                amplitude(2)= nmeasures/(10.0266*param(6));                 % amplitude first component
                gaussFnc = [];
            
                % July 10, 2017. First, fit with fixed means and STDs to obtain a
                % preliminary estimates of the gaussian amplitudes.
                ampliOut = fminsearch(@gaussFit1,amplitude);
                param(1) = ampliOut(1);
                param(4) = ampliOut(2);
                % next, recompute everything.
                paramOut = fminsearch(@gaussFit,param);
            
                % paramOut contains the 6 parameters that define the best fit.
                st = ['Gauss 1; mean:' num2str(paramOut(2),'%2.2f') ' ±SD: ' num2str(paramOut(3),'%2.2f')];
                set (app.gauss1param, 'Text', st);
                st = ['Gauss 2; mean:' num2str(paramOut(5),'%2.2f') ' ±SD: ' num2str(paramOut(6),'%2.2f')];
                set (app.gauss2param, 'Text', st);
            
                plot(app.histoPlot,centers,gaussFnc,'red','LineWidth',2);
                yl = app.histoPlot.YLim;
                line(app.histoPlot,[paramOut(2)+paramOut(3) paramOut(2)+paramOut(3)],[yl(1) yl(2)]);
                line(app.histoPlot,[paramOut(5)-paramOut(6) paramOut(5)-paramOut(6)],[yl(1) yl(2)]);
            
                % now compute the state thresholds
                xinterc = fzero(@gaussDiff,(paramOut(2)+paramOut(5))/2);
            
                app.evDet.autoblThr = xinterc;
                app.evDet.autoevThr = xinterc;
                
                disp('Data analyzed')
            else
                errStr = [app.detectionBP_dropDown.Value ' is not a valid source.'];
                error(errStr)
            end
            
                function res = gaussFit(a)
                    % gmodel = a(1)*exp(-(centers-a(2))^2/2*a(3).^2) + a(4)*exp(-(centers-a(5))^2/2*a(6).^2)
                    % nelements is the array that contains the histogram bin counts: this
                    % is the function to fit
                    gaussFnc = a(1)*exp(-(centers-a(2)).^2/(2*(a(3)^2))) + a(4)*exp(-(centers-a(5)).^2/(2*(a(6)^2)));
                    res = sum((nelements-gaussFnc).^2);
                end
                function res = gaussFit1(a)
                    % gmodel = a(1)*exp(-(centers-a(2))^2/2*a(3).^2) + a(4)*exp(-(centers-a(5))^2/2*a(6).^2)
                    % nelements is the array that contains the histogram bin counts: this
                    % is the function to fit
                    gaussFnc = a(1)*exp(-(centers-param(2)).^2/(2*(param(3)^2))) + a(2)*exp(-(centers-param(5)).^2/(2*(param(6)^2)));
                    res = sum((nelements-gaussFnc).^2);
                end
            
                function y = gaussDiff(x)
                    y = paramOut(1)*exp(-(x-paramOut(2)).^2/(2*(paramOut(3)^2)));
                    y = y - paramOut(4)*exp(-(x-paramOut(5)).^2/(2*(paramOut(6)^2)));
                end
        end

        % Button pushed function: exportTab_btn
        function exportTab_btn_Callback(app, event)
            % hObject    handle to exportTab_btn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            tmp = app.UStable.Data;
            splitDir = regexp(app.dir_in,'\','split');
            outName = [app.dir_in, '\UStable_' splitDir{end-1} '_' app.file_in '.mat'];
            save(outName,'tmp');
        end

        % Button pushed function: extractEvents
        function extractEvents_Callback(app, event)
            % refers to the 'compute' button            
            app.evDet.upstates = struct('fromI',1,'fromT',1,'toI',1,'toT',1,'deltaT',1,'medianV',1,'SD',1,'pw',1,...
                'medianV_hem2', 1, 'SD_hem2', 1, 'pw_hem2', 1, 'enable',1);
            app.evDet.downstates = struct('fromI',1,'fromT',1,'toI',1,'toT',1,'deltaT',1,'medianV',1,'SD',1,'pw',1,...
                'medianV_hem2', 1, 'SD_hem2', 1, 'pw_hem2', 1, 'enable',1);
            
            app.evDet.SPG = [];
            app.evDet.LFP = [];
            app.evDet.LFP_hem2 = [];
            app.evDet.timeSPG = [];
            app.evDet.upstates.fromI = [];
            app.evDet.upstates.toI = [];
            app.evDet.downstates.fromI = [];
            app.evDet.downstates.toI = [];
            app.evDet.USlist = [];
            app.evDet.USlist = {1 1 1 1 1 1 1 1 1 1};
            
            % set the threshold for event detection based on the used method
            if get(app.autoThr,'Value') % if autosetting state thresshold is clicked, automatically determine threshold
                lowThr = app.evDet.autoblThr; % this value is calculated later in analyzeData function based on power distribution!
                highThr = app.evDet.autoevThr; % this value is calculated later in analyzeData function based on power distribution!
            else
                lowThr = get(app.baselineThr,'Value'); %otherwise, obtain user defined threshold values
                highThr = get(app.eventThr,'Value');
            end
            
            % Identify the data set to be analyzed
%             dataSource = 9;
%             if get(app.detectionBP1,'Value'),
%                 if app.BPcomputed(2), dataSource = 2; %BPcomputed is a vector containing a 0 if the
%                    % specific band pass has not been analyzed, and a 1 if it has been analyzed
%                 end
%             end
%             if get(app.detectionBP2,'Value'),
%                 if app.BPcomputed(3), dataSource = 3;
%                 end
%             end
%             if get(app.detectionBP3,'Value'),
%                 if app.BPcomputed(4), dataSource = 4;
%                 end
%             end
%             if get(app.detectionHP,'Value'),
%                 if app.BPcomputed(5), dataSource = 5;
%                 end
%             end
%             if get(app.detectionSPG,'Value'), dataSource = 0;
%             end
%             
%             if dataSource < 9
            dataSource = NaN;
            % Find the selected BP
            selBP = find(strcmp(app.detectionBP_dropDown.Value,app.detectionBP_dropDown.Items));
            if selBP < size(app.detectionBP_dropDown.Items,1)
                % selBP is not SPG envelope, which is always the last element
                if app.BPcomputed(selBP)
                    dataSource = selBP;
                end
            else 
                % SPG envelop is selected
                dataSource = 0;
            end
            
            if ~isnan(dataSource)
                ct = app.currentTrial;
                cCh = app.currentCh;
                len = app.dtaLen;
                spp = app.sp;
                logFlag = get(app.powerLogCk,'Value'); % if 'log of envelope power' is checked
                flagLeak = get (app.displayLeakCk,'Value');
                app.evDet.tmin = get(app.leftTime,'Value'); % time values entered by user in the bottom left box
                app.evDet.tmax = get(app.rightTime,'Value');
            
                app.evDet.LFP(1:len) = app.workLFP (cCh,ct,1:len);
                if app.nCh == 2
                    if cCh == 1
                        Ch_hem2 = 2;
                    elseif cCh == 2
                        Ch_hem2 = 1;
                    end
                    app.evDet.LFP_hem2(1:len) = app.workLFP (Ch_hem2, ct, 1:len);
                end
            
                app.evDet.timeSPG = [];
                if dataSource == 0          % length of the spectral power file
                    sl = app.spgl;
                    app.evDet.timeSPG = app.spgt;
                else
%                     sl = app.BPpowerL(dataSource); % length of the vector power of band passed data
%                     app.evDet.timeSPG(1:sl) = app.BPpower(1,dataSource,cCh,ct,1:sl); % spectrogram data itself for current BP setting
                    sl = length(app.BPpower(dataSource).time); % length of the vector power of band passed data
                    app.evDet.timeSPG = app.BPpower(dataSource).time; % spectrogram data itself for current BP setting
                end
            
%                 if (app.deleakedFlag && flagLeak) % If we want the deleaked version of the spg
%                     if logFlag
%                         app.evDet.SPG (1:sl) = log10(app.spgPlotDeleaked(cCh,ct,1:sl)); %calculate the log of spg if clicked
%                     else
%                         app.evDet.SPG (1:sl) = app.spgPlotDeleaked(cCh,ct,1:sl);
%                     end
%                 else
%                     if logFlag % if not the deleaked version, but log is clicked:
%                         switch dataSource
%                             case 0      % SPG
%                                 app.evDet.SPG (1:sl) = log10(app.spgPlot(cCh,ct,1:sl));
%                             case {2, 3, 4, 5}      % BP1, BP2, BP3, HP
%                                 app.evDet.SPG (1:sl) = log10(app.BPpower(2,dataSource,cCh,ct,1:sl));
%                         end
%                     else % finally, if not the the deleaked version, and log is not clicked:
%                         app.evDet.SPG (1:sl) = app.spgPlot(cCh,ct,1:sl);
%                         switch dataSource
%                             case 0      % SPG
%                                 app.evDet.SPG (1:sl) = app.spgPlot(cCh,ct,1:sl);
%                             case {2, 3, 4, 5}      % BP1, BP2, BP3, HP
%                                 app.evDet.SPG (1:sl) = app.BPpower(2,dataSource,cCh,ct,1:sl);
%                         end
%                     end
%                 end
                % 2019/05/28 Gab. Rearranged and adapted to multiple BPs
                % and to the new structure of the variable BPpower
                if (app.deleakedFlag && flagLeak)
                    % If we want the deleaked version of the spg
                    app.evDet.SPG (1:sl) = app.spgPlotDeleaked(cCh,ct,1:sl);
                else
                    if ~dataSource
                        % SPG envelop is selected
                        app.evDet.SPG (1:sl) = app.spgPlot(cCh,ct,1:sl);
                    else
                        % a BP is selected
                        app.evDet.SPG (1:sl) = app.BPpower(2,dataSource,cCh,ct,1:sl);
                    end
                end
                if logFlag
                    app.evDet.SPG = log10(app.evDet.SPG);
                end
                
                downStatesBolean = app.evDet.SPG < lowThr; %logical array of SPG below threshold
                downStatesI = find(app.evDet.SPG< lowThr); % indexes of SPG where it is below threshold (i.e. is downstate)
                upStatesBolean = app.evDet.SPG > highThr; % same for up states (above threshold)
                upStatesI = find(app.evDet.SPG>highThr);
                limboBolean = ~(downStatesBolean | upStatesBolean); % I don't understand this, it seems to create an empty logical array
            
                % now the vector app.evDet.SPGt (downstatesBolean) contains the timing of the
                % upstates. This has to be converted to the indexes of the workLFP file
            
                lus = length (upStatesI);
                lds = length (downStatesI);
            
                % process upstates first
                if lus>0
                    firstI = upStatesI (1);
                    firstTime = app.evDet.timeSPG (firstI);
                    app.evDet.upstates.fromT (1) = firstTime;
                    app.evDet.upstates.fromI (1) = 1+firstTime/spp; % spp is the sampling period, this converts the time to the LFP index
                    nus = 1; % keeps track of the number of up states
                    for i=2:lus
                        nextI = upStatesI(i); % loop through all the indexes with higher than threshold value
                        if nextI==firstI+1
                            % continue the old US
                            firstI=nextI;
                            if i == lus
                                % we have reached the end of the track. close the US
                                endTime = app.evDet.timeSPG (firstI);
                                app.evDet.upstates.toT (nus) = endTime;
                                app.evDet.upstates.toI (nus) = int32(1+endTime/spp);
                                app.evDet.upstates.deltaT (nus) = endTime-app.evDet.upstates.fromT (nus);
                                app.evDet.upstates.enable (nus) = 1;
                            end
                        else
                            firstTime = app.evDet.timeSPG (firstI);
                            % beginning of a new US. Close the old US
                            app.evDet.upstates.toT (nus) = firstTime;
                            app.evDet.upstates.toI (nus) = int32(1+firstTime/spp);
                            app.evDet.upstates.deltaT (nus) = firstTime-app.evDet.upstates.fromT (nus);
                            app.evDet.upstates.enable (nus) = 1;
                            nus = nus + 1;
                            % open new US
                            app.evDet.upstates.fromT (nus) = app.evDet.timeSPG (nextI);
                            app.evDet.upstates.fromI (nus) =  int32(1+app.evDet.timeSPG (nextI)/spp);
                            firstI = nextI;
                        end
                    end
                end
            
                nus = length (app.evDet.upstates.toI);      % just to make sure...
            
                % process downstates
                if nus>0
                    firstI = downStatesI (1);
                    firstTime = app.evDet.timeSPG (firstI);
                    app.evDet.downstates.fromT (1) = firstTime;
                    app.evDet.downstates.fromI (1) = 1+firstTime/spp;
                    nds = 1;
            
                    for i=2:lds
                        nextI = downStatesI(i);
                        if nextI==firstI+1
                            % continue the old DS
                            firstI=nextI;
                             if i == lds
                                  % we have reached the end of the track. close the DS
                                  endTime = app.evDet.timeSPG (firstI);
                                  app.evDet.downstates.toT (nds) = endTime;
                                  app.evDet.downstates.toI (nds) = int32(1+endTime/spp);
                                  app.evDet.downstates.deltaT (nds) = endTime-app.evDet.downstates.fromT (nds);
                                  app.evDet.downstates.enable (nds) = 1;
                             end
                        else
                            firstTime = app.evDet.timeSPG (firstI);
                            % beginning of a new DS. Close the old DS
                            app.evDet.downstates.toT (nds) = firstTime;
                            app.evDet.downstates.toI (nds) = int32(1+firstTime/spp);
                            app.evDet.downstates.deltaT (nds) = firstTime-app.evDet.downstates.fromT (nds);
                            app.evDet.downstates.enable (nds) = 1;
                            nds = nds + 1;
                            % open new DS
                            app.evDet.downstates.fromT (nds) = app.evDet.timeSPG (nextI);
                            app.evDet.downstates.fromI (nds) =  int32(1+app.evDet.timeSPG (nextI)/spp);
                            firstI = nextI;
                        end
                    end
                end
                nds = length(app.evDet.downstates.toI);
            
            
                % defragment DS and US across short state gap. The max size of the filled
                % gap is defined in the GUI
                % These are the rules: a brief interruption shorter than maxInterruption is
                % filled in by assigning to the neighboring US that are fused in a longer
                % one. Isolated US briefer than minDuration are attributed to the limbo.
            
                maxGap = get(app.maxInterruption,'Value');
                minUS = get(app.minDuration,'Value');
            
                if get(app.removeCk,'Value') %if the remove state interruptions button is clicked
                    % process US first
                    usi = 2;
                    while usi <= nus % there must be at least two up states to do this operation, loop through from 2 till nus
                        % perform the fusions first since brief US might get fused together
                        % to form a longer and legal US.
                        interval = [app.evDet.upstates.fromT(usi) app.evDet.upstates.toT(usi-1)];
                        distance = interval(1) - interval(2);
                        if (distance<=maxGap)
                            % first fuse the two upstates
                            app.evDet.upstates.toT(usi-1) = app.evDet.upstates.toT(usi);
                            app.evDet.upstates.toI(usi-1) = app.evDet.upstates.toI(usi);
                            app.evDet.upstates.deltaT(usi-1) = app.evDet.upstates.toT(usi-1) - app.evDet.upstates.fromT(usi-1);
                            % second remove the US pointed to by usi
                            for k = usi:nus-1
                                % shift all USs
                                app.evDet.upstates.toT(k) = app.evDet.upstates.toT(k+1);
                                app.evDet.upstates.toI(k) = app.evDet.upstates.toI(k+1);
                                app.evDet.upstates.fromT(k) = app.evDet.upstates.fromT(k+1);
                                app.evDet.upstates.fromI(k) = app.evDet.upstates.fromI(k+1);
                                app.evDet.upstates.deltaT(k) = app.evDet.upstates.deltaT (k+1);
                            end
                            nus = nus - 1;
                        else
                            usi = usi+1;
                        end
                    end
                    % remove brief states
                    for usi=1:nus
                        if app.evDet.upstates.deltaT(usi) <= minUS, app.evDet.upstates.enable(usi) = 0;
                        end
                    end
                    % remove the disabled states from the list
                    cnt = nus;
                    for usi=nus:-1:1
                        if app.evDet.upstates.enable(usi)
                            % do nothing
                        else
                            cnt = cnt - 1;
                            % shift down if the US is not the last one of the track
                            if usi < nus
                                app.evDet.upstates.fromI (cnt:-1:usi) = app.evDet.upstates.fromI (cnt+1:-1:usi+1);
                                app.evDet.upstates.toI (cnt:-1:usi) = app.evDet.upstates.toI (cnt+1:-1:usi+1);
                                app.evDet.upstates.fromT (cnt:-1:usi) = app.evDet.upstates.fromT (cnt+1:-1:usi+1);
                                app.evDet.upstates.toT (cnt:-1:usi) = app.evDet.upstates.toT (cnt+1:-1:usi+1);
                                app.evDet.upstates.deltaT (cnt:-1:usi) = app.evDet.upstates.deltaT (cnt+1:-1:usi+1);
                                app.evDet.upstates.enable (cnt:-1:usi) = app.evDet.upstates.enable (cnt+1:-1:usi+1);
                            end
                        end
                    end
                    nus = cnt;
            
                    % process DS
                    for usi=2:nds
                        interval = [app.evDet.downstates.fromT(usi) app.evDet.downstates.toT(usi-1)];
                        distance = interval(1) - interval(2);
                        if (distance<=maxGap)
                            % fuse the contiguous downstates
                            app.evDet.downstates.toT(usi-1) = app.evDet.downstates.toT(usi);
                            app.evDet.downstates.toI(usi-1) = app.evDet.downstates.toI(usi);
                            app.evDet.downstates.deltaT(usi-1) = app.evDet.downstates.toT(usi-1) - app.evDet.downstates.fromT(usi-1);
                            app.evDet.downstates.enable(usi) = 0;
                        end
                    end
            
                    for usi=1:nds
                        if app.evDet.downstates.deltaT(usi) <= minUS, app.evDet.downstates.enable(usi) = 0;
                        end
                    end
            
                    cnt = nds;
                    for usi=nds:-1:1
                        if app.evDet.downstates.enable(usi)
                            % do nothing
                        else
                            cnt = cnt - 1;
                            % shift down
                            if usi < nds
                                app.evDet.downstates.fromI (cnt:-1:usi) = app.evDet.downstates.fromI (cnt+1:-1:usi+1);
                                app.evDet.downstates.toI (cnt:-1:usi) = app.evDet.downstates.toI (cnt+1:-1:usi+1);
                                app.evDet.downstates.fromT (cnt:-1:usi) = app.evDet.downstates.fromT (cnt+1:-1:usi+1);
                                app.evDet.downstates.toT (cnt:-1:usi) = app.evDet.downstates.toT (cnt+1:-1:usi+1);
                                app.evDet.downstates.deltaT (cnt:-1:usi) = app.evDet.downstates.deltaT (cnt+1:-1:usi+1);
                                app.evDet.downstates.enable (cnt:-1:usi) = app.evDet.downstates.enable (cnt+1:-1:usi+1);
                            end
                        end
                    end
                    nds = cnt;
                end
            
            
                % refine the edges of the state detection. Experimental and not great
                % not to be used as of March 22, 2017
                refineEdges (app);
            
                % At this stage all US and DS have been extracted and we can compute the
                % relative metrics.
                % Compute median, PW and SD of each US and DS.
                for usi=1:nds   % compute DS first since you need this to compute US size
                   i1 = app.evDet.downstates.fromI(usi);
                   i2 = app.evDet.downstates.toI(usi);
                   app.evDet.downstates.medianV(usi) = median(app.evDet.LFP(i1:i2));
                   app.evDet.downstates.SD(usi) = std(app.evDet.LFP(i1:i2));
                   app.evDet.downstates.pw(usi) = rms(app.evDet.LFP(i1:i2));
                   if app.nCh == 2
                       app.evDet.downstates.medianV_hem2(usi) = median(app.evDet.LFP_hem2(i1:i2));
                       app.evDet.downstates.SD_hem2(usi) = std(app.evDet.LFP_hem2(i1:i2));
                       app.evDet.downstates.pw_hem2(usi) = rms(app.evDet.LFP_hem2(i1:i2));
                   end
                end
            
                DSsearch = 1;
                dsi1 = app.evDet.downstates.fromI(DSsearch);
                dsi2 = app.evDet.downstates.toI(DSsearch);
                for usi=1:nus
                   i1 = app.evDet.upstates.fromI(usi);
                   i2 = app.evDet.upstates.toI(usi);
                   app.evDet.upstates.medianV(usi) = median(app.evDet.LFP(i1:i2));
                   app.evDet.upstates.SD(usi) = std(app.evDet.LFP(i1:i2));
                   app.evDet.upstates.pw(usi) = rms(app.evDet.LFP(i1:i2));
                   if app.nCh == 2
                       app.evDet.upstates.medianV_hem2(usi) = median(app.evDet.LFP_hem2(i1:i2));
                       app.evDet.upstates.SD_hem2(usi) = std(app.evDet.LFP_hem2(i1:i2));
                       app.evDet.upstates.pw_hem2(usi) = rms(app.evDet.LFP_hem2(i1:i2));
                   end
                   % now search for the closest DS
                   while dsi2 < i1 && DSsearch <= nds % while the end of the downstate is smaller than the start of the current up
                       % state and smaller than the number of down states
                       DSsearch = DSsearch + 1; % it will increase by one: we found the down state following the current up state
                       if DSsearch <= nds
                           dsi2 = app.evDet.downstates.toI(DSsearch);
                       end
                   end
                   % now DSsearch points to the DS immediatey after unless the data ends
                   % with a US. A second exception is when the data starts with an US
                   if DSsearch == 1     % data begins with US
                       baseline = app.evDet.downstates.medianV(1);
                       if app.nCh == 2
                           baseline_hem2 = app.evDet.downstates.medianV_hem2(1);
                       end
                   else
                       if DSsearch > nds    % data ends up with a US
                           baseline = app.evDet.downstates.medianV(nds);
                           if app.nCh == 2
                               baseline_hem2 = app.evDet.downstates.medianV_hem2(nds);
                           end
                       else
                           % OK, this is a middle of the road US!
                           baseline = (app.evDet.downstates.medianV(DSsearch)+app.evDet.downstates.medianV(DSsearch-1))/2;
                           if app.nCh == 2
                               baseline_hem2 = (app.evDet.downstates.medianV_hem2(DSsearch)+app.evDet.downstates.medianV_hem2(DSsearch-1))/2;
                           end
                       end
                   end
                   % this is added by Didi, in order to access the value later, so that
                   % I can add a downward going filter
                   newmedianV(usi) = app.evDet.upstates.medianV(usi) - baseline;
                   if app.nCh == 2
                        newmedianV_hem2(usi) = app.evDet.upstates.medianV_hem2(usi) - baseline_hem2;
                   end
                end
            
                % This part is added by Didi in November, 2018. Meant to remove
                % upstates from the list that have a positive medianV (i.e. that are
                % just artefacts)
                for us = 1:nus
                   i1 = app.evDet.upstates.fromI(us);
                   i2 = app.evDet.upstates.toI(us);
                    if newmedianV(us) > 0
                        app.evDet.upstates.enable(us) = 0;
                    end
                end
            
                % now remove the disabled up states from the list as was done above
                cnt = nus;
                for usi=nus:-1:1
                    if app.evDet.upstates.enable(usi)
                    % do nothing
                    else
                        cnt = cnt - 1;
                        % shift down if the US is not the last one of the track
                        if usi < nus
                            app.evDet.upstates.fromI (cnt:-1:usi) = app.evDet.upstates.fromI (cnt+1:-1:usi+1);
                            app.evDet.upstates.toI (cnt:-1:usi) = app.evDet.upstates.toI (cnt+1:-1:usi+1);
                            app.evDet.upstates.fromT (cnt:-1:usi) = app.evDet.upstates.fromT (cnt+1:-1:usi+1);
                            app.evDet.upstates.toT (cnt:-1:usi) = app.evDet.upstates.toT (cnt+1:-1:usi+1);
                            app.evDet.upstates.deltaT (cnt:-1:usi) = app.evDet.upstates.deltaT (cnt+1:-1:usi+1);
                            app.evDet.upstates.enable (cnt:-1:usi) = app.evDet.upstates.enable (cnt+1:-1:usi+1);
                            app.evDet.upstates.medianV(cnt:-1:usi) = app.evDet.upstates.medianV(cnt+1:-1:usi+1);
                            newmedianV(cnt:-1:usi) = newmedianV(cnt+1:-1:usi+1);
                            app.evDet.upstates.SD(cnt:-1:usi) = app.evDet.upstates.SD(cnt+1:-1:usi+1);
                            app.evDet.upstates.pw(cnt:-1:usi) = app.evDet.upstates.pw(cnt+1:-1:usi+1);
                            if app.nCh == 2
                                app.evDet.upstates.medianV_hem2(cnt:-1:usi) = app.evDet.upstates.medianV_hem2(cnt+1:-1:usi+1);
                                newmedianV_hem2(cnt:-1:usi) = newmedianV_hem2(cnt+1:-1:usi+1);
                                app.evDet.upstates.SD_hem2(cnt:-1:usi) = app.evDet.upstates.SD_hem2(cnt+1:-1:usi+1);
                                app.evDet.upstates.pw_hem2(cnt:-1:usi) = app.evDet.upstates.pw_hem2(cnt+1:-1:usi+1);
                            end
                        end
                    end
                end
                nus = cnt;
            
                app.evDet.NUS = nus;
                app.evDet.NDS = nds;
            
                % now create the list:
            
                for usi = 1:nus
                   app.evDet.USlist (usi,5) = num2cell(newmedianV(usi));
                   app.evDet.USlist (usi,6) = num2cell(app.evDet.upstates.SD(usi));
                   app.evDet.USlist (usi,7) = num2cell(app.evDet.upstates.pw(usi));
                   if app.nCh == 2
                       app.evDet.USlist (usi,8) = num2cell(newmedianV_hem2(usi));
                       app.evDet.USlist (usi,9) = num2cell(app.evDet.upstates.SD_hem2(usi));
                       app.evDet.USlist (usi,10) = num2cell(app.evDet.upstates.pw_hem2(usi));
                   end
                end
            
                % Creation of the list of USs
                %app.evDet.USlist (1,1) = num2cell(1);
                %app.evDet.USlist (1,2) = num2cell(app.evDet.upstates.fromT(1));
                app.evDet.USlist (1:nus,1) = num2cell(1:nus); % the first cell contains the up state number
                app.evDet.USlist (1:nus,2) = num2cell(app.evDet.upstates.fromT(1:nus)); % the second cell contains the start
                % time of the corresponding up state
                app.evDet.USlist (1:nus,3) = num2cell(app.evDet.upstates.toT(1:nus)); % then the end time
                app.evDet.USlist (1:nus,4) = num2cell(app.evDet.upstates.toT(1:nus) - app.evDet.upstates.fromT(1:nus)); % and the length of the up state
                
                
                                % Didi February 2019: Add Number of Multipeak Waves for both hemispheres. Based on the set of three papers Tononi lab: Sleep Homeostasis 
                % and Cortical Synchronization. This analysis is going to use the Band Passed filter data in the BP1 range. Here, an up state is represented
                % as a negative deflection of the signal. Multipeak waves are defined as waves with more than 1 negative peak between 2 zero crossings. So 
                % here, I took the BP1 filtered LFP from the start till the end of each up state and used the findpeaks function of matlabs Signal Processing
                % Toolbox to find negative peak number of both hemispheres.
                
%                 Gab 2019/05/28: from now on BPs are not fixed, so it is necessaty to choose the desired BP for this
%                 analysis. I added a spinnder button for this, it is called app.detectionBPmultiPeak_dropDown.
                multiPeakBP = app.detectionBPmultiPeak_spinner.Value;
                
                
                % start by setting up the parameters to find multipeaks of the current hemispere
                multipeak = zeros(app.evDet.NUS, 1);
                deltaBP(1:app.dtaLen) = app.bandPassed_LFP(multiPeakBP,app.currentCh, app.currentTrial, 1:app.dtaLen);
                
                % Now start looping through the up states and find peaks
                for usn = 1:app.evDet.NUS
                    cnt = 0;
                    start = app.evDet.upstates.fromI(usn);
                    stop = app.evDet.upstates.toI(usn);
                    pks = findpeaks(-deltaBP(start:stop));
                    if isempty(pks)
                        cnt = 0;
                    else
                    cnt = length(pks);
                    end
                    multipeak(usn) = cnt;
                end                
                
                % same to find peaks of other hemisphere
                if app.nCh == 2
                    multipeak_hem2 = zeros(app.evDet.NUS, 1);
                    deltaBP_hem2(1:app.dtaLen) = app.bandPassed_LFP(multiPeakBP,Ch_hem2, app.currentTrial, 1:app.dtaLen);
                    for usn2 = 1:app.evDet.NUS
                        cnt2 = 0;
                        start2 = app.evDet.upstates.fromI(usn2);
                        stop2 = app.evDet.upstates.toI(usn2);
                        pks2 = findpeaks(-deltaBP_hem2(start2:stop2));
                        if isempty(pks2)
                            cnt2 = 0;
                        else
                            cnt2 = length(pks2);
                        end
                    multipeak_hem2(usn2) = cnt2;
                    end 
                end
                
               % End of the finding of multipeak waves. 
               
               % Didi February 2019: Finding slopes of the slow waves. Based on the set of three papers Tononi lab: Sleep Homeostasis and Cortical 
               % Synchronization. Here, we take the 0.5-4 Hz (BP1) filtered LFP, where an up state is represented as a negative delfection. We define
               % both the average slope, and the maximum slope of the first and second segment of both hemispheres. Average slope is defined as the 
               % amplitude of the most negative peak, divided by the time of the previous zero crossing till the peak (for the first segment) or from 
               % the peak till the next zero crossing (for the second segment). Maximum slopes were defined as the maximum of the signal derivative 
               % (after applying a 1 millisecond moving average) following the negative zero crossing (first-segment slope) but before the most negative
               % peak, or subsequent to the most negative peak (second-segment slope) but prior to the positive-going zero crossing.
               
               % Start with the current channel (hemisphere)             
               
               
               % Set the parameters               
               avslopesegment1 = zeros(app.evDet.NUS, 1);
               maxslopesegment1 = zeros(app.evDet.NUS, 1);                              
               avslopesegment2 = zeros(app.evDet.NUS, 1);
               maxslopesegment2 = zeros(app.evDet.NUS, 1);              
               deltaBP(1:app.dtaLen) = app.bandPassed_LFP(multiPeakBP,app.currentCh, app.currentTrial, 1:app.dtaLen);
               
               % start looping through all the up states
               for usn = 1:app.evDet.NUS                    
                    st = app.evDet.upstates.fromI(usn);
                    en = app.evDet.upstates.toI(usn);
                    [pks, loc] = findpeaks(-deltaBP(st:en)); % pks contains the amplitude of the peaks, loc contains the index of the peaks
                    if isempty(pks) % if there are no peaks, the average and max slopes are 0
                        avslopesegment1(usn) = 0;
                        maxslopesegment1(usn) = 0;
                        avslopesegment2(usn) = 0;
                        maxslopesegment2(usn) = 0;
                    else
                        [mx, indexmx] = max(pks); % indexmx now contains contains the index of pks and loc with the largest peak
                        locmx = loc(indexmx)+(st-1); % locmx now contains the index of the most negative peak
                        
                        % first find first segment
                        posfirst = find(deltaBP(st:locmx)>=0)+(st-1); % contains all the indexes of delta BP higher than 0 before the most negative peak
                        if isempty(posfirst) % if there are no values above 0, we take the max value of the BP1 LFP
                            [zero1, Izero1] = max(deltaBP(st:locmx));
                            Izerocrossing1 = Izero1+(st-1);
                            timezerocrossing1 = Izerocrossing1*spp;
                            Ipeaktozerocrossing1 = locmx-Izerocrossing1;
                            timepeaktozerocrossing1 = Ipeaktozerocrossing1*spp;  
                        else                  
                            distance1 = locmx-posfirst;
                            Ipeaktozerocrossing1 = min(distance1);
                            timepeaktozerocrossing1 = Ipeaktozerocrossing1*spp;
                            Izerocrossing1 = locmx-min(distance1);
                            timezerocrossing1 = Izerocrossing1*spp;
                        end
                        avslopesegment1(usn) = (-pks(indexmx)-deltaBP(Izerocrossing1))/timepeaktozerocrossing1;
                        
                        % same for the second segment
                        possecond = find(deltaBP(locmx:en)>=0)+(locmx-1); % contains all the indexes of delta BP higher than 0 after the most negative peak
                        if isempty(possecond) % if there are no values above 0, we take the max value of the BP1 LFP
                            [zero2, Izero2] = max(deltaBP(locmx:en));
                            Izerocrossing2 = Izero2+(locmx-1);
                            timezerocrossing2 = Izerocrossing2*spp;
                            Ipeaktozerocrossing2 = Izerocrossing2-locmx;
                            timepeaktozerocrossing2 = Ipeaktozerocrossing2*spp;  
                        else                  
                            distance2 = possecond-locmx;
                            Ipeaktozerocrossing2 = min(distance2);
                            timepeaktozerocrossing2 = Ipeaktozerocrossing2*spp;
                            Izerocrossing2 = locmx+min(distance2);
                            timezerocrossing2 = Izerocrossing2*spp;
                        end
                        avslopesegment2(usn) = (deltaBP(Izerocrossing2)+pks(indexmx))/timepeaktozerocrossing2;                        
                        
                        % then we need to find the max slope. I want to have a moving (non-overlapping) average of 1 ms steps
                        stepT = 1e-3; % so the time step is 1 ms
                        stepI = stepT/spp; % then the step size in index number is this
                        dervector1 = [];
                        dervector2 = [];
                        
                        % First segment 1
                        cnt1 = 1; %set a count value that keeps track of each new time step
                        for ind1 = Izerocrossing1+stepI:stepI:locmx
                            av1 = mean(deltaBP(ind1-stepI:ind1));
                            dervector1(cnt1) = av1;
                            cnt1 = cnt1+1;
                        end
                        % Now dervector contains the average delta power of 1 ms periods
                        derivative1 = diff(dervector1)./stepT; % diff finds the difference between all the points in the input data (so if input data is
                        % is x, diffX) = x(2)-x(1), x(3)-x(2), etc. of length x-1. So if we divide this by the time step between x(2) and x(1), in our case stepT
                        % we found the derivative 
                        if isempty(derivative1)
                            maxslopesegment1(usn) = 0;
                        else
                            maxslopesegment1(usn) = min(derivative1); % minimun since negative slope so it should be below 0
                        end
                        
                        % Then for the second segment
                        cnt2 = 1; %set a count value that keeps track of each new time step
                        for ind2 = locmx+stepI:stepI:Izerocrossing2
                            av2 = mean(deltaBP(ind2-stepI:ind2));
                            dervector2(cnt2) = av2;
                            cnt2 = cnt2+1;
                        end
                        % Now dervector contains the average delta power of 1 ms periods
                        derivative2 = diff(dervector2)./stepT; % diff finds the difference between all the points in the input data (so if input data is
                        % is x, diffX) = x(2)-x(1), x(3)-x(2), etc. of length x-1. So if we divide this by the time step between x(2) and x(1), in our case stepT
                        % we found the derivative
                        if isempty(derivative2)
                            maxslopesegment2(usn) = 0;
                        else
                            maxslopesegment2(usn) = max(derivative2); % maximum since positive slope so it should be above 0
                        end
                    end
                end 
                
                % Now the the other hemisphere
                if app.nCh == 2
                    % Set parameters
                    avslopesegment1_hem2 = zeros(app.evDet.NUS, 1);
                    maxslopesegment1_hem2 = zeros(app.evDet.NUS, 1);                              
                    avslopesegment2_hem2 = zeros(app.evDet.NUS, 1);
                    maxslopesegment2_hem2 = zeros(app.evDet.NUS, 1);              
                    deltaBP_hem2(1:app.dtaLen) = app.bandPassed_LFP(multiPeakBP, Ch_hem2, app.currentTrial, 1:app.dtaLen);
                    
                    
                    % start looping through all the up states
                    for usn = 1:app.evDet.NUS                    
                        st = app.evDet.upstates.fromI(usn);
                        en = app.evDet.upstates.toI(usn);
                        [pks, loc] = findpeaks(-deltaBP_hem2(st:en)); 
                        if isempty(pks) 
                            avslopesegment1_hem2(usn) = 0;
                            maxslopesegment1_hem2(usn) = 0;
                            avslopesegment2_hem2(usn) = 0;
                            maxslopesegment2_hem2(usn) = 0;
                        else
                            [mx, indexmx] = max(pks); 
                            locmx = loc(indexmx)+(st-1); 
                        
                            % first find first segment
                            posfirst = find(deltaBP_hem2(st:locmx)>=0)+(st-1); 
                            if isempty(posfirst) 
                                 [zero1, Izero1] = max(deltaBP_hem2(st:locmx));
                                 Izerocrossing1 = Izero1+(st-1);
                                 timezerocrossing1 = Izerocrossing1*spp;
                                 Ipeaktozerocrossing1 = locmx-Izerocrossing1;
                                 timepeaktozerocrossing1 = Ipeaktozerocrossing1*spp;  
                            else                  
                                 distance1 = locmx-posfirst;
                                 Ipeaktozerocrossing1 = min(distance1);
                                 timepeaktozerocrossing1 = Ipeaktozerocrossing1*spp;
                                 Izerocrossing1 = locmx-min(distance1);
                                 timezerocrossing1 = Izerocrossing1*spp;
                            end
                            avslopesegment1_hem2(usn) = (-pks(indexmx)-deltaBP_hem2(Izerocrossing1))/timepeaktozerocrossing1;
                        
                            % same for the second segment
                            possecond = find(deltaBP_hem2(locmx:en)>=0)+(locmx-1); 
                            if isempty(possecond) 
                                [zero2, Izero2] = max(deltaBP_hem2(locmx:en));
                                Izerocrossing2 = Izero2+(locmx-1);
                                timezerocrossing2 = Izerocrossing2*spp;
                                Ipeaktozerocrossing2 = Izerocrossing2-locmx;
                                timepeaktozerocrossing2 = Ipeaktozerocrossing2*spp;  
                            else                  
                                distance2 = possecond-locmx;
                                Ipeaktozerocrossing2 = min(distance2);
                                timepeaktozerocrossing2 = Ipeaktozerocrossing2*spp;
                                Izerocrossing2 = locmx+min(distance2);
                                timezerocrossing2 = Izerocrossing2*spp;
                            end
                            avslopesegment2_hem2(usn) = (deltaBP_hem2(Izerocrossing2)+pks(indexmx))/timepeaktozerocrossing2;                        
                        
                            % then we need to find the max slope. I want to have a moving (non-overlapping) average of 1 ms steps
                            stepT = 1e-3; % so the time step is 1 ms
                            stepI = stepT/spp; % then the step size in index number is this
                            dervector1_hem2 = [];
                            dervector2_hem2 = [];
                        
                            % First segment 1
                            cnt1 = 1; 
                            for ind1 = Izerocrossing1+stepI:stepI:locmx
                                av1 = mean(deltaBP_hem2(ind1-stepI:ind1));
                                dervector1_hem2(cnt1) = av1;
                                cnt1 = cnt1+1;
                            end
                            derivative1_hem2 = diff(dervector1_hem2)./stepT;
                            if isempty(derivative1_hem2)
                                 maxslopesegment1_hem2(usn) = 0;
                            else
                                 maxslopesegment1_hem2(usn) = min(derivative1_hem2); 
                            end
                        
                            % Then for the second segment
                            cnt2 = 1; 
                            for ind2 = locmx+stepI:stepI:Izerocrossing2
                                av2 = mean(deltaBP_hem2(ind2-stepI:ind2));
                                dervector2_hem2(cnt2) = av2;
                                cnt2 = cnt2+1;
                            end                        
                            derivative2_hem2 = diff(dervector2_hem2)./stepT;
                            if isempty(derivative2_hem2)
                                maxslopesegment2_hem2(usn) = 0;
                            else
                                maxslopesegment2_hem2(usn) = max(derivative2_hem2); 
                            end
                        end
                    end
                end   
                
                % Didi February 2019: Add the number of peaks of the up state and the slopes to the US table
                
                for usi = 1:app.evDet.NUS
                   app.evDet.USlist (usi,11) = num2cell(multipeak(usi));
                   app.evDet.USlist (usi,13) = num2cell(avslopesegment1(usi));
                   app.evDet.USlist (usi,14) = num2cell(maxslopesegment1(usi));
                   app.evDet.USlist (usi,15) = num2cell(avslopesegment2(usi));
                   app.evDet.USlist (usi,16) = num2cell(maxslopesegment2(usi));
                   if app.nCh == 2
                       app.evDet.USlist (usi,12) = num2cell(multipeak_hem2(usi)); 
                       app.evDet.USlist (usi,17) = num2cell(avslopesegment1_hem2(usi));
                       app.evDet.USlist (usi,18) = num2cell(maxslopesegment1_hem2(usi));
                       app.evDet.USlist (usi,19) = num2cell(avslopesegment2_hem2(usi));
                       app.evDet.USlist (usi,20) = num2cell(maxslopesegment2_hem2(usi));
                   end
                end


                % Add the up state info to the table in the GUI
                set(app.UStable,'Data',app.evDet.USlist);
                        
                % plot the state IDs
                plotStatesFun (app)
            
                % create the joined files
                app.evDet.US = [];
                app.evDet.DS = [];
            
                ifrom = app.evDet.upstates.fromI(1);
                ito = app.evDet.upstates.toI(1);
                app.evDet.US = app.evDet.LFP(ifrom:ito);
            
                for usi=2:nus
                    ifrom = app.evDet.upstates.fromI(usi);
                    ito = app.evDet.upstates.toI(usi);
                    if get(app.joinOffsetChk,'Value')
                        offset = app.evDet.LFP(ifrom)-app.evDet.US(end);
                        app.evDet.US = [app.evDet.US app.evDet.LFP(ifrom:ito)-offset];
                    end
                    if get(app.joinCk,'Value'), app.evDet.US = [app.evDet.US tempData(ifrom:ito)];
                    end
                end
            
                for usi=1:nds
                    ifrom = app.evDet.downstates.fromI(usi);
                    ito = app.evDet.downstates.toI(usi);
                    if get(app.joinOffsetChk,'Value') %Gab
                        if ~isempty(app.evDet.DS) %Gab
                            offset = app.evDet.LFP(ifrom)-app.evDet.DS(end); %Gab
                            app.evDet.DS = [app.evDet.DS app.evDet.LFP(ifrom:ito)-offset];    %Gab
                        else %Gab
                            app.evDet.DS = [app.evDet.DS app.evDet.LFP(ifrom:ito)]; %Gab
                        end %Gab
                    else %Gab
                    app.evDet.DS = [app.evDet.DS app.evDet.LFP(ifrom:ito)];
                    end
                end
            
                % and finally lets compute the power spectra!
                app.params = struct('tapers',[],'Fs',1,'fpass',[]);
            
                flagChronux = get(app.ChronuxCk,'Value');
                if flagChronux
                    params.tapers = [5 9] ;%[5 9];
                    params.Fs = app.acqF;
                    params.fpass = [0 1000];
                    [app.evDet.pssUS,app.evDet.fUS] = mtspectrumc(app.evDet.US, params);
                    app.evDet.fUS = app.evDet.fUS';
                    [app.evDet.pssDS,app.evDet.fDS] = mtspectrumc(app.evDet.DS, params);
                    app.evDet.fDS = app.evDet.fDS';
                else
                    [app.evDet.pssUS,app.evDet.fUS] = pwelch(app.evDet.US,[],[],[],app.acqF);
                    [app.evDet.pssDS,app.evDet.fDS] = pwelch(app.evDet.DS,[],[],[],app.acqF);
                end
                %lUS = size(app.evDet.fUS);
                %lDS = size(app.evDet.fDS);
            
%                 axes(app.powerSpectra);
%                 hold on;
%                 lim = axis;
%                 cla
                cla(app.powerSpectra);
                lim = [app.powerSpectra.XLim, app.powerSpectra.YLim];
                
                %flagN = get(app.spectreData.normSpectraCk,'Value');
                % plot the mean traces
                plot(app.powerSpectra,app.evDet.fUS,10*log10(app.evDet.pssUS.'),'red');            % spectra in decibels
                plot(app.powerSpectra,app.evDet.fDS,10*log10(app.evDet.pssDS.'),'green');
            
                lim(1) = get(app.minFreq,'Value');
                lim(2) = get(app.maxFreq,'Value');
                lim(3) = get(app.minPw,'Value');
                lim(4) = get(app.maxPw,'Value');
                %axis (lim);
                app.powerSpectra.XLim = [lim(1) lim(2)];
                app.powerSpectra.YLim = [lim(3) lim(4)];
            
                set(app.powerSpectra,'XScale','log');
                app.powerSpectra.XLabel.String = 'Frequency (Hz)';
                app.powerSpectra.YLabel.String ='Magnitude (dB)';
            else
                errStr = [app.detectionBP_dropDown.Value ' is not a valid source.'];
                error(errStr)
            end
            disp('Computation Up/Down states finished');
        end

        % Button pushed function: replot
        function replot_Callback(app, event)
            plotStatesFun(app)
        end

        % Button pushed function: saveHCD
        function saveHCD_Callback(app, event)
            % Save the distributions of the integrated spectral power
            % March 22, 2017.
            
            tmpOut1 = [];
            
            cTr = app.currentTrial;
            cCh = app.currentCh;
            
            fileOut = [app.dir_in 'PWSdistribution Ch' num2str(cCh) ' Trial' num2str(cTr) '.dat'];
            tmpOut1 (1,:) = app.evDet.Hcenters;
            tmpOut1 (2,:) = app.evDet.Hnelements;
            tmpOut1 (3,:) = app.evDet.normNelements;
            tmpOut1 (4,:) = app.evDet.cdist;
            tmpOut1 (5,:) = app.evDet.cdistN;
            
            % create the format descriptor
            fmtSt=['%12.10f %12.10f %12.10f %12.10f %12.10f\n'];  % 5 columns and start new line
            fid = fopen(fileOut,'w');
            fprintf (fid,fmtSt,tmpOut1);
            fclose(fid);
        end

        % Button pushed function: savePWS
        function savePWS_Callback(app, event)
            % save as two different files the PS computed from the defragmented UP and
            % Downstate cumulative files.
                        
            cTr = app.currentTrial;
            cCh = app.currentCh;
            
            fileOutUS = [app.dir_in 'USpws Ch' num2str(cCh) ' Trial' num2str(cTr) '.dat'];
            fileOutDS = [app.dir_in 'DSpws Ch' num2str(cCh) ' Trial' num2str(cTr) '.dat'];
            usl = length (app.evDet.fUS);
            tmpOut1 = [];
            tmpOut2 = [];
            % US and DS have to be saved as separate files since they have different x
            % axis due to the variable length of the consolidated data.
            tmpOut1 (1,:) = app.evDet.fUS;
            tmpOut1 (2,:) = app.evDet.pssUS;
            tmpOut1 (3,:) = 10*log10(app.evDet.pssUS);
            
            tmpOut2 (1,:) = app.evDet.fDS;
            tmpOut2 (2,:) = app.evDet.pssDS;
            tmpOut2 (3,:) = 10*log10(app.evDet.pssDS);
            
            % create the format descriptor
            fmtSt=['%12.10f %12.10f %12.10f\n'];       % 3 columns and start new line
            fid = fopen(fileOutUS,'w');
            fprintf (fid,fmtSt,tmpOut1);
            fclose(fid);
            fid = fopen(fileOutDS,'w');
            fprintf (fid,fmtSt,tmpOut2);
            fclose(fid);
        end

        % Button pushed function: saveSegmentedStates
        function saveSegmentedStates_Callback(app, event)
            %GAB: saves 2 separate files for up & down states
            currCh=num2str(app.currentCh);
            currTr=num2str(app.currentTrial);
            fmt='%8.6f \n';
            fid=fopen([app.dir_in 'DS_ch' currCh '_tr' currTr '.dat'],'w');
            fprintf(fid,fmt,app.evDet.DS);
            fid=fopen([app.dir_in 'US_ch' currCh '_tr' currTr '.dat'],'w');
            fprintf(fid,fmt,app.evDet.US);
            fclose(fid);
        end

        % Button pushed function: traslateLeft
        function traslateLeft_Callback(app, event)
            app.evDet.tmin = get(app.leftTime,'Value');
            app.evDet.tmax = get(app.rightTime,'Value');
            delta = app.evDet.tmax - app.evDet.tmin;
            
            set(app.leftTime,'Value',app.evDet.tmin-delta);
            set(app.rightTime,'Value',app.evDet.tmin);
            
            plotStatesFun (app)
        end

        % Button pushed function: traslateRight
        function traslateRight_Callback(app, event)
            app.evDet.tmin = get(app.leftTime,'Value');
            app.evDet.tmax = get(app.rightTime,'Value');
            delta = app.evDet.tmax - app.evDet.tmin;
            
            set(app.leftTime,'Value',app.evDet.tmax);
            set(app.rightTime,'Value',app.evDet.tmax+delta);
            
            plotStatesFun(app)
        end

        % Value changed function: yminTxt
        function yminTxt_Callback(app, event)
%             event = [];
%             event.yLim = [app.yminTxt.Value, app.ymaxTxt.Value];
%             plotWideWindow(app,event)
%             plotMultiBand(app)
%             plotSpectrogram(app)
            plotWideWindow(app)
        end

        % Button pushed function: ResetButton
        function ResetButton_Callback(app, event)
            app.ymaxTxt.Value = inf;
            app.yminTxt.Value = -inf; 
            plotWideWindow(app)
        end

        % Button pushed function: CopyTableButton
        function CopyTableButtonPushed(app, event)
            % To copy the data from the table to the clipboard
            tablesize = size(app.evDet.USlist);
            str = '';
            for i = 1:tablesize(1)
                for j = 1:tablesize(2)
                    if j == tablesize(2)
                        str = sprintf('%s%f',str,app.evDet.USlist{i,j});
                    else
                        str = sprintf('%s%f\t',str,app.evDet.USlist{i,j});
                    end
                end
                str = sprintf('%s\n',str);
            end
            clipboard ('copy',str);
     

        end

        % Button pushed function: SaveStartImagingButton
        function SaveStartImagingButtonPushed(app, event)
           pos = find(app.LFP(2,1,:)>=0); 
           start = (pos(1)*app.sp)-app.sp
           stop = (pos(end)*app.sp)-app.sp 
        end

        % Button pushed function: CopytableButton
        function CopytableButtonPushed(app, event)
            % To copy the data from the table of zebrasays to the clipboard
            tablesize = size(app.outable.Data);
            
            % Enrico 20190409 
            % Created a function to copy data to clipboard from outtable.
            % selectionIndices is a 4-element vector containing the
            % upper-left corner and the bottom-right corner indices
            SelTab2Clip(app,app.outable,[1 1 tablesize(1) tablesize(2)])
        end

        % Button pushed function: sepochsButton
        function sepochsButtonPushed(app, event)
            sfrom = 0;
            sto = 30;
            app.tminTxt.Value = sfrom;
            app.tmaxTxt.Value = sto;
            for ii = 1:(app.x(end)/30)+1
                if ii > 1
                    sfrom = sfrom + 30;
                    sto = sto + 30;
                else                    
                end
                %if sto > app.x(end)
                %    break
                %end  
                [v ifrom] = min(abs(app.x-sfrom));
                [v2 ito] = min(abs(app.x-sto));                      
                savetr_name_1 = num2str(sfrom);
                savetr_name_2 = num2str(sto); 
                fileOut = [app.dir_in 'trace_' app.file_in '_' savetr_name_1 '-' savetr_name_2 '.dat'];                               
                tmpOut (1:app.nCh,1:(ito-ifrom+1)) = app.workLFP (1:app.nCh,app.currentTrial,ifrom:ito);
                % create the format descriptor
                str = ''; 
                for row = 1:length(tmpOut)
                    for column = 1:app.nCh
                        if column == app.nCh
                            str = sprintf('%s%f',str, tmpOut(column, row));
                        else
                            str = sprintf('%s%f\t',str, tmpOut(column, row));
                        end
                    end
                    str = sprintf('%s\n',str);
                end             
                fid = fopen(fileOut,'w');
                fprintf(fid, str);
                fclose(fid);
            end            
            app.tminTxt.Value = sfrom;
            app.tmaxTxt.Value = sto;
            display('saving done!')
        end

        % Cell selection callback: outable
        function outableCellSelection(app, event)
            app.outtableSelectedIndices = event.Indices;
            app.CopyOutableSelection.Enable = 1;
            app.DeleteSelRows.Enable = 1;
        end

        % Button pushed function: CopyOutableSelection
        function CopyOutableSelectionButtonPushed(app, event)
            selectedIndices = [min(app.outtableSelectedIndices(:,1)) min(app.outtableSelectedIndices(:,2))...
                max(app.outtableSelectedIndices(:,1)) max(app.outtableSelectedIndices(:,2))];
            SelTab2Clip(app,app.outable,selectedIndices)
        end

        % Button pushed function: DeleteSelRows
        function DeleteSelRowsButtonPushed(app, event)
            DeleteRowTable(app, app.outable, app.outtableSelectedIndices)
            app.outtableSelectedIndices = [];
            app.CopyOutableSelection.Enable = 0;
            app.DeleteSelRows.Enable = 0;
        end

        % Callback function
        function SelectBPsButtonPushed(app, event)
            nBP = app.NumberofBPsEditField.Value;
        % Create a cell array containing all the strings to be put aside each text box
            prompt = arrayfun(@(x) ['Bandpass n. ' num2str(x)], 1:nBP, 'un', 0);
            
            if ~isempty(app.BpTable.Data)
                % If app.AutoDlgIn is not empty, AutoDlg is used as preset values of the ZebraAuto folders
                preset = app.BpTable.Data;
            else
                % If there is nothing in app.AutoDlgIn, it is initialized as an empty cell array
                preset = cell(nBP, 2);
                preset(:) = {''};
            end
            
            title = 'Write low and high ends for each band';
            nlines = 1; % This is for dimensions of each line
            inputdlgcol(prompt,title,nlines,preset,'off',2);
            
            
        end

        % Button pushed function: CopyEvochedSelection
        function CopyEvochedSelectionButtonPushed(app, event)
            selectedIndices = [min(app.outEvochedSelectedIndices(:,1)) min(app.outEvochedSelectedIndices(:,2))...
                max(app.outEvochedSelectedIndices(:,1)) max(app.outEvochedSelectedIndices(:,2))];
            SelTab2Clip(app,app.outEvochedResponses,selectedIndices)
        end

        % Cell selection callback: outEvochedResponses
        function outEvochedResponsesCellSelection(app, event)
            app.outEvochedSelectedIndices = event.Indices;
            app.CopyEvochedSelection.Enable = 1;
        end

        % Cell selection callback: BpTable
        function BpTableCellSelection(app, event)
            app.BpTableSelIndices = event.Indices;
            app.DeleteBpBtn.Enable = 1;
            app.MoveUpBpBtn.Enable = 1;
            app.MoveDownBpBtn.Enable = 1;
        end

        % Button pushed function: DeleteBpBtn
        function DeleteBpBtnButtonPushed(app, event)
            toBeDeleted = app.BpTableSelIndices;    
            DeleteRowTable(app, app.BpTable, toBeDeleted)
            
            % update the number of BPs
            app.NumberofBPsEditField.Value = size(app.BpTable.Data, 1);
            % re-order the BP indices
            app.BpTable.Data(:,1) = (1:app.NumberofBPsEditField.Value)';
            % update the frequencyBand vector and all the other related variables 
            BPsynch(app,toBeDeleted(1))
            
%           Delete the bandpassed_LFP part that corresponds to the
%           deleted bands
            if ~isempty(app.bandPassed_LFP)
                app.bandPassed_LFP(min(app.BpTableSelIndices(:,1)):max(app.BpTableSelIndices(:,1)),:,:,:) = [];
            end
%             Delete the power in time of the deleted bands
            if app.fileInNum > 0
                app.BPpower(min(app.BpTableSelIndices(:,1)):max(app.BpTableSelIndices(:,1))) = [];
            end
            
            app.BpTableSelIndices = [];
            app.DeleteBpBtn.Enable = 0;
            app.MoveDownBpBtn.Enable = 0;
            app.MoveUpBpBtn.Enable = 0;
        end

        % Button pushed function: MoveUpBpBtn
        function MoveUpBpBtn_ButtonPushed(app, event)
%         The whole selected part is moved of one row up
        SelRowIndices = [min(app.BpTableSelIndices(:,1)), max(app.BpTableSelIndices(:,1))];
%             Execute only when the first selected row is not the first
            if SelRowIndices(1)~=1
%                 Now the row before the first selected one can be saved
%                 and the selected rows are shifted of one row up.
                tmpRow = app.BpTable.Data(SelRowIndices(1)-1, 2:end);
                app.BpTable.Data(SelRowIndices(1)-1 : SelRowIndices(2)-1, 2:end) = app.BpTable.Data(SelRowIndices(1):SelRowIndices(2), 2:end);
                app.BpTable.Data(SelRowIndices(2), 2:end) = tmpRow;
                if ~isempty(app.bandPassed_LFP)
    %             Move up the bandpassed_LFP part that corresponds to the
    %             selected bands
                    tmpLfp = app.bandPassed_LFP(SelRowIndices(1)-1,:,:,:);
                    app.bandPassed_LFP(SelRowIndices(1)-1 : SelRowIndices(2)-1,:,:,:) = app.bandPassed_LFP(SelRowIndices(1):SelRowIndices(2),:,:,:);
                    app.bandPassed_LFP(SelRowIndices(2),:,:,:) = tmpLfp;
                end
    %             Move up the power in time of the selected bands
                if app.fileInNum > 0
                    tmpPower = app.BPpower(SelRowIndices(1)-1);
                    app.BPpower(SelRowIndices(1)-1:SelRowIndices(2)-1) = app.BPpower(SelRowIndices(1):SelRowIndices(2));
                    app.BPpower(SelRowIndices(2)) = tmpPower;
                end
            end
        end

        % Button pushed function: MoveDownBpBtn
        function MoveDownBpBtn_ButtonPushed(app, event)
%         The whole selected part is moved of one row down
        SelRowIndices = [min(app.BpTableSelIndices(:,1)), max(app.BpTableSelIndices(:,1))];
%             Execute only when the first selected row is not the last one
            if SelRowIndices(2) ~= size(app.BpTable.Data,1)
%                 Now the row after the last selected one can be saved
%                 and the selected rows are shifted of one row down.
                tmpRow = app.BpTable.Data(SelRowIndices(2)+1, 2:end);
                app.BpTable.Data(SelRowIndices(1)+1 : SelRowIndices(2)+1, 2:end) = app.BpTable.Data(SelRowIndices(1):SelRowIndices(2), 2:end);
                app.BpTable.Data(SelRowIndices(1), 2:end) = tmpRow;
                
                if ~isempty(app.bandPassed_LFP)
%               Move down the bandpassed_LFP part that corresponds to the
%               deleted bands
                    tmpLfp = app.bandPassed_LFP(SelRowIndices(2)+1,:,:,:);
                    app.bandPassed_LFP(SelRowIndices(1)+1 : SelRowIndices(2)+1,:,:,:) = app.bandPassed_LFP(SelRowIndices(1):SelRowIndices(2),:,:,:);
                    app.bandPassed_LFP(SelRowIndices(1),:,:,:) = tmpLfp;
                end
%               Move down the power in time of the deleted bands
                if app.fileInNum > 0
                    tmpPower = app.BPpower(SelRowIndices(1)+1);
                    app.BPpower(SelRowIndices(1)+1:SelRowIndices(2)+1) = app.BPpower(SelRowIndices(1):SelRowIndices(2));
                    app.BPpower(SelRowIndices(1)) = tmpPower;
                end
            end
        end

        % Cell edit callback: BpTable
        function BpTable_CellEdit(app, event)
            if event.NewData < 0
                uialert(app.ZebraMainFig,'You have to type a positive number.','Invalid input')
                app.BpTable.Data(event.Indices(1),event.Indices(2)) = 0;
            end
            if ~isscalar(event.NewData) || isnan(event.NewData)
                uialert(app.ZebraMainFig,'Input has to be a scalar number.','Invalid input')
                app.BpTable.Data(event.Indices(1),event.Indices(2)) = event.PreviousData;
            end
            if (event.NewData > 1/app.sp/2) && (event.NewData ~= inf)
                uialert(app.ZebraMainFig,'You cannot exceed Nyquist frequency.','Invalid input')
                app.BpTable.Data(event.Indices(1),event.Indices(2)) = 1/app.sp/2;
            end
            
            BPsynch(app,0) % call the synch function in "zero mode". See the function description for details.
        end

        % Value changed function: NumberofBPsEditField
        function NumberofBPsEditField_ValueChanged(app, event)
            nBP = app.NumberofBPsEditField.Value;
            if size(app.BpTable.Data,1) > nBP
                app.BpTable.Data = app.BpTable.Data(1:nBP, :);
            else
                newTab = [(1:nBP)', ones(nBP,1), zeros(nBP,1), ones(nBP,1)*app.acqF/2];
                newTab(1:size(app.BpTable.Data),:) = app.BpTable.Data;
                app.BpTable.Data = newTab;
            end
            BPsynch(app,inf)
        end

        % Value changed function: BPplot_ck
        function BPplot_ck_ValueChanged(app, event)
            
            % Find the selected BP
            selBP = find(strcmp(app.BPplot_drop.Value,app.BPplot_drop.Items));
            
            % Update BP_4_plot
            app.BP_4_plot(selBP) = app.BPplot_ck.Value;
            
            % Update BP and Power plot
            if app.fileInNum
                plotMultiBand(app)
                if app.BP.Value
                    plotSpectrogram(app)
                end
            end
        end

        % Button pushed function: BPplotColor_btn
        function BPplotColor_btn_ButtonPushed(app, event)
            % Find the selected BP
            selBP = find(strcmp(app.BPplot_drop.Value,app.BPplot_drop.Items));
            
            % select a color
            c = uisetcolor; % color is returned as an r-g-b triplet with 0-1 range
            figure(app.ZebraMainFig)
            
            % Update BP_color
            app.BP_color(selBP,:) = c;
            
            % Update text color
            app.BPplotColor_btn.FontColor = c;
            
            % Update BP and Power plot
            if app.fileInNum
                plotMultiBand(app)
                if app.BP.Value
                    plotSpectrogram(app)
                end
            end
        end

        % Value changed function: BPplot_drop
        function BPplot_drop_ValueChanged(app, event)
            % Find the selected BP
            selBP = find(strcmp(app.BPplot_drop.Value,app.BPplot_drop.Items));
            
            % update the selection button
            app.BPplot_ck.Value = app.BP_4_plot(selBP);
            
            % show the color on the color button
            app.BPplotColor_btn.FontColor = app.BP_color(selBP,:);            
        end

        % Cell edit callback: PlotScaleTab
        function PlotScaleTab_CellEdit(app, event)
%             indices = event.Indices;
%             newData = event.NewData;
            app.autoscaleYck.Value = 0;
            plotWideWindow(app)
        end

        % Button pushed function: DefaultColorsButton
        function DefaultColors_ButtonPushed(app, event)
            % reset BP colors to default colors
            nBP = size(app.BP_color,1);
            app.BP_color = app.BP_color_default(1:nBP,:);
            
            % Find the selected BP
            selBP = find(strcmp(app.BPplot_drop.Value,app.BPplot_drop.Items));
            % update ColorButton color
            app.BPplotColor_btn.FontColor = app.BP_color(selBP,:);
            
            % Update BP and Power plot
            if app.fileInNum
                plotMultiBand(app)
                if app.BP.Value
                    plotSpectrogram(app)
                end
            end
        end

        % Button pushed function: BPplotColorShow_btn
        function BPplotColorShow_btn_ButtonPushed(app, event)
            % Visualize BP colors and selection state in a separate window
            
            % Store app.BP_4_plot in a table called t
            t = table(app.BP_4_plot,'RowNames',app.BPplot_drop.Items,'VariableNames',{'Selected'});
            
            % Open a new figure in a specified position
            pos = app.ZebraMainFig.Position;
            f = figure('Name','Colors','Position',[pos(1),pos(2),round(pos(3)/5),round(pos(4)*0.5)]);
            f.MenuBar = 'none';
            f.ToolBar = 'none';
            
            % show the table t as a graphic object
            T = uitable('Data',t{:,:},'ColumnName',{'Selected'},...
                 'RowName',app.BPplot_drop.Items,'Units', 'Normalized', 'Position',[0, 0, 1, 1]);
            T.ColumnWidth = {100};
            T.FontSize = 15;
            T.BackgroundColor = app.BP_color;
            T.ForegroundColor = [1,1,1]; % white font color
            T.ColumnEditable = true;
            
            % This way BP_4_plot can be edited from the table.
            T.CellEditCallback = {@app.BP_4_plot_updateFcn};
        end

        % Value changed function: autoscaleYck
        function autoscaleYck_ValueChanged(app, event)
            %  2019/05/29 Gab. Autoscale channels so that they fit properly in the same multiple
            % channel plot.   
            % This function scales all the channels to the currently selected channel and store 
            % the scale coefficients in the table named PlotScaleTab.
            if app.autoscaleYck.Value
                selCh = app.currentCh;
                ranges = max(app.LFP,[],[2,3]) - min(app.LFP,[],[2,3]); % the range of the LFP for each channel
                scales = ranges ./ ranges(selCh);
                app.PlotScaleTab.Data(:,2) = 1./scales;
                
                % if Multi channel plot is selected, plot the LFP
                if app.plotMultiCh.Value
                    plotWideWindow(app)
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ZebraMainFig = uifigure('Visible', 'off');
            app.ZebraMainFig.Name = 'ZebraExplore';
            app.TabGroup = uitabgroup(app.ZebraMainFig);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.plotSlate = uipanel(app.PlotTab);
            app.plotSlate.Title = 'Plot Window';
            app.wide_plot_HP = uiaxes(app.plotSlate);
            app.wide_plot_HP.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.wide_plot_HP.XTickLabel = '0';
            app.tminTxt = uieditfield(app.plotSlate, 'numeric');
            app.tminTxt.ValueChangedFcn = createCallbackFcn(app, @tminTxt_Callback, true);
            app.tminTxt.Value = 5;
            app.spectraWide = uiaxes(app.plotSlate);
            app.spectraWide.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.spectraWide.XTickLabel = '0';
            app.wide_plot_up = uiaxes(app.plotSlate);
            app.ymaxTxt = uieditfield(app.plotSlate, 'numeric');
            app.ymaxTxt.ValueChangedFcn = createCallbackFcn(app, @ymaxTxt_Callback, true);
            app.ymaxTxt.Value = Inf;
            app.yminTxt = uieditfield(app.plotSlate, 'numeric');
            app.yminTxt.ValueChangedFcn = createCallbackFcn(app, @yminTxt_Callback, true);
            app.yminTxt.Value = -Inf;
            app.ResetButton = uibutton(app.plotSlate, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_Callback, true);
            app.ResetButton.Text = {'R'; 'e'; 's'; 'e'; 't'};
            app.scrollLeft = uibutton(app.plotSlate, 'push');
            app.scrollLeft.ButtonPushedFcn = createCallbackFcn(app, @scrollLeft_Callback, true);
            app.scrollLeft.Text = '< Scroll ';
            app.scrollRight = uibutton(app.plotSlate, 'push');
            app.scrollRight.ButtonPushedFcn = createCallbackFcn(app, @scrollRight_Callback, true);
            app.scrollRight.Text = 'Scroll > ';
            app.tResetBtn = uibutton(app.plotSlate, 'push');
            app.tResetBtn.ButtonPushedFcn = createCallbackFcn(app, @tResetBtn_Callback, true);
            app.tResetBtn.Text = 'Reset T';
            app.tmaxTxt = uieditfield(app.plotSlate, 'numeric');
            app.tmaxTxt.ValueChangedFcn = createCallbackFcn(app, @tmaxTxt_Callback, true);
            app.tmaxTxt.Value = 5;
            app.sendToClipboard = uibutton(app.plotSlate, 'push');
            app.sendToClipboard.ButtonPushedFcn = createCallbackFcn(app, @sendToClipboard_Callback, true);
            app.sendToClipboard.Tooltip = 'Copy the inset widows to the system clipboard';
            app.sendToClipboard.Text = 'Clipboard';
            app.uipanel5 = uipanel(app.PlotTab);
            app.uipanel5.Title = 'Save Output';
            app.saveCurrentCh = uibutton(app.uipanel5, 'push');
            app.saveCurrentCh.ButtonPushedFcn = createCallbackFcn(app, @saveCurrentCh_Callback, true);
            app.saveCurrentCh.Tooltip = 'Save data in the current view ';
            app.saveCurrentCh.Text = 'Trace';
            app.saveSPG = uibutton(app.uipanel5, 'push');
            app.saveSPG.ButtonPushedFcn = createCallbackFcn(app, @saveSPG_Callback, true);
            app.saveSPG.Text = 'SPG';
            app.saveBP = uibutton(app.uipanel5, 'push');
            app.saveBP.ButtonPushedFcn = createCallbackFcn(app, @saveBP_Callback, true);
            app.saveBP.Tooltip = 'Save the BP filtered data ONLY within the display window. Sensitive to multi-channel flag.';
            app.saveBP.Text = 'BP';
            app.sepochsButton = uibutton(app.uipanel5, 'push');
            app.sepochsButton.ButtonPushedFcn = createCallbackFcn(app, @sepochsButtonPushed, true);
            app.sepochsButton.Text = '30 s epochs';
            app.TabGroup2 = uitabgroup(app.PlotTab);
            app.InputDataTab = uitab(app.TabGroup2);
            app.InputDataTab.Title = 'Input Data';
            app.select_data = uibutton(app.InputDataTab, 'push');
            app.select_data.ButtonPushedFcn = createCallbackFcn(app, @select_data_Callback, true);
            app.select_data.Text = 'Open';
            app.excludeChFlag = uicheckbox(app.InputDataTab);
            app.excludeChFlag.Text = 'Exclude Ch:';
            app.internalSynchCk = uicheckbox(app.InputDataTab);
            app.internalSynchCk.Text = 'Synch on Ch:';
            app.uipanel15 = uibuttongroup(app.InputDataTab);
            app.mVck = uiradiobutton(app.uipanel15);
            app.mVck.Tooltip = 'Click here if data are expressed in mV';
            app.mVck.Text = 'mV';
            app.mVck.Value = true;
            app.microVck = uiradiobutton(app.uipanel15);
            app.microVck.Tooltip = 'Click here if data are expressed in micro V';
            app.microVck.Text = 'μV';
            app.autoclear = uicheckbox(app.InputDataTab);
            app.autoclear.Tooltip = 'Clear spreadsheet in Zebra Says when a new data is loaded in';
            app.autoclear.Text = 'Autoclear';
            app.autoclear.Value = true;
            app.openDir = uibutton(app.InputDataTab, 'push');
            app.openDir.ButtonPushedFcn = createCallbackFcn(app, @openDir_Callback, true);
            app.openDir.Text = 'Open Dir';
            app.excludeCh = uieditfield(app.InputDataTab, 'numeric');
            app.excludeCh.Value = 2;
            app.synchCh = uieditfield(app.InputDataTab, 'numeric');
            app.synchCh.Value = 2;
            app.TrialtimeoffsetsLabel = uilabel(app.InputDataTab);
            app.TrialtimeoffsetsLabel.Text = 'Trial time offset (s):';
            app.trTimeOffset_txt = uieditfield(app.InputDataTab, 'numeric');
            app.SamplingsEditFieldLabel = uilabel(app.InputDataTab);
            app.SamplingsEditFieldLabel.Text = 'Sampling (s):';
            app.spEdit = uieditfield(app.InputDataTab, 'numeric');
            app.spEdit.ValueChangedFcn = createCallbackFcn(app, @spEdit_Callback, true);
            app.spEdit.Value = 0.0001;
            app.NChannelsLabel = uilabel(app.InputDataTab);
            app.NChannelsLabel.Text = 'N Channels:';
            app.chEdit = uieditfield(app.InputDataTab, 'numeric');
            app.chEdit.ValueChangedFcn = createCallbackFcn(app, @chEdit_Callback, true);
            app.chEdit.Value = 2;
            app.TrialsEditFieldLabel = uilabel(app.InputDataTab);
            app.TrialsEditFieldLabel.Text = 'Trials:';
            app.trialsEdit = uieditfield(app.InputDataTab, 'numeric');
            app.trialsEdit.Editable = 'off';
            app.trialsEdit.Value = 1;
            app.trialText = uilabel(app.InputDataTab);
            app.trialText.Text = 'Trials (1)';
            app.trialSlider = uispinner(app.InputDataTab);
            app.trialSlider.Limits = [1 Inf];
            app.trialSlider.RoundFractionalValues = 'on';
            app.trialSlider.ValueChangedFcn = createCallbackFcn(app, @trialSlider_Callback, true);
            app.trialSlider.Tooltip = {'Select the trial to visualize'};
            app.trialSlider.Value = 1;
            app.ChText = uilabel(app.InputDataTab);
            app.ChText.Text = 'Ch (1)';
            app.chSlider = uispinner(app.InputDataTab);
            app.chSlider.Limits = [1 Inf];
            app.chSlider.RoundFractionalValues = 'on';
            app.chSlider.ValueChangedFcn = createCallbackFcn(app, @chSlider_Callback, true);
            app.chSlider.Tooltip = {'Select the channel to visualize'};
            app.chSlider.Value = 1;
            app.TrialtimesLabel = uilabel(app.InputDataTab);
            app.TrialtimesLabel.Text = 'Trial time (s):';
            app.SweepTimeEdit = uieditfield(app.InputDataTab, 'numeric');
            app.SweepTimeEdit.Enable = 'off';
            app.GainEditFieldLabel = uilabel(app.InputDataTab);
            app.GainEditFieldLabel.Text = 'Gain:';
            app.gain = uieditfield(app.InputDataTab, 'numeric');
            app.gain.ValueChangedFcn = createCallbackFcn(app, @gain_Callback, true);
            app.gain.Value = 1000;
            app.DataFormatLabel = uilabel(app.InputDataTab);
            app.DataFormatLabel.Text = 'Data Format:';
            app.dataFormat = uidropdown(app.InputDataTab);
            app.dataFormat.Items = {'Default', 'AxoP', 'TRC import', 'Meyer VEP', 'SEP', 'EEG format', 'IMM MEA', 'EDF format', 'Matlab'};
            app.dataFormat.ValueChangedFcn = createCallbackFcn(app, @dataFormatPanel_SelectionChangeFcn, true);
            app.dataFormat.Value = 'Default';
            app.autofocus = uicheckbox(app.InputDataTab);
            app.autofocus.Tooltip = {'Stay focused on ZebraExlore when opening a file'};
            app.autofocus.Text = 'Autofocus';
            app.autofocus.Value = true;
            app.DisplayControlTab = uitab(app.TabGroup2);
            app.DisplayControlTab.Title = 'Display Control';
            app.computeBP = uibutton(app.DisplayControlTab, 'push');
            app.computeBP.ButtonPushedFcn = createCallbackFcn(app, @computeBP_Callback, true);
            app.computeBP.Tooltip = 'Recompute and plot band pass data';
            app.computeBP.Text = 'Compute BP';
            app.plotMeanFlag = uicheckbox(app.DisplayControlTab);
            app.plotMeanFlag.ValueChangedFcn = createCallbackFcn(app, @plotMeanFlag_Callback, true);
            app.plotMeanFlag.Text = 'Plot mean';
            app.plotMultiCh = uicheckbox(app.DisplayControlTab);
            app.plotMultiCh.ValueChangedFcn = createCallbackFcn(app, @plotMultiCh_Callback, true);
            app.plotMultiCh.Text = 'Multi ch plot';
            app.plotTrCk = uicheckbox(app.DisplayControlTab);
            app.plotTrCk.ValueChangedFcn = createCallbackFcn(app, @plotTrCk_Callback, true);
            app.plotTrCk.Text = 'Plot each trial';
            app.plotTrCk.Value = true;
            app.autoOffCk = uicheckbox(app.DisplayControlTab);
            app.autoOffCk.ValueChangedFcn = createCallbackFcn(app, @autoOffCk_Callback, true);
            app.autoOffCk.Tooltip = 'Automatic offset when plotting multiple channels';
            app.autoOffCk.Text = 'Auto offset';
            app.recomputeSPG = uibutton(app.DisplayControlTab, 'push');
            app.recomputeSPG.ButtonPushedFcn = createCallbackFcn(app, @recomputeSPG_Callback, true);
            app.recomputeSPG.Tooltip = 'Compute the spectogram and the associated entities with the current parameters';
            app.recomputeSPG.Text = 'Compute SPG';
            app.autoscaleYck = uicheckbox(app.DisplayControlTab);
            app.autoscaleYck.ValueChangedFcn = createCallbackFcn(app, @autoscaleYck_ValueChanged, true);
            app.autoscaleYck.Text = 'Y autoscale';
            app.plotTemplateCk = uicheckbox(app.DisplayControlTab);
            app.plotTemplateCk.Text = 'Plot template';
            app.SPGmode = uibuttongroup(app.DisplayControlTab);
            app.SPGmode.SelectionChangedFcn = createCallbackFcn(app, @SPGmode_SelectionChangeFcn, true);
            app.SPGmode.Title = 'Spectrogram mode';
            app.SPG = uiradiobutton(app.SPGmode);
            app.SPG.Tooltip = 'Display the spectrogram';
            app.SPG.Text = 'SPG';
            app.SPG.Value = true;
            app.gammaPower = uiradiobutton(app.SPGmode);
            app.gammaPower.Tooltip = 'Display the power in the envelope frequency band';
            app.gammaPower.Text = 'Envelope';
            app.SPGlogCk = uicheckbox(app.SPGmode);
            app.SPGlogCk.ValueChangedFcn = createCallbackFcn(app, @SPGlogCk_Callback, true);
            app.SPGlogCk.Tooltip = 'Use log scale for the linear plots';
            app.SPGlogCk.Text = 'Log';
            app.EIbutton = uiradiobutton(app.SPGmode);
            app.EIbutton.Tooltip = 'Display the time course o the power spectra slope in the 20-50 Hz range';
            app.EIbutton.Text = 'E/I';
            app.BP = uiradiobutton(app.SPGmode);
            app.BP.Text = 'BP';
            app.ChronuxCk = uicheckbox(app.SPGmode);
            app.ChronuxCk.Text = 'Use Chronux';
            app.ChronuxCk.Value = true;
            app.displayLeakCk = uicheckbox(app.SPGmode);
            app.displayLeakCk.ValueChangedFcn = createCallbackFcn(app, @displayLeakCk_Callback, true);
            app.displayLeakCk.Text = 'Leak corrected';
            app.leakageCk = uicheckbox(app.SPGmode);
            app.leakageCk.Text = 'Correct spectral leak';
            app.ColormaplimitsLabel = uilabel(app.SPGmode);
            app.ColormaplimitsLabel.Text = 'Colormap limits:';
            app.upperDB = uieditfield(app.SPGmode, 'numeric');
            app.upperDB.ValueChangedFcn = createCallbackFcn(app, @upperDB_Callback, true);
            app.upperDB.Enable = 'off';
            app.upperDB.Tooltip = {'Upper limit for power in the spectrogram graph (dB)'};
            app.lowerDB = uieditfield(app.SPGmode, 'numeric');
            app.lowerDB.ValueChangedFcn = createCallbackFcn(app, @lowerDB_Callback, true);
            app.lowerDB.Enable = 'off';
            app.lowerDB.Tooltip = {'Lower limit for power in the spectrogram graph (dB)'};
            app.lowerDB.Value = -60;
            app.MaxLabel = uilabel(app.SPGmode);
            app.MaxLabel.Text = 'Max:';
            app.MinLabel = uilabel(app.SPGmode);
            app.MinLabel.Text = 'Min:';
            app.PlotScaleTab = uitable(app.DisplayControlTab);
            app.PlotScaleTab.ColumnName = {'Channel'; 'Scale factor'};
            app.PlotScaleTab.RowName = {};
            app.PlotScaleTab.ColumnEditable = [false true];
            app.PlotScaleTab.CellEditCallback = createCallbackFcn(app, @PlotScaleTab_CellEdit, true);
            app.PlotScaleTab.Tooltip = {'Allows to rescale plots in the main axes to compare signals from dfferent sources'};
            app.BPManagerPanel = uipanel(app.DisplayControlTab);
            app.BPManagerPanel.Title = 'BP Manager';
            app.BPplot_drop = uidropdown(app.BPManagerPanel);
            app.BPplot_drop.Items = {'BP 1'};
            app.BPplot_drop.ValueChangedFcn = createCallbackFcn(app, @BPplot_drop_ValueChanged, true);
            app.BPplot_drop.Value = 'BP 1';
            app.BPplot_ck = uicheckbox(app.BPManagerPanel);
            app.BPplot_ck.ValueChangedFcn = createCallbackFcn(app, @BPplot_ck_ValueChanged, true);
            app.BPplot_ck.Text = 'Selected';
            app.BPplot_ck.Value = true;
            app.BPplotColor_btn = uibutton(app.BPManagerPanel, 'push');
            app.BPplotColor_btn.ButtonPushedFcn = createCallbackFcn(app, @BPplotColor_btn_ButtonPushed, true);
            app.BPplotColor_btn.Text = 'Color';
            app.BPplotColorShow_btn = uibutton(app.BPManagerPanel, 'push');
            app.BPplotColorShow_btn.ButtonPushedFcn = createCallbackFcn(app, @BPplotColorShow_btn_ButtonPushed, true);
            app.BPplotColorShow_btn.Text = 'Show BP colors';
            app.DefaultColorsButton = uibutton(app.BPManagerPanel, 'push');
            app.DefaultColorsButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultColors_ButtonPushed, true);
            app.DefaultColorsButton.Text = 'Default Colors';
            app.BPsynch_led1 = uilamp(app.DisplayControlTab);
            app.BPsynch_led1.Tooltip = {'GREEN = BP computed'; 'RED = ComputeBP required'; ''};
            app.trialText_2 = uilabel(app.DisplayControlTab);
            app.trialText_2.Text = 'Trials (1)';
            app.trialSlider_2 = uispinner(app.DisplayControlTab);
            app.trialSlider_2.Limits = [1 Inf];
            app.trialSlider_2.RoundFractionalValues = 'on';
            app.trialSlider_2.ValueChangedFcn = createCallbackFcn(app, @trialSlider_Callback, true);
            app.trialSlider_2.Tooltip = {'Select the trial to visualize'};
            app.trialSlider_2.Value = 1;
            app.ChText_2 = uilabel(app.DisplayControlTab);
            app.ChText_2.Text = 'Ch (1)';
            app.chSlider_2 = uispinner(app.DisplayControlTab);
            app.chSlider_2.Limits = [1 Inf];
            app.chSlider_2.RoundFractionalValues = 'on';
            app.chSlider_2.ValueChangedFcn = createCallbackFcn(app, @chSlider_Callback, true);
            app.chSlider_2.Tooltip = {'Select the channel to visualize'};
            app.chSlider_2.Value = 1;
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.autoProcessing = uipanel(app.SettingsTab);
            app.autoProcessing.Title = 'Auto Processing Settings';
            app.uipanel20 = uipanel(app.autoProcessing);
            app.uipanel20.Title = 'Child Processes';
            app.powerSpectraCk = uicheckbox(app.uipanel20);
            app.powerSpectraCk.ValueChangedFcn = createCallbackFcn(app, @powerSpectraCk_Callback, true);
            app.powerSpectraCk.Tooltip = 'Compute power spectra of each loaded data';
            app.powerSpectraCk.Text = 'Power Spectra';
            app.autoSavePSck = uicheckbox(app.uipanel20);
            app.autoSavePSck.Tooltip = 'Auto save power spectra of each loaded data';
            app.autoSavePSck.Text = 'Auto Save';
            app.analyzePWdistributionCk = uicheckbox(app.uipanel20);
            app.analyzePWdistributionCk.ValueChangedFcn = createCallbackFcn(app, @analyzePWdistributionCk_Callback, true);
            app.analyzePWdistributionCk.Tooltip = 'Analyze the power distribution for each BP and the HP data';
            app.analyzePWdistributionCk.Text = 'Analyze Power Distribution';
            app.autoSavePWck = uicheckbox(app.uipanel20);
            app.autoSavePWck.Tooltip = 'Auto save distribution of spectral power';
            app.autoSavePWck.Text = 'Auto Save';
            app.TrRej_ck = uicheckbox(app.uipanel20);
            app.TrRej_ck.ValueChangedFcn = createCallbackFcn(app, @TrRej_ck_Callback, true);
            app.TrRej_ck.Text = 'Trial Rejection';
            app.multiChAnalyzer = uicheckbox(app.uipanel20);
            app.multiChAnalyzer.ValueChangedFcn = createCallbackFcn(app, @multiChAnalyzer_Callback, true);
            app.multiChAnalyzer.Tooltip = 'Xcorrelation and Xspectra of multiple channels';
            app.multiChAnalyzer.Text = 'Cross correlation and cross spectra';
            app.singleUnitExtraction_ck = uicheckbox(app.uipanel20);
            app.singleUnitExtraction_ck.ValueChangedFcn = createCallbackFcn(app, @singleUnitExtraction_ck_Callback, true);
            app.singleUnitExtraction_ck.Tooltip = 'Xcorrelation and Xspectra of multiple channels';
            app.singleUnitExtraction_ck.Text = 'Single unit extraction';
            app.hit_ck = uicheckbox(app.uipanel20);
            app.hit_ck.ValueChangedFcn = createCallbackFcn(app, @hit_ck_Callback, true);
            app.hit_ck.Text = 'Hit %';
            app.checkbox_sleepscoring = uicheckbox(app.uipanel20);
            app.checkbox_sleepscoring.ValueChangedFcn = createCallbackFcn(app, @checkbox_sleepscoring_Callback, true);
            app.checkbox_sleepscoring.Text = 'Sleep Scoring';
            app.saveParams_btn = uibutton(app.autoProcessing, 'push');
            app.saveParams_btn.ButtonPushedFcn = createCallbackFcn(app, @saveParams_btn_Callback, true);
            app.saveParams_btn.Text = 'Save Parameters';
            app.loadParams_btn = uibutton(app.autoProcessing, 'push');
            app.loadParams_btn.ButtonPushedFcn = createCallbackFcn(app, @loadParams_btn_Callback, true);
            app.loadParams_btn.Text = 'Load Parameters';
            app.uipanel21 = uipanel(app.autoProcessing);
            app.uipanel21.Title = 'Concatenation Panel';
            app.concat_ck = uicheckbox(app.uipanel21);
            app.concat_ck.ValueChangedFcn = createCallbackFcn(app, @concat_ck_Callback, true);
            app.concat_ck.Tooltip = 'Concatenate trials opening different files';
            app.concat_ck.Text = 'Concatenate Trials';
            app.analyzeWhileConc_ck = uicheckbox(app.uipanel21);
            app.analyzeWhileConc_ck.ValueChangedFcn = createCallbackFcn(app, @analyzeWhileConc_ck_Callback, true);
            app.analyzeWhileConc_ck.Text = 'Analyze while concatenating';
            app.concat_btn = uibutton(app.uipanel21, 'push');
            app.concat_btn.ButtonPushedFcn = createCallbackFcn(app, @concat_btn_Callback, true);
            app.concat_btn.Enable = 'off';
            app.concat_btn.Tooltip = 'Concatenate uploaded trials and run the analysis';
            app.concat_btn.Text = 'Concatenate now';
            app.clearConc_btn = uibutton(app.uipanel21, 'push');
            app.clearConc_btn.ButtonPushedFcn = createCallbackFcn(app, @clearConc_btn_Callback, true);
            app.clearConc_btn.Tooltip = 'Clear traces stored for concatenation';
            app.clearConc_btn.Text = 'Clear';
            app.dataProcessing = uipanel(app.SettingsTab);
            app.dataProcessing.Title = 'Data Processing';
            app.uipanel4 = uibuttongroup(app.dataProcessing);
            app.uipanel4.Title = 'Analysis Options';
            app.text8 = uilabel(app.uipanel4);
            app.text8.Text = 'Baseline for power computation (s):';
            app.text5 = uilabel(app.uipanel4);
            app.text5.Text = 'Threshold multiunits (SD multiplier):';
            app.text16 = uilabel(app.uipanel4);
            app.text16.Text = 'Trial analysis, baseline (s):';
            app.text17 = uilabel(app.uipanel4);
            app.text17.Text = 'Trial analysis, response (s):';
            app.doubleStimCk = uicheckbox(app.uipanel4);
            app.doubleStimCk.Tooltip = 'Click if the trial contain two stimuli, one at the beginning, the second at half period.';
            app.doubleStimCk.Text = 'Two stimuli per trial';
            app.templateCk = uicheckbox(app.uipanel4);
            app.templateCk.Tooltip = 'Template-based response analysis';
            app.templateCk.Text = 'Template analysis';
            app.templateCk.Value = true;
            app.text29 = uilabel(app.uipanel4);
            app.text29.Text = 'Template definition interval (s):';
            app.skip1trialCk = uicheckbox(app.uipanel4);
            app.skip1trialCk.Tooltip = 'Template-based response analysis';
            app.skip1trialCk.Text = 'Skip first trial';
            app.skip1trialCk.Value = true;
            app.templExp_bt = uibutton(app.uipanel4, 'push');
            app.templExp_bt.ButtonPushedFcn = createCallbackFcn(app, @templExp_bt_Callback, true);
            app.templExp_bt.Tooltip = 'Export the current template';
            app.templExp_bt.Text = 'Export';
            app.templImp_bt = uibutton(app.uipanel4, 'push');
            app.templImp_bt.ButtonPushedFcn = createCallbackFcn(app, @templImp_bt_Callback, true);
            app.templImp_bt.Tooltip = 'Import a template from a .mat file';
            app.templImp_bt.Text = 'Import';
            app.templRecalc_bt = uibutton(app.uipanel4, 'push');
            app.templRecalc_bt.Tooltip = 'Recalculate template for the current trace with specified parameter';
            app.templRecalc_bt.Text = 'Recalc';
            app.smooth_ck = uicheckbox(app.uipanel4);
            app.smooth_ck.Tooltip = 'filter the trace before template calculation';
            app.smooth_ck.Text = 'Smoothing';
            app.smooth_ck.Value = true;
            app.singleUnitsThr = uieditfield(app.uipanel4, 'numeric');
            app.singleUnitsThr.Limits = [0 Inf];
            app.singleUnitsThr.Value = 2;
            app.baselinePower = uieditfield(app.uipanel4, 'numeric');
            app.baselinePower.Limits = [0 Inf];
            app.baselinePower.Value = 5;
            app.baselineFrom = uieditfield(app.uipanel4, 'numeric');
            app.baselineFrom.Limits = [0 Inf];
            app.baselineFrom.Value = 0.03;
            app.baselineTo = uieditfield(app.uipanel4, 'numeric');
            app.baselineTo.Limits = [0 Inf];
            app.baselineTo.Value = 0.08;
            app.respFrom = uieditfield(app.uipanel4, 'numeric');
            app.respFrom.Limits = [0 Inf];
            app.respFrom.Value = 0.09;
            app.respTo = uieditfield(app.uipanel4, 'numeric');
            app.respTo.Limits = [0 Inf];
            app.respTo.Value = 0.35;
            app.winPWleft = uieditfield(app.uipanel4, 'numeric');
            app.winPWleft.Value = 0.02;
            app.winPWright = uieditfield(app.uipanel4, 'numeric');
            app.winPWright.Value = 0.8;
            app.templateFrom = uieditfield(app.uipanel4, 'numeric');
            app.templateFrom.Value = 0.02;
            app.templateTo = uieditfield(app.uipanel4, 'numeric');
            app.templateTo.Value = 0.35;
            app.BPtext = uilabel(app.uipanel4);
            app.BPtext.Text = 'Select (BP)';
            app.sliderBPpower = uispinner(app.uipanel4);
            app.sliderBPpower.Limits = [1 5];
            app.sliderBPpower.RoundFractionalValues = 'on';
            app.sliderBPpower.ValueChangedFcn = createCallbackFcn(app, @sliderBPpower_Callback, true);
            app.sliderBPpower.Tooltip = {'Select the Band for power computation'};
            app.sliderBPpower.Value = 3;
            app.text32 = uilabel(app.uipanel4);
            app.text32.Tooltip = 'Window for the computation of the power integral ';
            app.text32.Text = 'Window for power computation (s):';
            app.TriggersynchmindurationsamplesLabel = uilabel(app.uipanel4);
            app.TriggersynchmindurationsamplesLabel.Text = 'Trigger synch min duration  (samples)';
            app.triggerMinLength = uieditfield(app.uipanel4, 'numeric');
            app.triggerMinLength.Value = 30;
            app.TriggersynchmaxdurationsamplesLabel = uilabel(app.uipanel4);
            app.TriggersynchmaxdurationsamplesLabel.Text = 'Trigger synch max duration (samples)';
            app.triggerMaxLength = uieditfield(app.uipanel4, 'numeric');
            app.triggerMaxLength.Value = 250;
            app.DisplayPanel = uibuttongroup(app.dataProcessing);
            app.DisplayPanel.Title = 'Options of Spectral Analysis';
            app.text4 = uilabel(app.DisplayPanel);
            app.text4.Text = 'Band pass definitions               from (Hz)        to  ';
            app.notchFilter = uicheckbox(app.DisplayPanel);
            app.notchFilter.ValueChangedFcn = createCallbackFcn(app, @notchFilter_Callback, true);
            app.notchFilter.Text = '   Notch filter';
            app.predefinedBW = uibuttongroup(app.DisplayPanel);
            app.predefinedBW.SelectionChangedFcn = createCallbackFcn(app, @predefinedBW_SelectionChangeFcn, true);
            app.predefinedBW.Title = 'Predefined bands';
            app.mouseCk = uiradiobutton(app.predefinedBW);
            app.mouseCk.Text = 'Mouse';
            app.mouseCk.Value = true;
            app.zebraCk = uiradiobutton(app.predefinedBW);
            app.zebraCk.Text = 'Zebrafish';
            app.EEGck = uiradiobutton(app.predefinedBW);
            app.EEGck.Text = 'EEG VEP';
            app.NewcastleBand = uiradiobutton(app.predefinedBW);
            app.NewcastleBand.Text = 'Newcastle';
            app.envelopeChoice = uibuttongroup(app.DisplayPanel);
            app.envelopeChoice.Title = 'Envelope Computation';
            app.text12 = uilabel(app.envelopeChoice);
            app.text12.Text = 'BP from (Hz)                to';
            app.radiobutton6 = uiradiobutton(app.envelopeChoice);
            app.radiobutton6.Text = 'Thresholded variance';
            app.radiobutton6.Value = true;
            app.plotEnvelopeCk = uicheckbox(app.envelopeChoice);
            app.plotEnvelopeCk.ValueChangedFcn = createCallbackFcn(app, @plotEnvelopeCk_Callback, true);
            app.plotEnvelopeCk.Text = 'Plot HP envelope';
            app.filterBPpower = uicheckbox(app.envelopeChoice);
            app.filterBPpower.Text = 'Filter power plot';
            app.envSPGfrom = uieditfield(app.envelopeChoice, 'numeric');
            app.envSPGfrom.Limits = [0 Inf];
            app.envSPGfrom.Value = 30;
            app.envSPGto = uieditfield(app.envelopeChoice, 'numeric');
            app.envSPGto.Limits = [0 Inf];
            app.envSPGto.Value = 80;
            app.uipanel17 = uipanel(app.DisplayPanel);
            app.uipanel17.Title = 'Spectrogram computation';
            app.uipanel18 = uipanel(app.uipanel17);
            app.uipanel18.Title = 'Signal Analysis Toolbox';
            app.txt = uilabel(app.uipanel18);
            app.txt.Text = 'SPG step (Hz):';
            app.txtspg = uilabel(app.uipanel18);
            app.txtspg.Text = 'SPG window:';
            app.text21 = uilabel(app.uipanel18);
            app.text21.Text = 'Overlap:';
            app.SPGresolution = uieditfield(app.uipanel18, 'numeric');
            app.SPGresolution.Limits = [0 Inf];
            app.SPGresolution.Value = 5;
            app.SPGwindow = uieditfield(app.uipanel18, 'numeric');
            app.SPGwindow.Limits = [0 Inf];
            app.SPGwindow.Value = 256;
            app.SPGoverlap = uieditfield(app.uipanel18, 'numeric');
            app.SPGoverlap.Limits = [0 Inf];
            app.SPGoverlap.Value = 16;
            app.uipanel19 = uipanel(app.uipanel17);
            app.uipanel19.Title = 'Chronux';
            app.ChronuxAutoConfigCk = uicheckbox(app.uipanel19);
            app.ChronuxAutoConfigCk.Text = 'Automatic configuration';
            app.ChronuxAutoConfigCk.Value = true;
            app.text25 = uilabel(app.uipanel19);
            app.text25.Text = 'SPG window (s):';
            app.text26 = uilabel(app.uipanel19);
            app.text26.Text = 'Overlap:';
            app.ChronuxRW = uieditfield(app.uipanel19, 'numeric');
            app.ChronuxRW.Limits = [0 Inf];
            app.ChronuxRW.Value = 0.25;
            app.ChronuxOvl = uieditfield(app.uipanel19, 'numeric');
            app.ChronuxOvl.Limits = [0 Inf];
            app.ChronuxOvl.Value = 0.05;
            app.text27 = uilabel(app.uipanel17);
            app.text27.Text = 'Band of SPG from (Hz):';
            app.text28 = uilabel(app.uipanel17);
            app.text28.Text = 'To:';
            app.decimateFlag = uicheckbox(app.uipanel17);
            app.decimateFlag.Tooltip = 'To be used only for very long data set and slow PCs';
            app.decimateFlag.Text = 'Decimate data for spectogram';
            app.text30 = uilabel(app.uipanel17);
            app.text30.Tooltip = 'BP of the signal on which computing the SPG';
            app.text30.Text = 'LP for leakage correction (Hz):';
            app.SPGfromFreq = uieditfield(app.uipanel17, 'numeric');
            app.SPGfromFreq.Limits = [0 Inf];
            app.SPGtoFreq = uieditfield(app.uipanel17, 'numeric');
            app.SPGtoFreq.Limits = [0 Inf];
            app.SPGtoFreq.Value = 200;
            app.leakageLP = uieditfield(app.uipanel17, 'numeric');
            app.leakageLP.Limits = [0 Inf];
            app.leakageLP.ValueChangedFcn = createCallbackFcn(app, @leakageLP_Callback, true);
            app.leakageLP.Value = 10;
            app.text33 = uilabel(app.DisplayPanel);
            app.text33.Text = 'Butterworth filter order:';
            app.filtOrder = uieditfield(app.DisplayPanel, 'numeric');
            app.filtOrder.Value = 2;
            app.notchFrom = uieditfield(app.DisplayPanel, 'numeric');
            app.notchFrom.ValueChangedFcn = createCallbackFcn(app, @notchFrom_Callback, true);
            app.notchFrom.Value = 49;
            app.notchTo = uieditfield(app.DisplayPanel, 'numeric');
            app.notchTo.ValueChangedFcn = createCallbackFcn(app, @notchTo_Callback, true);
            app.notchTo.Value = 51;
            app.BpTable = uitable(app.DisplayPanel);
            app.BpTable.ColumnName = {'BP'; 'Compute'; 'From (Hz)'; 'To (Hz)'};
            app.BpTable.RowName = {};
            app.BpTable.ColumnEditable = [false true true true];
            app.BpTable.CellEditCallback = createCallbackFcn(app, @BpTable_CellEdit, true);
            app.BpTable.CellSelectionCallback = createCallbackFcn(app, @BpTableCellSelection, true);
            app.NumberofBPsEditFieldLabel = uilabel(app.DisplayPanel);
            app.NumberofBPsEditFieldLabel.Text = 'Number of BPs';
            app.NumberofBPsEditField = uieditfield(app.DisplayPanel, 'numeric');
            app.NumberofBPsEditField.Limits = [1 Inf];
            app.NumberofBPsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofBPsEditField_ValueChanged, true);
            app.NumberofBPsEditField.Value = 5;
            app.DeleteBpBtn = uibutton(app.DisplayPanel, 'push');
            app.DeleteBpBtn.ButtonPushedFcn = createCallbackFcn(app, @DeleteBpBtnButtonPushed, true);
            app.DeleteBpBtn.Enable = 'off';
            app.DeleteBpBtn.Text = 'X';
            app.MoveDownBpBtn = uibutton(app.DisplayPanel, 'push');
            app.MoveDownBpBtn.ButtonPushedFcn = createCallbackFcn(app, @MoveDownBpBtn_ButtonPushed, true);
            app.MoveDownBpBtn.Enable = 'off';
            app.MoveDownBpBtn.Text = '↓';
            app.MoveUpBpBtn = uibutton(app.DisplayPanel, 'push');
            app.MoveUpBpBtn.ButtonPushedFcn = createCallbackFcn(app, @MoveUpBpBtn_ButtonPushed, true);
            app.MoveUpBpBtn.Enable = 'off';
            app.MoveUpBpBtn.Text = '↑';
            app.computeBP_2 = uibutton(app.DisplayPanel, 'push');
            app.computeBP_2.ButtonPushedFcn = createCallbackFcn(app, @computeBP_Callback, true);
            app.computeBP_2.Tooltip = 'Recompute and plot band pass data';
            app.computeBP_2.Text = 'Compute BP';
            app.BPsynch_led2 = uilamp(app.DisplayPanel);
            app.BPsynch_led2.Tooltip = {'GREEN = BP computed'; 'RED = ComputeBP required'; ''};
            app.ZebraAutoPanel = uipanel(app.SettingsTab);
            app.ZebraAutoPanel.Title = 'ZebraAuto';
            app.AutoSetContrasts_bt = uibutton(app.ZebraAutoPanel, 'push');
            app.AutoSetContrasts_bt.ButtonPushedFcn = createCallbackFcn(app, @AutoSetContrasts_btButtonPushed, true);
            app.AutoSetContrasts_bt.Text = 'Set Contrasts';
            app.AutoGo_bt = uibutton(app.ZebraAutoPanel, 'push');
            app.AutoGo_bt.ButtonPushedFcn = createCallbackFcn(app, @AutoGo_btButtonPushed, true);
            app.AutoGo_bt.Enable = 'off';
            app.AutoGo_bt.Text = 'Go';
            app.autoMode_bt = uibutton(app.ZebraAutoPanel, 'push');
            app.autoMode_bt.ButtonPushedFcn = createCallbackFcn(app, @autoMode_btButtonPushed, true);
            app.autoMode_bt.Text = 'Auto Mode OFF';
            app.NumberofconditionsLabel = uilabel(app.ZebraAutoPanel);
            app.NumberofconditionsLabel.Text = 'Number of conditions';
            app.AutoNConds_txt = uieditfield(app.ZebraAutoPanel, 'numeric');
            app.AutoNConds_txt.Value = 6;
            app.uipanel2_2 = uipanel(app.ZebraAutoPanel);
            app.uipanel2_2.Title = 'Path selection';
            app.text2 = uilabel(app.uipanel2_2);
            app.text2.Text = 'Main folder';
            app.AutoFileNameIn_edit = uieditfield(app.uipanel2_2, 'text');
            app.AutoFileNameIn_edit.Value = 'ltp001.dat';
            app.AutoOutFileName_edit = uieditfield(app.uipanel2_2, 'text');
            app.AutoOutFileName_edit.Value = '.mat';
            app.text3 = uilabel(app.uipanel2_2);
            app.text3.Text = 'Data file name';
            app.AutoMainFolder_edit = uieditfield(app.uipanel2_2, 'text');
            app.text4_2 = uilabel(app.uipanel2_2);
            app.text4_2.Text = 'Output file name';
            app.uipanel1 = uipanel(app.ZebraAutoPanel);
            app.uipanel1.Title = 'Selection Panel';
            app.AutoSpg_ck = uicheckbox(app.uipanel1);
            app.AutoSpg_ck.Text = 'SPG';
            app.AutoSpg_ck.Value = true;
            app.AutoTable_ck = uicheckbox(app.uipanel1);
            app.AutoTable_ck.Text = 'Table';
            app.AutoTable_ck.Value = true;
            app.AutoTrace_ck = uicheckbox(app.uipanel1);
            app.AutoTrace_ck.Text = 'Trace';
            app.AutoTrace_ck.Value = true;
            app.uipanel4_2 = uipanel(app.uipanel1);
            app.uipanel4_2.Title = 'Spectra';
            app.AutoSpectra_ck = uicheckbox(app.uipanel4_2);
            app.AutoSpectra_ck.Text = 'Spectra';
            app.AutoSpectra_ck.Value = true;
            app.AutoWinSpectra_ck = uicheckbox(app.uipanel4_2);
            app.AutoWinSpectra_ck.Text = 'On window 1, (s)';
            app.AutoWinSpectra_ck.Value = true;
            app.AutoWinSpectra2_ck = uicheckbox(app.uipanel4_2);
            app.AutoWinSpectra2_ck.Text = 'On window 2, (s)';
            app.fromEditFieldLabel = uilabel(app.uipanel4_2);
            app.fromEditFieldLabel.Text = 'from';
            app.AutoSpectraFrom_edit = uieditfield(app.uipanel4_2, 'numeric');
            app.AutoSpectraFrom_edit.Value = 1;
            app.toEditFieldLabel = uilabel(app.uipanel4_2);
            app.toEditFieldLabel.Text = 'to';
            app.AutoSpectraTo_edit = uieditfield(app.uipanel4_2, 'numeric');
            app.AutoSpectraTo_edit.Value = 1;
            app.fromEditField_2Label = uilabel(app.uipanel4_2);
            app.fromEditField_2Label.Text = 'from';
            app.AutoSpectraFrom2_edit = uieditfield(app.uipanel4_2, 'numeric');
            app.AutoSpectraFrom2_edit.Value = 1;
            app.toEditField_2Label = uilabel(app.uipanel4_2);
            app.toEditField_2Label.Text = 'to';
            app.AutoSpectraTo2_edit = uieditfield(app.uipanel4_2, 'numeric');
            app.AutoSpectraTo2_edit.Value = 1;
            app.ChannelselectionButtonGroup = uibuttongroup(app.ZebraAutoPanel);
            app.ChannelselectionButtonGroup.Title = 'Channel selection';
            app.allCh_rb = uiradiobutton(app.ChannelselectionButtonGroup);
            app.allCh_rb.Text = 'All channels';
            app.allCh_rb.Value = true;
            app.selCh_rb = uiradiobutton(app.ChannelselectionButtonGroup);
            app.selCh_rb.Text = 'Only channel';
            app.selCh_edit = uieditfield(app.ChannelselectionButtonGroup, 'numeric');
            app.sound_ck = uicheckbox(app.ZebraAutoPanel);
            app.sound_ck.Text = 'Sound';
            app.sound_ck.Value = true;
            app.ZebraSaysTab = uitab(app.TabGroup);
            app.ZebraSaysTab.Title = 'ZebraSays';
            app.outable = uitable(app.ZebraSaysTab);
            app.outable.ColumnName = {'Dir'; 'File Name'; 'Time'; 'Ch'; 'Trial'; 'LP RMS'; 'BP1 RMS'; 'BP2 RMS'; 'BP3 RMS'; 'HP RMS'; 'Notes'};
            app.outable.RowName = {};
            app.outable.ColumnEditable = [false false false false false false false false false false true];
            app.outable.CellSelectionCallback = createCallbackFcn(app, @outableCellSelection, true);
            app.MetricsoftheentiretraceLabel = uilabel(app.ZebraSaysTab);
            app.MetricsoftheentiretraceLabel.Text = 'Metrics of the entire trace';
            app.MetricsoftheevokedresponsessingletrialsandaveragesLabel = uilabel(app.ZebraSaysTab);
            app.MetricsoftheevokedresponsessingletrialsandaveragesLabel.Text = 'Metrics of the evoked responses (single trials and averages)';
            app.MetricsofasynchronouseventsburstsandseizuresLabel = uilabel(app.ZebraSaysTab);
            app.MetricsofasynchronouseventsburstsandseizuresLabel.Text = 'Metrics of asynchronous events (bursts and seizures)';
            app.outEvochedResponses = uitable(app.ZebraSaysTab);
            app.outEvochedResponses.ColumnName = {'File Name'; 'Ch'; 'Trial'; 'Rep'; 'Amp'; 'T Peak'; 'Base PW BP2'; 'Resp PW BP2'; 'Resp/Base'; 'Temp PW BP2'; 'Comment'};
            app.outEvochedResponses.RowName = {};
            app.outEvochedResponses.ColumnEditable = false;
            app.outEvochedResponses.CellSelectionCallback = createCallbackFcn(app, @outEvochedResponsesCellSelection, true);
            app.outEventsSummary = uitable(app.ZebraSaysTab);
            app.outEventsSummary.ColumnName = {'Dir'; 'File Name'; 'N'; 'Duration'; 'Time'; 'Base Beta'; 'Base RMS'; 'Ratio Beta'; 'Base Gamma'; 'Gamma RMS'; 'Ratio Gamma'; 'Ratio B/G'; 'Base HP'; 'HP RMS'; 'Ratio HP'; 'Notes'};
            app.outEventsSummary.RowName = {};
            app.outEventsSummary.ColumnEditable = [false false false false false false false false false false false false false false false true];
            app.CopytableButton = uibutton(app.ZebraSaysTab, 'push');
            app.CopytableButton.ButtonPushedFcn = createCallbackFcn(app, @CopytableButtonPushed, true);
            app.CopytableButton.Text = 'Copy table';
            app.CopyOutableSelection = uibutton(app.ZebraSaysTab, 'push');
            app.CopyOutableSelection.ButtonPushedFcn = createCallbackFcn(app, @CopyOutableSelectionButtonPushed, true);
            app.CopyOutableSelection.Enable = 'off';
            app.CopyOutableSelection.Text = 'Copy selection';
            app.DeleteSelRows = uibutton(app.ZebraSaysTab, 'push');
            app.DeleteSelRows.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelRowsButtonPushed, true);
            app.DeleteSelRows.Enable = 'off';
            app.DeleteSelRows.Text = 'Delete selected rows';
            app.CopyEvochedSelection = uibutton(app.ZebraSaysTab, 'push');
            app.CopyEvochedSelection.ButtonPushedFcn = createCallbackFcn(app, @CopyEvochedSelectionButtonPushed, true);
            app.CopyEvochedSelection.Enable = 'off';
            app.CopyEvochedSelection.Text = 'Copy selection';
            app.EventDetectionTab = uitab(app.TabGroup);
            app.EventDetectionTab.Title = 'EventDetection';
            app.uipanel2_3 = uipanel(app.EventDetectionTab);
            app.uipanel2_3.Title = 'Parameters';
            app.text1 = uilabel(app.uipanel2_3);
            app.text1.Text = 'Baseline threshold:';
            app.text2_2 = uilabel(app.uipanel2_3);
            app.text2_2.Text = 'Event threshold:';
            app.text3_2 = uilabel(app.uipanel2_3);
            app.text3_2.Tooltip = 'Shortest admissible duration of an upstate';
            app.text3_2.Text = 'Minimum state duration (s):';
            app.extractEvents = uibutton(app.uipanel2_3, 'push');
            app.extractEvents.ButtonPushedFcn = createCallbackFcn(app, @extractEvents_Callback, true);
            app.extractEvents.Text = 'Compute';
            app.analyzeData = uibutton(app.uipanel2_3, 'push');
            app.analyzeData.ButtonPushedFcn = createCallbackFcn(app, @analyzeData_Callback, true);
            app.analyzeData.Text = 'Analyze';
            app.text4_3 = uilabel(app.uipanel2_3);
            app.text4_3.Tooltip = 'Upstate definitions are bridged across interuptons shorter than this interval';
            app.text4_3.Text = 'Shortest state interruption:';
            app.gauss1param = uilabel(app.uipanel2_3);
            app.gauss1param.Text = 'Gaussian 1: ';
            app.gauss2param = uilabel(app.uipanel2_3);
            app.gauss2param.Text = 'Gaussian 2:';
            app.text8_2 = uilabel(app.uipanel2_3);
            app.text8_2.Tooltip = 'Limits in log scale of the power histogram and bin number. Set to the lower limits to 0 for autoscaling.';
            app.text8_2.Text = 'From, To, Bins:';
            app.text9 = uilabel(app.uipanel2_3);
            app.text9.Text = 'Limits of the test plot:';
            app.replot = uibutton(app.uipanel2_3, 'push');
            app.replot.ButtonPushedFcn = createCallbackFcn(app, @replot_Callback, true);
            app.replot.Text = 'Replot';
            app.erodeCk = uicheckbox(app.uipanel2_3);
            app.erodeCk.Text = 'Check state edges';
            app.uipanel3_2 = uibuttongroup(app.uipanel2_3);
            app.powerLogCk = uiradiobutton(app.uipanel3_2);
            app.powerLogCk.Text = 'Log of ''envelope'' power';
            app.powerLogCk.Value = true;
            app.radiobutton2 = uiradiobutton(app.uipanel3_2);
            app.radiobutton2.Text = '''Envelope'' power';
            app.removeCk = uicheckbox(app.uipanel2_3);
            app.removeCk.Text = 'Remove state interruptions';
            app.removeCk.Value = true;
            app.autoThr = uicheckbox(app.uipanel2_3);
            app.autoThr.Text = 'Autosetting state threshold';
            app.autoThr.Value = true;
            app.conMethod = uibuttongroup(app.uipanel2_3);
            app.conMethod.Title = 'Method of concatenation';
            app.noConcCk = uiradiobutton(app.conMethod);
            app.noConcCk.Text = 'No concatenation';
            app.joinCk = uiradiobutton(app.conMethod);
            app.joinCk.Text = 'Join';
            app.joinOffsetChk = uiradiobutton(app.conMethod);
            app.joinOffsetChk.Text = 'Join with offset';
            app.joinOffsetChk.Value = true;
            app.traslateLeft = uibutton(app.uipanel2_3, 'push');
            app.traslateLeft.ButtonPushedFcn = createCallbackFcn(app, @traslateLeft_Callback, true);
            app.traslateLeft.Tooltip = 'Traslate the plot window left';
            app.traslateLeft.Text = '<';
            app.traslateRight = uibutton(app.uipanel2_3, 'push');
            app.traslateRight.ButtonPushedFcn = createCallbackFcn(app, @traslateRight_Callback, true);
            app.traslateRight.Tooltip = 'Traslate the plot window right';
            app.traslateRight.Text = '>';
            app.leftTime = uieditfield(app.uipanel2_3, 'numeric');
            app.rightTime = uieditfield(app.uipanel2_3, 'numeric');
            app.rightTime.Value = 10;
            app.fromH = uieditfield(app.uipanel2_3, 'numeric');
            app.fromH.Value = -5;
            app.toH = uieditfield(app.uipanel2_3, 'numeric');
            app.binNumber = uieditfield(app.uipanel2_3, 'numeric');
            app.binNumber.Value = 32;
            app.minDuration = uieditfield(app.uipanel2_3, 'numeric');
            app.minDuration.Value = 0.11;
            app.baselineThr = uieditfield(app.uipanel2_3, 'numeric');
            app.baselineThr.Value = -3;
            app.maxInterruption = uieditfield(app.uipanel2_3, 'numeric');
            app.maxInterruption.Value = 0.11;
            app.eventThr = uieditfield(app.uipanel2_3, 'numeric');
            app.eventThr.Value = -3;
            app.SelectedBPLabel = uilabel(app.uipanel2_3);
            app.SelectedBPLabel.Text = 'Selected BP:';
            app.detectionBP_dropDown = uidropdown(app.uipanel2_3);
            app.BPforMultipeakWavesSpinnerLabel = uilabel(app.uipanel2_3);
            app.BPforMultipeakWavesSpinnerLabel.Text = 'BP for Multipeak Waves:';
            app.BPforMultipeakWaves_spinner = uispinner(app.uipanel2_3);
            app.BPforMultipeakWaves_spinner.Limits = [1 Inf];
            app.BPforMultipeakWaves_spinner.Value = 1;
            app.uipanel1_2 = uipanel(app.EventDetectionTab);
            app.uipanel1_2.Title = 'Histogram';
            app.histoPlot = uiaxes(app.uipanel1_2);
            app.plotStates = uiaxes(app.uipanel1_2);
            app.statesTrack = uiaxes(app.uipanel1_2);
            app.saveHCD = uibutton(app.uipanel1_2, 'push');
            app.saveHCD.ButtonPushedFcn = createCallbackFcn(app, @saveHCD_Callback, true);
            app.saveHCD.Tooltip = 'Save the histograms and the cumulative distributions';
            app.saveHCD.Text = 'Save';
            app.uipanel4_3 = uipanel(app.EventDetectionTab);
            app.uipanel4_3.Title = 'Power Spectra';
            app.powerSpectra = uiaxes(app.uipanel4_3);
            app.uipanel5_2 = uipanel(app.EventDetectionTab);
            app.uipanel5_2.Title = 'Output options';
            app.text10 = uilabel(app.uipanel5_2);
            app.text10.Text = 'Set min & max power (dB):';
            app.text11 = uilabel(app.uipanel5_2);
            app.text11.Text = 'Set min & max freq (Hz):';
            app.savePWS = uibutton(app.uipanel5_2, 'push');
            app.savePWS.ButtonPushedFcn = createCallbackFcn(app, @savePWS_Callback, true);
            app.savePWS.Text = 'Save PwS';
            app.saveSegmentedStates = uibutton(app.uipanel5_2, 'push');
            app.saveSegmentedStates.ButtonPushedFcn = createCallbackFcn(app, @saveSegmentedStates_Callback, true);
            app.saveSegmentedStates.Text = 'Save US/DS';
            app.UStable = uitable(app.uipanel5_2);
            app.UStable.ColumnName = {'N'; 'T from'; 'T to'; 'Duration'; 'Median V'; 'SD'; 'Power'; 'Median V other hemisphere'; 'SD other hemisphere'; 'Power other hemisphere'};
            app.UStable.RowName = {};
            app.UStable.ColumnEditable = [false false false false false false false false false false];
            app.exportTab_btn = uibutton(app.uipanel5_2, 'push');
            app.exportTab_btn.ButtonPushedFcn = createCallbackFcn(app, @exportTab_btn_Callback, true);
            app.exportTab_btn.Text = 'Export table';
            app.minPw = uieditfield(app.uipanel5_2, 'numeric');
            app.minPw.Value = -70;
            app.maxPw = uieditfield(app.uipanel5_2, 'numeric');
            app.maxPw.Value = -30;
            app.minFreq = uieditfield(app.uipanel5_2, 'numeric');
            app.minFreq.Value = 8;
            app.maxFreq = uieditfield(app.uipanel5_2, 'numeric');
            app.maxFreq.Value = 200;
            app.SaveStartImagingButton = uibutton(app.uipanel5_2, 'push');
            app.SaveStartImagingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveStartImagingButtonPushed, true);
            app.SaveStartImagingButton.Text = 'Save Start Imaging';
            app.SaveDownstSpec = uibutton(app.uipanel5_2, 'push');
            app.SaveDownstSpec.ButtonPushedFcn = createCallbackFcn(app, @SaveDownstSpecButtonPushed, true);
            app.SaveDownstSpec.Text = 'Save Dwnst PwS';
            app.CopyTableButton = uibutton(app.uipanel5_2, 'push');
            app.CopyTableButton.ButtonPushedFcn = createCallbackFcn(app, @CopyTableButtonPushed, true);
            app.CopyTableButton.Text = {'Copy Table'; ''};
            app.uipanel7 = uibuttongroup(app.EventDetectionTab);
            app.detectionBP1 = uiradiobutton(app.uipanel7);
            app.detectionBP1.Tooltip = 'Select bandwidth for event extraction';
            app.detectionBP1.Text = 'BP1';
            app.detectionBP1.Value = true;
            app.detectionBP2 = uiradiobutton(app.uipanel7);
            app.detectionBP2.Tooltip = 'Select bandwidth for event extraction';
            app.detectionBP2.Text = 'BP2';
            app.detectionBP3 = uiradiobutton(app.uipanel7);
            app.detectionBP3.Tooltip = 'Select bandwidth for event extraction';
            app.detectionBP3.Text = 'BP3';
            app.detectionHP = uiradiobutton(app.uipanel7);
            app.detectionHP.Tooltip = 'Select bandwidth for event extraction';
            app.detectionHP.Text = 'HP';
            app.detectionSPG = uiradiobutton(app.uipanel7);
            app.detectionSPG.Tooltip = 'Select bandwidth for event extraction';
            app.detectionSPG.Text = 'SPG envelope';
            % Show the figure after all components are created
            app.ZebraMainFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ZebraExploreApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ZebraMainFig)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ZebraMainFig)
        end
    end
end